classdef winEditInstrumentList < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        EditionModeGrid         matlab.ui.container.GridLayout
        EditionModeLabel        matlab.ui.control.Label
        toolGrid                matlab.ui.container.GridLayout
        jsBackDoor              matlab.ui.control.HTML
        toolButton_edit         matlab.ui.control.Button
        toolButton_connectTest  matlab.ui.control.Button
        toolSeparator           matlab.ui.control.Image
        toolButton_export       matlab.ui.control.Button
        toolButton_open         matlab.ui.control.Button
        MainPanel               matlab.ui.container.Panel
        MainGrid                matlab.ui.container.GridLayout
        Tab3_PanelGrid          matlab.ui.container.GridLayout
        instrImage              matlab.ui.control.Image
        instrMetadataPanel      matlab.ui.container.Panel
        instrMetadataGrid       matlab.ui.container.GridLayout
        instrMetadata           matlab.ui.control.HTML
        Tab3_GridTitle          matlab.ui.container.GridLayout
        Tab3_Image              matlab.ui.control.Image
        Tab3_Title              matlab.ui.control.Label
        Tab2_Panel              matlab.ui.container.Panel
        Tab2_PanelGrid          matlab.ui.container.GridLayout
        ParametersPanel         matlab.ui.container.Panel
        ParametersGrid          matlab.ui.container.GridLayout
        LocalhostCheckBox       matlab.ui.control.CheckBox
        Timeout                 matlab.ui.control.NumericEditField
        TimeoutLabel            matlab.ui.control.Label
        BaudRate                matlab.ui.control.NumericEditField
        BaudRateLabel           matlab.ui.control.Label
        LocalhostPanel          matlab.ui.container.Panel
        LocalhostGrid2          matlab.ui.container.GridLayout
        publicIP                matlab.ui.control.EditField
        publicIPLabel           matlab.ui.control.Label
        localIP                 matlab.ui.control.EditField
        localIPLabel            matlab.ui.control.Label
        Port                    matlab.ui.control.EditField
        PortLabel               matlab.ui.control.Label
        IP                      matlab.ui.control.EditField
        IPLabel                 matlab.ui.control.Label
        Type                    matlab.ui.control.DropDown
        TypeLabel               matlab.ui.control.Label
        Description             matlab.ui.control.TextArea
        DescriptionLabel        matlab.ui.control.Label
        Name                    matlab.ui.control.DropDown
        NameLabel               matlab.ui.control.Label
        Family                  matlab.ui.control.DropDown
        FamilyLabel             matlab.ui.control.Label
        Status                  matlab.ui.control.DropDown
        StatusLabel             matlab.ui.control.Label
        Tab2_GridTitle          matlab.ui.container.GridLayout
        Tab2_Image              matlab.ui.control.Image
        Tab2_Title              matlab.ui.control.Label
        Tab1_Grid               matlab.ui.container.GridLayout
        Image_downArrow         matlab.ui.control.Image
        Image_upArrow           matlab.ui.control.Image
        Image_del               matlab.ui.control.Image
        Image_add               matlab.ui.control.Image
        Tree                    matlab.ui.container.Tree
        TreeNode_Receiver       matlab.ui.container.TreeNode
        TreeNode_GPS            matlab.ui.container.TreeNode
        Tab1_GridTitle          matlab.ui.container.GridLayout
        Tab1_Image              matlab.ui.control.Image
        Tab1_Title              matlab.ui.control.Label
        ButtonGroupPanel        matlab.ui.container.ButtonGroup
        ButtonGroup_View        matlab.ui.control.RadioButton
        ButtonGroup_Edit        matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        %-----------------------------------------------------------------%
        CallingApp
        RootFolder

        timerObj_startup

        receiverObj
        gpsObj

        instrumentList
        editedList
        editedFlag = false

        % Janela de progresso já criada no DOM. Dessa forma, controla-se 
        % apenas a sua visibilidade - e tornando desnecessário criá-la a
        % cada chamada (usando uiprogressdlg, por exemplo).
        progressDialog
    end
    

    methods (Access = private)
        %-----------------------------------------------------------------%
        % STARTUP
        %-----------------------------------------------------------------%
        function jsBackDoor_Initialization(app)
            app.jsBackDoor.HTMLSource = ccTools.fcn.jsBackDoorHTMLSource;
        end

        %-----------------------------------------------------------------%
        function jsBackDoor_Customizations(app)
            % Cria um ProgressDialog...
            app.progressDialog = ccTools.ProgressDialog(app.jsBackDoor);

            % Customizações dos componentes...
            sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-theme-light',                                                   ...
                                                                                   'classAttributes', ['--mw-backgroundColor-dataWidget-selected: rgb(180 222 255 / 45%); ' ...
                                                                                                       '--mw-backgroundColor-selected: rgb(180 222 255 / 45%); '            ...
                                                                                                       '--mw-backgroundColor-selectedFocus: rgb(180 222 255 / 45%);']));

            sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-default-header-cell', ...
                                                                                   'classAttributes',  'font-size: 10px; white-space: pre-wrap; margin-bottom: 5px;'));
        end

        %-----------------------------------------------------------------%
        function startup_timerCreation(app)            
            % A criação desse timer tem como objetivo garantir uma renderização 
            % mais rápida dos componentes principais da GUI, possibilitando a 
            % visualização da sua tela inicialpelo usuário. Trata-se de aspecto 
            % essencial quando o app é compilado como webapp.

            app.timerObj_startup = timer("ExecutionMode", "fixedSpacing", ...
                                         "StartDelay",    1.5,            ...
                                         "Period",        .1,             ...
                                         "TimerFcn",      @(~,~)app.startup_timerFcn);
            start(app.timerObj_startup)
        end

        %-----------------------------------------------------------------%
        function startup_timerFcn(app)
            if ccTools.fcn.UIFigureRenderStatus(app.UIFigure)
                stop(app.timerObj_startup)

                % Customiza as aspectos estéticos de alguns dos componentes da GUI 
                % (diretamente em JS).
                jsBackDoor_Customizations(app)

                app.receiverObj = app.CallingApp.receiverObj;
                app.gpsObj      = app.CallingApp.gpsObj;
    
                % Não é lido o arquivo "instrumentList.json", sendo aproveitada
                % a versão do winAppColetaV2.
    
                app.instrumentList = [app.receiverObj.List; app.gpsObj.List];
                app.editedList     = app.instrumentList;
                
                % Ajustes iniciais no layout do app, tornando invisíveis itens 
                % que aparecerão apenas no "MODO DE EDIÇÃO". E, por fim,
                % organização da informação do arquivo em árvore.
    
                app.EditionModeGrid.Visible  = 0;
                app.Tab1_Grid.ColumnWidth{2} = 0;
                app.toolButton_edit.Visible  = 0;
    
                TreeBuilding(app, [])
                focus(app.Tree)
                drawnow
            end
        end

        %-----------------------------------------------------------------%
        function TreeBuilding(app, idxSelectedNode)

            if ~isempty(app.TreeNode_Receiver.Children)
                delete(app.TreeNode_Receiver.Children)
            end
            
            if ~isempty(app.TreeNode_GPS.Children)
                delete(app.TreeNode_GPS.Children)
            end

            % Tree creation
            for ii = 1:height(app.editedList)
                switch app.editedList.Family{ii}
                    case 'Receiver'
                        Parent = app.TreeNode_Receiver;
                    case 'GPS'
                        Parent = app.TreeNode_GPS;                    
                    otherwise
                        continue
                end

                if ~ismember(app.editedList.Type{ii}, {'TCPIP Socket', 'TCP/UDP IP Socket', 'Serial'})
                    continue
                end

                nodeText = TreeBuilding_nodeText(app, ii);
                nodeTree = uitreenode(Parent, 'Text', nodeText, 'NodeData', ii, 'UserData', numel(Parent.Children)+1);

                if ~isempty(idxSelectedNode) && (ii == idxSelectedNode)
                    SelectedNode = nodeTree;
                end
            end            
            TreeBuilding_addStyle(app);

            % SelectedNode
            if exist('SelectedNode', 'var')
                app.Tree.SelectedNodes = SelectedNode;
            else
                app.Tree.SelectedNodes = app.Tree.Children(1).Children(1);
            end

            TreeSelectionChanged(app)
            expand(app.Tree, 'all')
        end


        %-----------------------------------------------------------------%
        function nodeText = TreeBuilding_nodeText(app, idx)

            Parameters = jsondecode(app.editedList.Parameters{idx});

            switch app.editedList.Type{idx}
                case 'Serial'
                    Socket = sprintf('%s', Parameters.Port);
                case {'TCPIP Socket', 'TCP/UDP IP Socket'}
                    Socket = sprintf('%s:%s', Parameters.IP, Parameters.Port);
            end

            nodeText = sprintf('%s - %s', app.editedList.Name{idx}, Socket);
        end


        %-----------------------------------------------------------------%
        function TreeBuilding_addStyle(app)

            if ~isempty(app.Tree.StyleConfigurations)
                removeStyle(app.Tree)
            end

            h = [allchild(app.TreeNode_Receiver); ...
                 allchild(app.TreeNode_GPS)];

            DisableNodes = [];
            for ii = 1:numel(h)
                idx = h(ii).NodeData;
                if ~app.editedList.Enable(idx)
                    DisableNodes = [DisableNodes, h(ii)];
                end
            end

            if ~isempty(DisableNodes)
                s = uistyle('FontColor', [.5 .5 .5]);
                addStyle(app.Tree, s, 'node', DisableNodes)
            end
        end


        %-----------------------------------------------------------------%
        function Layout_FamilyChanged(app, srcFcn)

            switch app.Family.Value
                case 'Receiver'
                    idx = strcmp(app.receiverObj.Config.Family, app.Family.Value);
                    app.Name.Items = app.receiverObj.Config.Name(idx);

                case 'GPS'
                    idx = strcmp(app.gpsObj.Config.Family, app.Family.Value);
                    app.Name.Items = app.gpsObj.Config.Name(idx);
            end

            if strcmp(srcFcn, 'InstrumentParameterChanged')
                Layout_NameChanged(app, srcFcn)
            end
        end


        %-----------------------------------------------------------------%
        function Layout_NameChanged(app, srcFcn)

            switch app.Family.Value
                case 'Receiver'
                    idx = strcmp(app.receiverObj.Config.Name, app.Name.Value);
                    app.Type.Items = app.receiverObj.Config.connectType(idx);

                case 'GPS'
                    idx = strcmp(app.gpsObj.Config.Name, app.Name.Value);
                    app.Type.Items = app.gpsObj.Config.connectType(idx);
            end

            if strcmp(srcFcn, 'InstrumentParameterChanged')
                Layout_TypeValueChanged(app)
            end
        end


        %-----------------------------------------------------------------%
        function Layout_TypeValueChanged(app)

            switch app.Type.Value
                case 'Serial'
                    app.ParametersGrid.ColumnWidth([1 3]) = {0, '1x'};
                    portValidation = contains(app.Port.Value, 'COM');

                otherwise
                    app.ParametersGrid.ColumnWidth([1 3]) = {110, 0};

                    if isempty(app.IP.Value)
                        app.IP.Value = '127.0.0.1';
                    end
                    portValidation = ~isnan(str2double(app.Port.Value));
            end

            switch app.Family.Value
                case 'Receiver'
                    idx = find(strcmp(app.receiverObj.Config.Name, app.Name.Value), 1);
                    app.Port.Value = num2str(app.receiverObj.Config.connectPort(idx));

                case 'GPS'
                    if ~portValidation
                        idx = find(strcmp(app.gpsObj.Config.Name, app.Name.Value), 1);
                        app.Port.Value = app.gpsObj.Config.connectPort{idx};
                    end
            end

            Layout_LocalhostCheckBox1(app)
            Layout_LocalhostCheckBox2(app)
        end


        %-----------------------------------------------------------------%
        function Layout_LocalhostCheckBox1(app)

            app.LocalhostCheckBox.Enable = 0;

            if app.ButtonGroup_Edit.Value && strcmp(app.Type.Value, "TCP/UDP IP Socket")
                app.LocalhostCheckBox.Enable = 1;
            end
        end


        %-----------------------------------------------------------------%
        function Layout_LocalhostCheckBox2(app)

            if app.LocalhostCheckBox.Value
                set(app.LocalhostGrid2.Children, 'Enable', 1)

            else
                idx = app.Tree.SelectedNodes.NodeData;
                Parameters = jsondecode(app.editedList.Parameters{idx});

                if isfield(Parameters, 'Localhost_Enable')
                    Localhost_localIP  = Parameters.Localhost_localIP;
                    Localhost_publicIP = Parameters.Localhost_publicIP;
                else
                    Localhost_localIP  = '';
                    Localhost_publicIP = ''; 
                end

                set(app.localIP,  'Enable', 0, 'Value', Localhost_localIP)
                set(app.publicIP, 'Enable', 0, 'Value', Localhost_publicIP)
            end
        end


        %-----------------------------------------------------------------%
        function Layout_InstrumentSpecifications(app)
            
            idx1 = app.Tree.SelectedNodes.NodeData;       

            switch app.editedList.Family{idx1}
                case 'Receiver'
                    idx2 = find(strcmp(app.receiverObj.Config.Name, app.editedList.Name{idx1}), 1);
                    app.instrImage.ImageSource = app.receiverObj.Config.Image{idx2};

                    dataStruct    = struct('group', 'IDENTIFICAÇÃO', ...
                                           'value', table2struct(app.receiverObj.Config(idx2,1:4)));        
                    dataStruct(2) = struct('group', 'PARÂMETROS', ...
                                           'value', table2struct(app.receiverObj.Config(idx2,[7:9, 21:end])));

                case 'GPS'
                    idx2 = find(strcmp(app.gpsObj.Config.Name, app.editedList.Name{idx1}), 1);
                    app.instrImage.ImageSource = app.gpsObj.Config.Image{idx2};

                    dataStruct    = struct('group', 'IDENTIFICAÇÃO', ...
                                           'value', table2struct(app.gpsObj.Config(idx2,1:3)));        
                    dataStruct(2) = struct('group', 'PARÂMETROS', ...
                                           'value', table2struct(app.gpsObj.Config(idx2,6:7)));
            end

            app.instrMetadata.HTMLSource = textFormatGUI.struct2PrettyPrintList(dataStruct);
        end


        %-----------------------------------------------------------------%
        function ParameterUpdate(app)

            idx = app.Tree.SelectedNodes.NodeData;

            switch app.Type.Value
                case 'Serial'
                    app.editedList.Parameters{idx} = jsonencode(struct('Port',     app.Port.Value,     ...
                                                                       'BaudRate', app.BaudRate.Value, ...
                                                                       'Timeout',  app.Timeout.Value));

                case 'TCPIP Socket'
                    app.editedList.Parameters{idx} = jsonencode(struct('IP',       app.IP.Value,       ...
                                                                       'Port',     app.Port.Value,     ...
                                                                       'Timeout',  app.Timeout.Value));
                
                case 'TCP/UDP IP Socket'
                    if app.LocalhostCheckBox.Value; Localhost_Enable = 1;
                    else;                           Localhost_Enable = 0;
                    end

                    app.editedList.Parameters{idx} = jsonencode(struct('IP',       app.IP.Value,                ...
                                                                       'Port',     app.Port.Value,              ...
                                                                       'Timeout',  app.Timeout.Value,           ...
                                                                       'Localhost_Enable',   Localhost_Enable,  ...
                                                                       'Localhost_localIP',  app.localIP.Value, ...
                                                                       'Localhost_publicIP', app.publicIP.Value));
            end
        end


        %-----------------------------------------------------------------%
        function Flag = IPv4Validation(app, event)
            
            switch event.Source
                case app.IP;       ipAddress = app.IP.Value;
                case app.localIP;  ipAddress = app.localIP.Value;
                case app.publicIP; ipAddress = app.publicIP.Value;
            end

            ipString = regexp(ipAddress, '\d*[.]{1}\d{1,3}[.]{1}\d{1,3}[.]{1}\d*', 'match');

            Flag = 0;
            if isempty(ipString)
                Flag = 1;
            else
                ipArray = cellfun(@(x) str2double(x), strsplit(char(ipString), '.'));
                if any(ipArray > 255) || any(isnan(ipArray))
                    Flag = 1;
                end                    
            end

            if Flag
                appUtil.modalWindow(app.UIFigure, 'warning', 'Endereço IP inválido');

                switch event.Source
                    case app.IP;       app.IP.Value       = event.PreviousValue;
                    case app.localIP;  app.localIP.Value  = event.PreviousValue;
                    case app.publicIP; app.publicIP.Value = event.PreviousValue;
                end
            else
                if ~strcmp(ipAddress, char(ipString))
                    switch event.Source
                        case app.IP;       app.IP.Value       = char(ipString);
                        case app.localIP;  app.localIP.Value  = char(ipString);
                        case app.publicIP; app.publicIP.Value = char(ipString);
                    end
                end
            end
        end


        %-----------------------------------------------------------------%
        function Flag = PortValidation(app, event)

            Flag = 0;

            switch app.Type.Value
                case 'Serial'
                    portValidation = regexpi(app.Port.Value, 'COM\d+', 'match');
                    if isempty(portValidation)
                        Flag = 1;
                    end

                case {'TCPIP Socket', 'TCP/UDP IP Socket'}
                    portValidation = regexp(app.Port.Value, '\d+', 'match');
                    if isempty(portValidation)
                        Flag = 1;
                    else
                        if str2double(portValidation) > 65535
                            Flag = 1;
                        end
                    end
            end

            if Flag
                appUtil.modalWindow(app.UIFigure, 'warning', 'Porta inválida');
                app.Port.Value = event.PreviousValue;
                
            else
                if ~strcmpi(app.Port.Value, char(portValidation))
                    app.Port.Value = upper(char(portValidation));
                end
            end
        end


        %-----------------------------------------------------------------%
        function [idx, msgError] = SelectionNodeValidation(app)

            try
                idx = app.Tree.SelectedNodes.NodeData;

                if isempty(idx)
                    switch app.Tree.SelectedNodes
                        case app.TreeNode_Receiver
                            app.Tree.SelectedNodes = app.TreeNode_Receiver.Children(1);
    
                        case app.TreeNode_GPS
                            if ~isempty(app.TreeNode_GPS.Children)
                                app.Tree.SelectedNodes = app.TreeNode_GPS.Children(1);
                            else
                                app.Tree.SelectedNodes = app.TreeNode_Receiver.Children(1);
                            end
                    end
                    idx = app.Tree.SelectedNodes.NodeData;
                end

                msgError = '';

            catch ME
                app.Tree.SelectedNodes = app.TreeNode_Receiver.Children(1);
                idx = app.Tree.SelectedNodes.NodeData;

                msgError = ME.message;
            end
        end


        %-----------------------------------------------------------------%
        function saveNewFile(app, Folder, ShowAlert)

            fileList = table2struct(app.instrumentList);
            for ii = 1:numel(fileList)
                fileList(ii).Parameters = jsondecode(fileList(ii).Parameters);

                % Eliminar informação relacionada à localhost, caso não tenha 
                % sido preenchidos ao menos um dos campos de IP - "ip_local" 
                % ou "ip_público".

                if isfield(fileList(ii).Parameters, 'Localhost_Enable')
                    if fileList(ii).Parameters.Localhost_Enable  && isempty(fileList(ii).Parameters.Localhost_localIP) && isempty(fileList(ii).Parameters.Localhost_publicIP)
                        fileList(ii).Parameters = rmfield(fileList(ii).Parameters, {'Localhost_Enable', 'Localhost_localIP', 'Localhost_publicIP'});
                    end
                end
            end

            try
                fileID = fopen(fullfile(Folder, 'instrumentList.json'), 'wt');
                fwrite(fileID, jsonencode(fileList, 'PrettyPrint', true));
                fclose(fileID);

                if ShowAlert
                    appUtil.modalWindow(app.UIFigure, 'warning', sprintf('Arquivo <b>instrumentList.json</b> salvo na pasta "%s"', replace(Folder, '\', '\\')));
                end
            catch ME
                appUtil.modalWindow(app.UIFigure, 'error', getReport(ME));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            % A razão de ser deste app é possibilitar visualização/edição 
            % do arquivo "instrumentList.json".
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;

            layout.winPosition(app)
            jsBackDoor_Initialization(app)
            startup_timerCreation(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            if app.editedFlag
                saveNewFile(app, fullfile(app.RootFolder, 'Settings'), false)

                % Atualiza as propriedades do app, fechando o seu módulo auxiliar
                % "auxApp.winAddTask", caso aberto.
                [tempList1, msgError1] = app.receiverObj.FileRead(app.RootFolder);
                if isempty(msgError1)
                    app.receiverObj.List = tempList1;
                end

                [tempList2, msgError2] = app.gpsObj.FileRead(app.RootFolder);
                if isempty(msgError2)
                    app.gpsObj.List = tempList2;
                end

                if ~isempty(app.CallingApp.auxiliarWin2)
                    delete(app.CallingApp.auxiliarWin2)
                    app.CallingApp.auxiliarWin2 = [];
                end
            end

            app.CallingApp.auxiliarWin4 = [];
            delete(app)
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            
            % Caso o nó selecionado da árvore seja o "RECEPTOR" ou "GPS",
            % busca-se o seu primeiro filho, caso existente.

            idx = SelectionNodeValidation(app);

            % Ajuste dos itens que são listas suspensas (uidropdown)
            % porque no "MODO DE EDIÇÃO" todos os possíveis valores
            % estão disponíveis para escolha, enquanto que no "MODO DE
            % VISUALIZAÇÃO" ficará disponível apenas o valor indicado
            % no "taskList.json".

            %---------------------------------------------------------%
            % ## MODO DE VISUALIZAÇÃO ##
            %---------------------------------------------------------%
            if app.ButtonGroup_View.Value
                if app.editedList.Enable(idx); app.Status.Items = {'ON'};
                else;                          app.Status.Items = {'OFF'};
                end

                app.Family.Items = app.editedList.Family(idx);
                app.Name.Items   = app.editedList.Name(idx);
                app.Type.Items   = app.editedList.Type(idx);

            %---------------------------------------------------------%
            % ## MODO DE EDIÇÃO ##
            %---------------------------------------------------------%
            else
                if app.editedList.Enable(idx); app.Status.Value = 'ON';
                else;                          app.Status.Value = 'OFF';
                end

                app.Family.Value = app.editedList.Family{idx};
                Layout_FamilyChanged(app, '')
                
                app.Name.Value = app.editedList.Name{idx};
                Layout_NameChanged(app, 'InstrumentParameterChanged')

                app.Type.Value = app.editedList.Type{idx};
            end            

            % Ajustes nos outros campos (que não são listas suspensas), 
            % além de especificidades do campo "Fator integração" e dos
            % parâmetros relacionados à busca de emissões.

            app.Description.Value = app.editedList.Description{idx};
            app.LocalhostCheckBox.Value = 0;

            Parameters = jsondecode(app.editedList.Parameters{idx});
            switch app.Type.Value
                case 'Serial'
                    app.IP.Value       = '';
                    app.Port.Value     = Parameters.Port;
                    app.BaudRate.Value = Parameters.BaudRate;

                case {'TCPIP Socket', 'TCP/UDP IP Socket'}
                    app.IP.Value       = Parameters.IP;
                    app.Port.Value     = Parameters.Port;

                    if isfield(Parameters, 'Localhost_Enable')
                        app.LocalhostCheckBox.Value = Parameters.Localhost_Enable;
                        app.localIP.Value  = Parameters.Localhost_localIP;
                        app.publicIP.Value = Parameters.Localhost_publicIP;
                    else
                        app.LocalhostCheckBox.Value = 0;
                        app.localIP.Value           = '';
                        app.publicIP.Value          = '';
                    end
            end

            if isfield(Parameters, 'Timeout');  app.Timeout.Value = Parameters.Timeout;
            else;                               app.Timeout.Value = class.Constants.Timeout;
            end
            
            % Os últimos ajustes consistem na validação do campo "Port",
            % assim como atualização estética do instrumento.

            Layout_TypeValueChanged(app)
            Layout_InstrumentSpecifications(app)
            
        end

        % Selection changed function: ButtonGroupPanel
        function ValueChanged_OperationMode(app, event)
            
            %-------------------------------------------------------------%
            % ## MODO DE VISUALIZAÇÃO ##
            %-------------------------------------------------------------%
            if app.ButtonGroup_View.Value
                % Aspectos relacionados à indicação visual de que se trata 
                % do modo de visualização:

                app.EditionModeGrid.Visible  = 0;
                set(findobj(app.Tab1_Grid, 'Type', 'uiimage'), Enable='off')
                app.Tab1_Grid.ColumnWidth{2} = 0;
                app.toolButton_edit.Visible  = 0;
                app.toolButton_open.Enable   = 'on';
                app.toolButton_export.Enable = 'on';                

                % Desabilita edição do conteúdo dos campos...

                app.Description.Editable = 'off';
                set(findobj(app.ParametersGrid, 'Type', 'uinumericeditfield', '-or', 'Type', 'uieditfield'), Editable='off')
                set(findobj(app.LocalhostGrid2, 'Type', 'uinumericeditfield', '-or', 'Type', 'uieditfield'), Editable='off')
                app.LocalhostCheckBox.Enable = 0;

                set(app.Status, 'Items', {app.Status.Value})
                set(app.Family, 'Items', {app.Family.Value})
                set(app.Name,   'Items', {app.Name.Value})
                set(app.Type,   'Items', {app.Type.Value})

                % Essa última validação é essencial para desfazer alterações 
                % que não foram salvas. Ou seja, o usuário fez alterações
                % em app.taskList (que estavam armazenadas na sua cópia -
                % app.editedList) e não clicou no botão "Confirma edição".

                if ~isequal(app.instrumentList, app.editedList)
                    app.editedList = app.instrumentList;
                    TreeBuilding(app, [])
                end

            %-------------------------------------------------------------%
            % ## MODO DE EDIÇÃO ##
            %-------------------------------------------------------------%
            else
                % Aspectos relacionados à indicação visual de que se trata 
                % do modo de edição:

                app.EditionModeGrid.Visible = 1;
                set(app.Tab1_Grid.Children, Enable='on')
                app.Tab1_Grid.ColumnWidth{2} = 16;
                app.toolButton_edit.Visible  = 1;
                app.toolButton_open.Enable   = 'off';
                app.toolButton_export.Enable = 'off';

                % Habilita edição do conteúdo dos campos...

                app.Description.Editable = 'on';
                set(findobj(app.ParametersGrid, 'Type', 'uinumericeditfield', '-or', 'Type', 'uieditfield'), Editable='on')
                set(findobj(app.LocalhostGrid2, 'Type', 'uinumericeditfield', '-or', 'Type', 'uieditfield'), Editable='on')
                app.LocalhostCheckBox.Enable = 1;

                set(app.Status, 'Items', {'ON', 'OFF'})
                set(app.Family, 'Items', {'Receiver', 'GPS'})
                Layout_FamilyChanged(app, 'InstrumentParameterChanged')
            end

        end

        % Value changed function: BaudRate, Description, Family, IP, 
        % ...and 8 other components
        function ValueChanged_Parameter(app, event)
            
            [idx, msgError] = SelectionNodeValidation(app);

            if ~isempty(msgError)
                TreeSelectionChanged(app)
                return
            end


            switch event.Source
                %---------------------------------------------------------%
                case app.Status
                    if app.Status.Value == "ON"
                        app.editedList.Enable(idx) = 1;
                    else
                        app.editedList.Enable(idx) = 0;
                    end

                %---------------------------------------------------------%
                case app.Family
                    Layout_FamilyChanged(app, 'InstrumentParameterChanged')
                    
                    app.editedList.Family{idx} = app.Family.Value;
                    app.editedList.Name{idx}   = app.Name.Value;
                    app.editedList.Type{idx}   = app.Type.Value;
                    
                    Layout_InstrumentSpecifications(app)
                    ParameterUpdate(app)
                    
                %---------------------------------------------------------%
                case app.Name
                    Layout_NameChanged(app, 'InstrumentParameterChanged')

                    app.editedList.Name{idx} = app.Name.Value;
                    app.editedList.Type{idx} = app.Type.Value;

                    Layout_InstrumentSpecifications(app)
                    ParameterUpdate(app)

                %---------------------------------------------------------%
                case app.Type
                    Layout_TypeChanged(app, 'InstrumentParameterChanged')

                    app.editedList.Type{idx} = app.Type.Value;
                    ParameterUpdate(app)

                %---------------------------------------------------------%
                case {app.Port, app.IP, app.localIP, app.publicIP}
                    Flag = 0;

                    if ~isempty(event.Source.Value)
                        if event.Source == app.Port
                            Flag = PortValidation(app, event);
                        else
                            Flag = IPv4Validation(app, event);
                        end
                    end

                    if ~Flag
                        ParameterUpdate(app)
                    end

                %---------------------------------------------------------%
                case app.BaudRate
                    ParameterUpdate(app)

                %---------------------------------------------------------%
                case app.Timeout
                    ParameterUpdate(app)

                %---------------------------------------------------------%
                case app.LocalhostCheckBox
                    Layout_LocalhostCheckBox2(app)
                    ParameterUpdate(app)

                %---------------------------------------------------------%
                case app.Description
                    app.editedList.Description{idx} = strjoin(app.Description.Value);
            end

            % Recriando a árvore...
            TreeBuilding(app, idx)
            
        end

        % Image clicked function: Image_add
        function ImageClicked_add(app, event)
            
            [idx, msgError] = SelectionNodeValidation(app);

            if isempty(msgError)
                idx_old = idx;
                idx_new = height(app.editedList) + 1;
    
                app.editedList(idx_new,:) = app.editedList(idx_old,:);
                TreeBuilding(app, idx_new)
            end

        end

        % Image clicked function: Image_del
        function ImageClicked_del(app, event)
            
            [idx, msgError] = SelectionNodeValidation(app);

            if isempty(msgError)
                nodeParent = app.Tree.SelectedNodes.Parent;

                if nodeParent == app.TreeNode_Receiver    
                    if numel(nodeParent.Children) == 1
                        return;
                    end
                end

                app.editedList(idx,:) = [];
                TreeBuilding(app, [])
            end

        end

        % Image clicked function: Image_downArrow, Image_upArrow
        function ImageClicked_UpDownArrows(app, event)
            
            [idx, msgError] = SelectionNodeValidation(app);

            if isempty(msgError)
                idx1_old = idx;
                idx2_old = app.Tree.SelectedNodes.UserData;
                Parent   = app.Tree.SelectedNodes.Parent;
    
                Flag     = 0;
                switch event.Source
                    case app.Image_upArrow
                        if idx2_old > 1
                            idx1_new = Parent.Children(idx2_old-1).NodeData;
                            Flag     = 1;
                        end
    
                    case app.Image_downArrow
                        if idx2_old < numel(Parent.Children)
                            idx1_new = Parent.Children(idx2_old+1).NodeData;
                            Flag     = 1;
                        end
                end
    
                if Flag
                    app.editedList([idx1_old, idx1_new],:) = flip(app.editedList([idx1_old, idx1_new],:));
                    TreeBuilding(app, idx1_new)
                end
            end

        end

        % Button pushed function: toolButton_open
        function toolButtonPushed_open(app, event)
            
            [File, Folder] = uigetfile({'*.json', '*.json'}, 'Selecione um arquivo', 'MultiSelect', 'off');
            figure(app.UIFigure)

            if File
                try
                    tempList = fcn.instrumentListRead(fullfile(Folder, File));

                    if ~isempty(tempList)
                        app.instrumentList = [app.instrumentList; tempList];
                        app.editedList     = app.instrumentList;
                        app.editedFlag     = true;
    
                        TreeBuilding(app, [])
                    end

                catch ME
                    appUtil.modalWindow(app.UIFigure, 'error', getReport(ME));
                end
            end

        end

        % Button pushed function: toolButton_export
        function toolButtonPushed_export(app, event)
            
            Folder = uigetdir(app.CallingApp.menu_userPath.Value, 'Escolha o diretório em que será salva a lista de instrumentos');
            figure(app.UIFigure)

            if Folder
                saveNewFile(app, Folder, true)
            end

        end

        % Button pushed function: toolButton_connectTest
        function toolButtonPushed_connectTest(app, event)
            
            app.progressDialog.Visible = 'visible';

            % O "idx1" se refere ao índice da tabela completa extraída de
            % "instrumentList.json" (possivelmente já editada), incluindo 
            % receptores e GPSs.

            [idx, msgError] = SelectionNodeValidation(app);

            if isempty(msgError)
                idx1 = idx;
    
                switch app.Family.Value
                    case 'Receiver'
                        idx2 = find(strcmp(app.receiverObj.Config.Name, app.Name.Value), 1);
                        instrSelected = struct('Type',       app.Type.Value,                   ...
                                               'Tag',        app.receiverObj.Config.Tag{idx2}, ...
                                               'Parameters', jsondecode(app.editedList.Parameters{idx1}));
    
                        fcn.ConnectivityTest_Receiver(app, instrSelected, 1);
    
                    case 'GPS'
                        instrSelected = struct('Type',       app.Type.Value, ...
                                               'Parameters', jsondecode(app.editedList.Parameters{idx1}));
    
                        fcn.ConnectivityTest_GPS(app, instrSelected, 1);
                end
            end

            app.progressDialog.Visible = 'hidden';

        end

        % Button pushed function: toolButton_edit
        function toolButtonPushed_edit(app, event)
            
            % Finalizada a edição, avalia-se se algum parâmetro foi, de fato, 
            % alterado, colocando essa informação na variável app.editedFlag.
            % Por fim, volta-se ao "MODO DE VISUALIZAÇÃO", clicando-se
            % programaticamente no botão de controle e, posteriormente,
            % acionando o seu callback.

            if ~isequal(app.instrumentList, app.editedList)
                app.instrumentList = app.editedList;
                app.editedFlag     = true;
            end
            
            app.ButtonGroup_View.Value = 1;
            ValueChanged_OperationMode(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ButtonGroupPanel = uibuttongroup(app.GridLayout);
            app.ButtonGroupPanel.SelectionChangedFcn = createCallbackFcn(app, @ValueChanged_OperationMode, true);
            app.ButtonGroup_Edit = uiradiobutton(app.ButtonGroupPanel);
            app.ButtonGroup_Edit.Text = 'Editar lista';
            app.ButtonGroup_View = uiradiobutton(app.ButtonGroupPanel);
            app.ButtonGroup_View.Text = 'Visualizar lista de instrumentos';
            app.ButtonGroup_View.Value = true;
            app.MainPanel = uipanel(app.GridLayout);
            app.MainGrid = uigridlayout(app.MainPanel);
            app.Tab1_GridTitle = uigridlayout(app.MainGrid);
            app.Tab1_GridTitle.Tag = 'COLORLOCKED';
            app.Tab1_Title = uilabel(app.Tab1_GridTitle);
            app.Tab1_Title.Text = 'LISTA DE INSTRUMENTOS';
            app.Tab1_Image = uiimage(app.Tab1_GridTitle);
            app.Tab1_Image.ImageSource = 'LT_connect.png';
            app.Tab1_Grid = uigridlayout(app.MainGrid);
            app.Tree = uitree(app.Tab1_Grid);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.TreeNode_Receiver = uitreenode(app.Tree);
            app.TreeNode_Receiver.Text = 'RECEPTOR';
            app.TreeNode_GPS = uitreenode(app.Tree);
            app.TreeNode_GPS.Text = 'GPS';
            app.Image_add = uiimage(app.Tab1_Grid);
            app.Image_add.ImageClickedFcn = createCallbackFcn(app, @ImageClicked_add, true);
            app.Image_add.Enable = 'off';
            app.Image_add.Tooltip = {'Novo registro'};
            app.Image_add.ImageSource = 'LT_newTask.png';
            app.Image_del = uiimage(app.Tab1_Grid);
            app.Image_del.ImageClickedFcn = createCallbackFcn(app, @ImageClicked_del, true);
            app.Image_del.Enable = 'off';
            app.Image_del.Tooltip = {'Exclui registro'};
            app.Image_del.ImageSource = 'LT_redX.png';
            app.Image_upArrow = uiimage(app.Tab1_Grid);
            app.Image_upArrow.ImageClickedFcn = createCallbackFcn(app, @ImageClicked_UpDownArrows, true);
            app.Image_upArrow.Enable = 'off';
            app.Image_upArrow.ImageSource = 'LT_upArrow_Zoom.png';
            app.Image_downArrow = uiimage(app.Tab1_Grid);
            app.Image_downArrow.ImageClickedFcn = createCallbackFcn(app, @ImageClicked_UpDownArrows, true);
            app.Image_downArrow.Enable = 'off';
            app.Image_downArrow.ImageSource = 'LT_downArrow_Zoom.png';
            app.Tab2_GridTitle = uigridlayout(app.MainGrid);
            app.Tab2_GridTitle.Tag = 'COLORLOCKED';
            app.Tab2_Title = uilabel(app.Tab2_GridTitle);
            app.Tab2_Title.Text = 'PARÂMETROS';
            app.Tab2_Image = uiimage(app.Tab2_GridTitle);
            app.Tab2_Image.ImageSource = 'LT_Dots2.png';
            app.Tab2_Panel = uipanel(app.MainGrid);
            app.Tab2_PanelGrid = uigridlayout(app.Tab2_Panel);
            app.StatusLabel = uilabel(app.Tab2_PanelGrid);
            app.StatusLabel.Text = 'Estado:';
            app.Status = uidropdown(app.Tab2_PanelGrid);
            app.Status.Items = {'ON', 'OFF'};
            app.Status.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.Status.Value = 'ON';
            app.FamilyLabel = uilabel(app.Tab2_PanelGrid);
            app.FamilyLabel.Text = 'Família:';
            app.Family = uidropdown(app.Tab2_PanelGrid);
            app.Family.Items = {};
            app.Family.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.Family.Value = {};
            app.NameLabel = uilabel(app.Tab2_PanelGrid);
            app.NameLabel.Text = 'Fabricante e modelo:';
            app.Name = uidropdown(app.Tab2_PanelGrid);
            app.Name.Items = {};
            app.Name.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.Name.Value = {};
            app.DescriptionLabel = uilabel(app.Tab2_PanelGrid);
            app.DescriptionLabel.Text = 'Descrição (opcional):';
            app.Description = uitextarea(app.Tab2_PanelGrid);
            app.Description.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.Description.Editable = 'off';
            app.TypeLabel = uilabel(app.Tab2_PanelGrid);
            app.TypeLabel.Text = 'Tipo de conexão:';
            app.Type = uidropdown(app.Tab2_PanelGrid);
            app.Type.Items = {};
            app.Type.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.Type.Value = {};
            app.ParametersPanel = uipanel(app.Tab2_PanelGrid);
            app.ParametersGrid = uigridlayout(app.ParametersPanel);
            app.IPLabel = uilabel(app.ParametersGrid);
            app.IPLabel.Text = 'Endereço IP:';
            app.IP = uieditfield(app.ParametersGrid, 'text');
            app.IP.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.IP.Editable = 'off';
            app.PortLabel = uilabel(app.ParametersGrid);
            app.PortLabel.Text = 'Porta:';
            app.Port = uieditfield(app.ParametersGrid, 'text');
            app.Port.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.Port.Editable = 'off';
            app.LocalhostPanel = uipanel(app.ParametersGrid);
            app.LocalhostGrid2 = uigridlayout(app.LocalhostPanel);
            app.localIPLabel = uilabel(app.LocalhostGrid2);
            app.localIPLabel.Text = 'IP local:';
            app.localIP = uieditfield(app.LocalhostGrid2, 'text');
            app.localIP.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.localIP.Editable = 'off';
            app.localIP.Enable = 'off';
            app.publicIPLabel = uilabel(app.LocalhostGrid2);
            app.publicIPLabel.Text = 'IP público:';
            app.publicIP = uieditfield(app.LocalhostGrid2, 'text');
            app.publicIP.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.publicIP.Editable = 'off';
            app.publicIP.Enable = 'off';
            app.BaudRateLabel = uilabel(app.ParametersGrid);
            app.BaudRateLabel.Text = 'BaudRate:';
            app.BaudRate = uieditfield(app.ParametersGrid, 'numeric');
            app.BaudRate.Limits = [0 Inf];
            app.BaudRate.RoundFractionalValues = 'on';
            app.BaudRate.ValueDisplayFormat = '%.0f';
            app.BaudRate.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.BaudRate.Editable = 'off';
            app.BaudRate.Value = 9600;
            app.TimeoutLabel = uilabel(app.ParametersGrid);
            app.TimeoutLabel.Text = 'Timeout (s):';
            app.Timeout = uieditfield(app.ParametersGrid, 'numeric');
            app.Timeout.Limits = [1 20];
            app.Timeout.RoundFractionalValues = 'on';
            app.Timeout.ValueDisplayFormat = '%.0f';
            app.Timeout.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.Timeout.Editable = 'off';
            app.Timeout.Value = 5;
            app.LocalhostCheckBox = uicheckbox(app.ParametersGrid);
            app.LocalhostCheckBox.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.LocalhostCheckBox.Enable = 'off';
            app.LocalhostCheckBox.Text = 'Localhost';
            app.Tab3_GridTitle = uigridlayout(app.MainGrid);
            app.Tab3_GridTitle.Tag = 'COLORLOCKED';
            app.Tab3_Title = uilabel(app.Tab3_GridTitle);
            app.Tab3_Title.Text = 'ESPECIFICAÇÕES';
            app.Tab3_Image = uiimage(app.Tab3_GridTitle);
            app.Tab3_Image.ImageSource = 'LT_antenna.png';
            app.Tab3_PanelGrid = uigridlayout(app.MainGrid);
            app.instrMetadataPanel = uipanel(app.Tab3_PanelGrid);
            app.instrMetadataGrid = uigridlayout(app.instrMetadataPanel);
            app.instrMetadata = uihtml(app.instrMetadataGrid);
            app.instrImage = uiimage(app.Tab3_PanelGrid);
            app.instrImage.ImageSource = 'Instr_R&S_FSL.png';
            app.toolGrid = uigridlayout(app.GridLayout);
            app.toolButton_open = uibutton(app.toolGrid, 'push');
            app.toolButton_open.ButtonPushedFcn = createCallbackFcn(app, @toolButtonPushed_open, true);
            app.toolButton_open.Icon = 'LT_OpenFile.png';
            app.toolButton_open.Tooltip = {'Abrir'};
            app.toolButton_open.Text = '';
            app.toolButton_export = uibutton(app.toolGrid, 'push');
            app.toolButton_export.ButtonPushedFcn = createCallbackFcn(app, @toolButtonPushed_export, true);
            app.toolButton_export.Icon = 'LT_save.png';
            app.toolButton_export.Tooltip = {'Exportar'};
            app.toolButton_export.Text = '';
            app.toolSeparator = uiimage(app.toolGrid);
            app.toolSeparator.Enable = 'off';
            app.toolSeparator.ImageSource = 'LT_LineV.png';
            app.toolButton_connectTest = uibutton(app.toolGrid, 'push');
            app.toolButton_connectTest.ButtonPushedFcn = createCallbackFcn(app, @toolButtonPushed_connectTest, true);
            app.toolButton_connectTest.Icon = 'LT_connectivity.png';
            app.toolButton_connectTest.Tooltip = {'Teste de conectividade'};
            app.toolButton_connectTest.Text = '';
            app.toolButton_edit = uibutton(app.toolGrid, 'push');
            app.toolButton_edit.ButtonPushedFcn = createCallbackFcn(app, @toolButtonPushed_edit, true);
            app.toolButton_edit.Icon = 'LT_edit.png';
            app.toolButton_edit.Text = 'Confirma edição';
            app.jsBackDoor = uihtml(app.toolGrid);
            app.EditionModeGrid = uigridlayout(app.GridLayout);
            app.EditionModeLabel = uilabel(app.EditionModeGrid);
            app.EditionModeLabel.Text = 'MODO DE EDIÇÃO';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winEditInstrumentList(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end