classdef winServer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        MainPanelGrid         matlab.ui.container.GridLayout
        general_refresh       matlab.ui.control.Image
        general_TablePanel    matlab.ui.container.Panel
        general_TableGrid     matlab.ui.container.GridLayout
        general_Table         ccTools.Table
        general_versionPanel  matlab.ui.container.Panel
        general_versionGrid   matlab.ui.container.GridLayout
        general_version       matlab.ui.control.HTML
        Tab1_GridTitle        matlab.ui.container.GridLayout
        Tab1_Title            matlab.ui.control.Label
        Tab1_Image            matlab.ui.control.Image
        toolGrid              matlab.ui.container.GridLayout
        toolButton_edit       matlab.ui.control.Button
        toolLampLabel         matlab.ui.control.Label
        toolLamp              matlab.ui.control.Lamp
    end

    
    properties
        %-----------------------------------------------------------------%
        Container
        isDocked = false
        
        CallingApp
        rootFolder

        tcpServer
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function startupGeneral(app)
            app.tcpServer = app.CallingApp.tcpServer;
            Layout(app)
        end


        %-----------------------------------------------------------------%
        function Layout(app)
            if isempty(app.tcpServer)
                app.general_version.HTMLSource = ' ';
                app.general_refresh.Visible = 0;

                app.toolLamp.Color = [.64 .08 .18];
                app.toolLampLabel.Text = 'Servidor não está em execução.';

                app.general_Table.Data = table;
                set(app.toolButton_edit, Text='Iniciar servidor', Icon='play_32.png')

            elseif isempty(app.tcpServer.Server)
                app.general_version.HTMLSource = ' ';
                app.general_refresh.Visible = 0;

                app.toolLamp.Color = [.5 .5 .5];
                app.toolLampLabel.Text = sprintf('Servidor ainda não está em execução, apesar do objeto "class.tcpServerLib" já ter sido criado. Será realizada uma nova tentativa para executá-lo a cada %d segundos.', class.Constants.tcpServerPeriod);

                app.general_Table.Data = app.tcpServer.LOG;
                set(app.toolButton_edit, Text='Excluir objeto', Icon='Delete_32Red.png')

            else
                verMetaData(1).group = 'CARACTERÍSTICAS';
                verMetaData(1).value = struct('ServerAddress',     app.tcpServer.Server.ServerAddress,     ...
                                              'ServerPort',        app.tcpServer.Server.ServerPort,        ...
                                              'Connected',         app.tcpServer.Server.Connected,         ...
                                              'ClientAddress',     app.tcpServer.Server.ClientAddress,     ...
                                              'ClientPort',        app.tcpServer.Server.ClientPort,        ...
                                              'NumBytesAvailable', app.tcpServer.Server.NumBytesAvailable, ...
                                              'Timeout',           app.tcpServer.Server.Timeout,           ...
                                              'ByteOrder',         app.tcpServer.Server.ByteOrder,         ...
                                              'Terminator',        app.tcpServer.Server.Terminator,        ...
                                              'NumBytesWritten',   app.tcpServer.Server.NumBytesWritten);

                app.general_version.HTMLSource = textFormatGUI.struct2PrettyPrintList(verMetaData);
                app.general_refresh.Visible = 1;

                app.toolLamp.Color = [.47 .67 .19];
                app.toolLampLabel.Text = sprintf('Servidor em execução desde %s.', char(app.tcpServer.Time));

                app.general_Table.Data = app.tcpServer.LOG;
                set(app.toolButton_edit, Text='Parar servidor', Icon='stop_32.png')
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp;
            app.rootFolder = app.CallingApp.rootFolder;

            if app.isDocked
                app.GridLayout.Padding(4) = 21;
            else
                appUtil.winPosition(app.UIFigure)
            end

            startupGeneral(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            appBackDoor(app.CallingApp, app, 'closeFcn', 'SERVER')
            delete(app)
            
        end

        % Button pushed function: toolButton_edit
        function toolButtonPushed_edit(app, event)
            
            if isempty(app.CallingApp.tcpServer)
                app.CallingApp.tcpServer = class.tcpServerLib(app.CallingApp);
            
            else
                stop(app.CallingApp.tcpServer.Timer)
                delete(app.CallingApp.tcpServer.Timer)
                delete(app.CallingApp.tcpServer.Server)
                
                app.CallingApp.tcpServer = [];
            end

            startupGeneral(app)

        end

        % Image clicked function: general_refresh
        function general_refreshImageClicked(app, event)
            
            startupGeneral(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta';
            app.UIFigure.Icon = 'icon_32.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.toolGrid = uigridlayout(app.GridLayout);
            app.toolLamp = uilamp(app.toolGrid);
            app.toolLampLabel = uilabel(app.toolGrid);
            app.toolLampLabel.Text = 'Desconectado';
            app.toolButton_edit = uibutton(app.toolGrid, 'push');
            app.toolButton_edit.ButtonPushedFcn = createCallbackFcn(app, @toolButtonPushed_edit, true);
            app.toolButton_edit.Icon = 'play_32.png';
            app.toolButton_edit.Text = 'Iniciar servidor';
            app.MainPanelGrid = uigridlayout(app.GridLayout);
            app.Tab1_GridTitle = uigridlayout(app.MainPanelGrid);
            app.Tab1_GridTitle.Tag = 'COLORLOCKED';
            app.Tab1_Image = uiimage(app.Tab1_GridTitle);
            app.Tab1_Image.ImageSource = 'Server_36.png';
            app.Tab1_Title = uilabel(app.Tab1_GridTitle);
            app.Tab1_Title.Text = 'SERVIDOR';
            app.general_versionPanel = uipanel(app.MainPanelGrid);
            app.general_versionGrid = uigridlayout(app.general_versionPanel);
            app.general_version = uihtml(app.general_versionGrid);
            app.general_TablePanel = uipanel(app.MainPanelGrid);
            app.general_TableGrid = uigridlayout(app.general_TablePanel);
            app.general_Table = ccTools.Table(app.general_TableGrid);
            app.general_Table.ColumnName = {'Instante', 'IP', 'Porta', 'Mensagem', 'Cliente', 'Requisição', 'Bytes enviados', 'Estado'};
            app.general_Table.hFontSize = 11;
            app.general_Table.hCapitalLetter = true;
            app.general_refresh = uiimage(app.MainPanelGrid);
            app.general_refresh.ImageClickedFcn = createCallbackFcn(app, @general_refreshImageClicked, true);
            app.general_refresh.ImageSource = 'Refresh_18.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winServer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end