classdef winSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        toolGrid                       matlab.ui.container.GridLayout
        toolButton_edit                matlab.ui.control.Button
        debug_PromptKey                matlab.ui.control.EditField
        debug_Image                    matlab.ui.control.Image
        debug_ReturnSettings           matlab.ui.control.Image
        debug_Grid                     matlab.ui.container.GridLayout
        debug_PromptPanel              matlab.ui.container.Panel
        debug_PromptGrid               matlab.ui.container.GridLayout
        debug_Prompt                   matlab.ui.control.HTML
        debug_Tree                     matlab.ui.container.Tree
        debug_EntryListDel             matlab.ui.control.Image
        debug_EntryList                matlab.ui.control.DropDown
        debug_Entry                    matlab.ui.control.TextArea
        debug_EntryLabel               matlab.ui.control.Label
        MainPanel                      matlab.ui.container.Panel
        MainPanelGrid                  matlab.ui.container.GridLayout
        Tab1_Panel                     matlab.ui.container.Panel
        general_Grid                   matlab.ui.container.GridLayout
        Tab2_Panel                     matlab.ui.container.Panel
        server_Grid                    matlab.ui.container.GridLayout
        server_Status                  matlab.ui.control.DropDown
        server_StatusLabel             matlab.ui.control.Label
        server_Port                    matlab.ui.control.NumericEditField
        server_PortLabel               matlab.ui.control.Label
        server_IP                      matlab.ui.control.EditField
        server_IPLabel                 matlab.ui.control.Label
        server_ClientList              matlab.ui.control.EditField
        server_ClientListLabel         matlab.ui.control.Label
        server_Key                     matlab.ui.control.EditField
        server_KeyLabel                matlab.ui.control.Label
        general_gpu                    matlab.ui.control.DropDown
        general_gpuLabel               matlab.ui.control.Label
        general_versionLabel           matlab.ui.control.Label
        general_stationPanel           matlab.ui.container.Panel
        general_stationGrid            matlab.ui.container.GridLayout
        general_lastSessionInfoGrid    matlab.ui.container.GridLayout
        general_lastSessionInfo        matlab.ui.control.CheckBox
        general_lastSessionInfoLabel   matlab.ui.control.Label
        general_stationLongitude       matlab.ui.control.NumericEditField
        general_stationLongitudeLabel  matlab.ui.control.Label
        general_stationLatitude        matlab.ui.control.NumericEditField
        general_stationLatitudeLabel   matlab.ui.control.Label
        general_stationType            matlab.ui.control.DropDown
        general_stationTypeLabel       matlab.ui.control.Label
        general_stationName            matlab.ui.control.EditField
        general_stationNameLabel       matlab.ui.control.Label
        general_stationLabel           matlab.ui.control.Label
        general_versionPanel           matlab.ui.container.Panel
        general_versionGrid            matlab.ui.container.GridLayout
        general_version                matlab.ui.control.HTML
        Tab3_Panel                     matlab.ui.container.Panel
        plot_Grid                      matlab.ui.container.GridLayout
        plot_IntegrationPanel          matlab.ui.container.Panel
        plot_IntegrationGrid           matlab.ui.container.GridLayout
        plot_IntegrationTrace          matlab.ui.control.NumericEditField
        plot_IntegrationTraceLabel     matlab.ui.control.Label
        plot_IntegrationTime           matlab.ui.control.NumericEditField
        plot_IntegrationTimeLabel      matlab.ui.control.Label
        plot_IntegrationLabel          matlab.ui.control.Label
        plot_WaterfallPanel            matlab.ui.container.Panel
        plot_WaterfallGrid             matlab.ui.container.GridLayout
        plot_WaterfallColormap         matlab.ui.control.DropDown
        plot_WaterfallColormapLabel    matlab.ui.control.Label
        plot_WaterfallDepth            matlab.ui.control.DropDown
        plot_WaterfallDepthLabel       matlab.ui.control.Label
        plot_WaterfallLabel            matlab.ui.control.Label
        plot_colorsGrid                matlab.ui.container.GridLayout
        plot_colorsMaxHold             matlab.ui.control.Button
        plot_colorsClearWrite          matlab.ui.control.Button
        plot_colorsAverage             matlab.ui.control.Button
        plot_colorsMinHold             matlab.ui.control.Button
        plot_colorsLabel               matlab.ui.control.Label
        plot_TiledSpacing              matlab.ui.control.DropDown
        plot_TiledSpacingLabel         matlab.ui.control.Label
        plot_InteractionsPanel         matlab.ui.container.Panel
        plot_InteractionsGrid          matlab.ui.container.GridLayout
        plot_RestoreViewVisibility     matlab.ui.control.Image
        plot_RestoreView               matlab.ui.control.Image
        plot_ZoomOutVisibility         matlab.ui.control.Image
        plot_ZoomOut                   matlab.ui.control.Image
        plot_ZoomInVisibility          matlab.ui.control.Image
        plot_ZoomIn                    matlab.ui.control.Image
        plot_PanVisibility             matlab.ui.control.Image
        plot_Pan                       matlab.ui.control.Image
        plot_DatatipVisibility         matlab.ui.control.Image
        plot_Datatip                   matlab.ui.control.Image
        plot_InteractionsLabel         matlab.ui.control.Label
        Tab3_Grid                      matlab.ui.container.GridLayout
        plot_refresh                   matlab.ui.control.Image
        Tab3_Image                     matlab.ui.control.Image
        Tab3_Title                     matlab.ui.control.Label
        Tab2_GridTitle                 matlab.ui.container.GridLayout
        Tab2_Image                     matlab.ui.control.Image
        Tab2_Title                     matlab.ui.control.Label
        Tab1_GridTitle                 matlab.ui.container.GridLayout
        Tab1_Image                     matlab.ui.control.Image
        Tab1_Title                     matlab.ui.control.Label
        EditionModeGrid                matlab.ui.container.GridLayout
        EditionModeLabel               matlab.ui.control.Label
        ButtonGroupPanel               matlab.ui.container.ButtonGroup
        ButtonGroup_Edit               matlab.ui.control.RadioButton
        ButtonGroup_View               matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        CallingApp
        RootFolder

        General

        editedGeneral
        editedFlag = false

        debugWS = struct()
    end
    

    methods (Access = private)
        %-----------------------------------------------------------------%
        function Layout(app)
            % PAINEL "VERSÃO"                
            verMetaData(1).group = 'SISTEMA OPERACIONAL';
            verMetaData(1).value = struct('Version', app.editedGeneral.version.OS);
            
            verMetaData(2).group = 'MATLAB';
            verMetaData(2).value = app.editedGeneral.version.Matlab;
            
            verMetaData(3).group = 'APPCOLETA';
            verMetaData(3).value = app.editedGeneral.version.(class.Constants.appName);
            
            verMetaData(4).group = 'UNIDADE GRÁFICA';
            verMetaData(4).value = app.editedGeneral.version.OpenGL;
            
            verMetaData(5).group = 'PATH';
            verMetaData(5).value = struct('RootFolder', app.RootFolder, ...
                                          'userPath',   app.editedGeneral.userPath);

            if isdeployed
                verMetaData(5).value.deployedPath = ctfroot;
            end

            app.general_version.HTMLSource = fcn.metadataInfo(verMetaData);


            % PAINEL "CONFIGURAÇÕES GERAIS"
            app.general_stationName.Value        = app.editedGeneral.stationInfo.Name;
            app.general_stationType.Items        = {app.editedGeneral.stationInfo.Type};
            app.general_stationLatitude.Value    = app.editedGeneral.stationInfo.Latitude;
            app.general_stationLongitude.Value   = app.editedGeneral.stationInfo.Longitude;
            app.general_lastSessionInfo.Value    = app.editedGeneral.startupInfo;

            switch app.editedGeneral.tcpServer.Status
                case 0; app.server_Status.Items  = {'OFF'};
                case 1; app.server_Status.Items  = {'ON'};
            end

            app.server_Key.Value        = app.editedGeneral.tcpServer.Key;
            app.server_ClientList.Value = strjoin(app.editedGeneral.tcpServer.ClientList, ', ');
            app.server_IP.Value         = app.editedGeneral.tcpServer.IP;
            app.server_Port.Value       = app.editedGeneral.tcpServer.Port;
            
            graphRender = opengl('data');
            switch graphRender.HardwareSupportLevel
                case 'basic'; app.general_gpu.Items = {'hardwarebasic'};
                case 'full';  app.general_gpu.Items = {'hardware'};
                case 'none';  app.general_gpu.Items = {'software'};
                otherwise;    app.general_gpu.Items = {graphRender.HardwareSupportLevel}; % "driverissue"
            end


            % PAINEL "PLOT"
            Layout_plotInteractions(app)
            Layout_plotTiledSpacing(app)

            app.plot_colorsMinHold.BackgroundColor    = app.editedGeneral.Colors(1,:);
            app.plot_colorsAverage.BackgroundColor    = app.editedGeneral.Colors(2,:);
            app.plot_colorsMaxHold.BackgroundColor    = app.editedGeneral.Colors(3,:);
            app.plot_colorsClearWrite.BackgroundColor = app.editedGeneral.Colors(4,:);
            
            app.plot_WaterfallColormap.Items          = {app.editedGeneral.Waterfall.Colormap};
            app.plot_WaterfallDepth.Items             = {num2str(app.editedGeneral.Waterfall.Depth)};

            app.plot_IntegrationTrace.Value           = app.editedGeneral.Integration.Trace;
            app.plot_IntegrationTime.Value            = app.editedGeneral.Integration.SampleTime;
        end


        %-----------------------------------------------------------------%
        function Layout_plotInteractions(app)
            set(findobj(app.plot_InteractionsGrid, Tag='InteractionVisibility'), Visible=0)

            for ii = 1:numel(app.CallingApp.axes1.Toolbar.Children)
                switch app.CallingApp.axes1.Toolbar.Children(ii).Tag
                    case 'datacursor';  h = app.plot_DatatipVisibility;
                    case 'pan';         h = app.plot_PanVisibility;
                    case 'zoomin';      h = app.plot_ZoomInVisibility;
                    case 'zoomout';     h = app.plot_ZoomOutVisibility;
                    case 'restoreview'; h = app.plot_RestoreViewVisibility;
                end

                if app.CallingApp.axes1.Toolbar.Children(ii).Visible
                    h.Visible = 1;
                end
            end
        end


        %-----------------------------------------------------------------%
        function Layout_plotTiledSpacing(app)
            app.plot_TiledSpacing.Value = app.CallingApp.axes1.Parent.TileSpacing;
        end


        %-----------------------------------------------------------------%
        function status = IPv4Validation(app, ipAddress)
            status   = true;
            ipString = regexp(ipAddress, '\d*[.]{1}\d{1,3}[.]{1}\d{1,3}[.]{1}\d*', 'match');
            
            if isempty(ipString)
                status = false;
            else
                ipArray = cellfun(@(x) str2double(x), strsplit(char(ipString), '.'));
                if any(ipArray > 255) || any(isnan(ipArray))
                    status = false;
                end                    
            end
        end


        %-----------------------------------------------------------------%
        function debugRun(app, Entry)
            Entry = strjoin(Entry, '\n');

            if ~isempty(Entry)
                switch Entry
                    case 'clc'
                        app.debug_Prompt.HTMLSource = ' ';
    
                    otherwise
                        try
                            msg = evalc(Entry);
                            if contains(Entry, 'app.debugWS')
                                debugVariables(app)
                            end
                            drawnow
                        catch ME
                            msg = getReport(ME);
                        end
                        app.debug_Prompt.HTMLSource = sprintf('<p style="font-family: Consolas; font-size: 12px;">%s</p>', replace(msg, newline, '<br>'));
                end
    
                if ~exist('ME', 'var')
                    if ~ismember(Entry, app.debug_EntryList.Items)
                        app.debug_EntryList.Items{end+1} = Entry;
                        app.debug_EntryList.Value        = app.debug_EntryList.Items{end};
                    end
                end
            end
        end


        %-----------------------------------------------------------------%
        function debugVariables(app)
            if ~isempty(app.debug_Tree.Children)
                delete(app.debug_Tree.Children)
            end

            debugVariables = fields(app.debugWS);
            for ii = 1:numel(debugVariables)
                nodeParent = uitreenode(app.debug_Tree, 'Text', debugVariables{ii});
                uitreenode(nodeParent, 'Text',  sprintf('%s %s', strjoin(string(size(app.debugWS.(debugVariables{ii}))), 'x'), class(app.debugWS.(debugVariables{ii}))));
            end
            expand(app.debug_Tree, 'all')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            % A razão de ser deste app é possibilitar visualização/edição 
            % do arquivo "GeneralSettings.json".
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            fcn.winPosition(app)

            app.General    = app.CallingApp.General;
            app.editedGeneral = app.General;
            
            % Ajustes iniciais no layout do app, tornando invisíveis itens 
            % que aparecerão apenas no "MODO DE EDIÇÃO". E, por fim,
            % organização da informação nos componentes.

            app.EditionModeGrid.Visible = 0;
            app.toolButton_edit.Visible = 0;
            app.plot_refresh.Visible    = 0;

            Layout(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            if app.editedFlag
                fcn.GeneralSettings(app.General, app.RootFolder)
                app.CallingApp.General = app.General;
                eval(sprintf('opengl %s', app.General.openGL))
            end

            % A validação abaixo é necessária pois quando o app é fechado, 
            % no ambiente do MATLAB (e não do Runtime), e o modo de DEBUG 
            % está aberto, o app não conseguirá deletar essa janela. Isto  
            % acontece porque no modo de DEBUG a propriedade "auxiliarWin1",
            % do app , é alterada para vazio, o que possibilita abrir outra 
            % janela deste modo auxiliar (auxApp.winSettings).

            if isvalid(app.CallingApp)
                app.CallingApp.auxiliarWin1 = [];
            end

            delete(app)
            
        end

        % Selection changed function: ButtonGroupPanel
        function ValueChanged_OperationMode(app, event)
            
            %-------------------------------------------------------------%
            % ## MODO DE VISUALIZAÇÃO ##
            %-------------------------------------------------------------%
            if app.ButtonGroup_View.Value
                % Aspectos relacionados ao modo de DEBUG:
                app.debug_Image.Visible     = 0;
                app.debug_PromptKey.Visible = 0;

                % Aspectos relacionados à indicação visual de que se trata 
                % do modo de visualização:
                app.EditionModeGrid.Visible = 0;
                app.toolButton_edit.Visible = 0;
                app.plot_refresh.Visible    = 0;

                % Desabilita edição do conteúdo dos campos...
                set(findobj(app.general_stationGrid,  'Type', 'uinumericeditfield', '-or', 'Type', 'uieditfield'), Editable='off')
                set(findobj(app.server_Grid,          'Type', 'uinumericeditfield', '-or', 'Type', 'uieditfield'), Editable='off')
                set(findobj(app.plot_WaterfallGrid,   'Type', 'uinumericeditfield', '-or', 'Type', 'uieditfield'), Editable='off')
                set(findobj(app.plot_IntegrationGrid, 'Type', 'uinumericeditfield', '-or', 'Type', 'uieditfield'), Editable='off')
                
                app.general_lastSessionInfo.Enable = 0;

                set(app.general_stationType,    'Items', {app.general_stationType.Value})
                set(app.server_Status,          'Items', {app.server_Status.Value})
                set(app.general_gpu,            'Items', {app.general_gpu.Value})
                set(app.plot_WaterfallColormap, 'Items', {app.plot_WaterfallColormap.Value})
                set(app.plot_WaterfallDepth,    'Items', {app.plot_WaterfallDepth.Value})

                % Essa última validação é essencial para desfazer alterações 
                % que não foram salvas. Ou seja, o usuário fez alterações
                % em app.General (que estavam armazenadas na sua cópia -
                % app.editedGeneral) e não clicou no botão "Confirma edição".

                if ~isequal(app.General, app.editedGeneral)
                    app.editedGeneral = app.General;
                    Layout(app)
                end

            %-------------------------------------------------------------%
            % ## MODO DE EDIÇÃO ##
            %-------------------------------------------------------------%
            else
                % Aspectos relacionados ao modo de DEBUG:
                app.debug_Image.Visible     = 1;
                app.debug_PromptKey.Visible = 0;

                % Aspectos relacionados à indicação visual de que se trata 
                % do modo de edição:
                app.EditionModeGrid.Visible = 1;
                app.toolButton_edit.Visible = 1;
                app.plot_refresh.Visible    = 1;

                % Habilita edição do conteúdo dos campos...
                set(findobj(app.general_stationGrid,  'Type', 'uinumericeditfield', '-or', 'Type', 'uieditfield'), Editable='on')
                set(findobj(app.server_Grid,          'Type', 'uinumericeditfield', '-or', 'Type', 'uieditfield'), Editable='on')
                set(findobj(app.plot_WaterfallGrid,   'Type', 'uinumericeditfield', '-or', 'Type', 'uieditfield'), Editable='on')
                set(findobj(app.plot_IntegrationGrid, 'Type', 'uinumericeditfield', '-or', 'Type', 'uieditfield'), Editable='on')
                app.general_lastSessionInfo.Enable = 1;

                set(app.general_stationType,    'Items', {'Fixed', 'Mobile'})
                set(app.server_Status,          'Items', {'ON', 'OFF'})
                set(app.plot_WaterfallColormap, 'Items', {'gray', 'hot', 'jet', 'summer', 'turbo', 'winter'})
                set(app.plot_WaterfallDepth,    'Items', {'64', '128', '256', '512'})

                if ismember(app.general_gpu.Value, {'hardwarebasic', 'hardware', 'software'})
                    set(app.general_gpu,        'Items', {'hardwarebasic', 'hardware', 'software'})
                else
                    set(app.general_gpu,        'Items', {'hardwarebasic', 'hardware', 'software', app.general_gpu.Value})
                end
            end

        end

        % Value changed function: general_gpu, general_lastSessionInfo, 
        % ...and 13 other components
        function ValueChanged_Parameter(app, event)
            
            switch event.Source
                %---------------------------------------------------------%
                case app.general_stationName
                    if ~isempty(regexp(app.general_stationName.Value, '^EMSat$|^ERMx-[A-Z]{2}-[0-9][1-9]$', 'once'))
                        app.editedGeneral.stationInfo.Name = app.general_stationName.Value;
                    else
                        app.general_stationName.Value = event.PreviousValue;
                        layoutFcn.modalWindow(app.UIFigure, "ccTools.MessageBox", 'Formato esperado: "EMSat" ou "ERMx-UF-XX", sendo "UF" a sigla da unidade da federação e XX dois dígitos numéricos (01 a 99). Por exemplo: "ERMx-BA-01".');
                    end


                %---------------------------------------------------------%
                case app.general_stationType
                    app.editedGeneral.stationInfo.Type      = app.general_stationType.Value;


                %---------------------------------------------------------%
                case app.general_stationLatitude
                    app.editedGeneral.stationInfo.Latitude  = app.general_stationLatitude.Value;


                %---------------------------------------------------------%
                case app.general_stationLongitude
                    app.editedGeneral.stationInfo.Longitude = app.general_stationLongitude.Value;


                %---------------------------------------------------------%
                case app.general_lastSessionInfo
                    app.editedGeneral.startupInfo = app.general_lastSessionInfo.Value;
                    

                %---------------------------------------------------------%
                case app.server_Status
                    switch app.server_Status.Value
                        case 'ON';  app.editedGeneral.tcpServer.Status = 1;
                        case 'OFF'; app.editedGeneral.tcpServer.Status = 0;
                    end

                case app.server_Key
                    app.server_Key.Value = replace(app.server_Key.Value, ' ', '');
                    app.editedGeneral.tcpServer.Key = app.server_Key.Value;

                case app.server_ClientList
                    app.server_ClientList.Value = replace(app.server_ClientList.Value, ' ', '');
                    
                    if isempty(app.server_ClientList.Value)
                        app.editedGeneral.tcpServer.ClientList = {};
                    else
                        app.editedGeneral.tcpServer.ClientList = strsplit(app.server_ClientList.Value, ',');
                    end

                    app.server_ClientList.Value = strjoin(app.editedGeneral.tcpServer.ClientList, ', ');

                case app.server_IP
                    app.server_IP.Value = strtrim(app.server_IP.Value);

                    if IPv4Validation(app, app.server_IP.Value) || isempty(app.server_IP.Value)
                        app.editedGeneral.tcpServer.IP = app.server_IP.Value;
                    else
                        app.server_IP.Value = event.PreviousValue;
                        layoutFcn.modalWindow(app.UIFigure, "ccTools.MessageBox", 'Endereço inválido (IPv4).');
                    end

                case app.server_Port
                    app.editedGeneral.tcpServer.Port = app.server_Port.Value;


                %---------------------------------------------------------%
                case app.general_gpu
                    if ismember(app.general_gpu.Value, {'hardwarebasic', 'hardware', 'software'})
                        app.editedGeneral.openGL = app.general_gpu.Value;
                    end


                %---------------------------------------------------------%
                case {app.plot_colorsMinHold, app.plot_colorsAverage, app.plot_colorsMaxHold, app.plot_colorsClearWrite}
                    switch event.Source
                        case app.plot_colorsMinHold;    idx = 1;
                        case app.plot_colorsAverage;    idx = 2;
                        case app.plot_colorsMaxHold;    idx = 3;
                        case app.plot_colorsClearWrite; idx = 4;
                    end
                    app.editedGeneral.Colors(idx,:) = event.Source.BackgroundColor';

                %---------------------------------------------------------%
                case app.plot_WaterfallColormap
                    app.editedGeneral.Waterfall.Colormap = app.plot_WaterfallColormap.Value;

                case app.plot_WaterfallDepth
                    app.editedGeneral.Waterfall.Depth = str2double(app.plot_WaterfallDepth.Value);

                
                %---------------------------------------------------------%
                case app.plot_IntegrationTrace
                    app.editedGeneral.Integration.Trace = app.plot_IntegrationTrace.Value;

                case app.plot_IntegrationTime
                    app.editedGeneral.Integration.SampleTime = app.plot_IntegrationTime.Value;
            end
            
        end

        % Image clicked function: plot_refresh
        function plotImageClicked_refresh(app, event)
            
            % Axes toolbar
            Interactions = class.Constants.Interactions;
            plotFcn.axesInteractions(app.CallingApp.axes1, Interactions)
            plotFcn.axesInteractions(app.CallingApp.axes2, Interactions)
            Layout_plotInteractions(app)

            app.CallingApp.axes1.Parent.TileSpacing = 'tight';            
            Layout_plotTiledSpacing(app)

            % Others parameters...
            defaultGeneral = struct('Colors',     [0.38,0.60,0.73;  ...
                                                   0.39,0.83,0.07;  ...
                                                   1.00,0.07,0.65;  ...
                                                   0.93,0.69,0.13], ...
                                    'Waterfall',   struct('Colormap', 'winter', 'Depth', 512), ...
                                    'Integration', struct('Trace', 10, 'SampleTime', 10));

            if ~isequal(app.editedGeneral.Colors,      defaultGeneral.Colors)    || ...
               ~isequal(app.editedGeneral.Waterfall,   defaultGeneral.Waterfall) || ...
               ~isequal(app.editedGeneral.Integration, defaultGeneral.Integration)

                app.editedGeneral.Colors      = defaultGeneral.Colors;
                app.editedGeneral.Waterfall   = defaultGeneral.Waterfall;
                app.editedGeneral.Integration = defaultGeneral.Integration;

                Layout(app)
                ValueChanged_OperationMode(app)
            end

        end

        % Image clicked function: plot_Datatip, plot_Pan, 
        % ...and 3 other components
        function plotImageClicked_Interactions(app, event)
            
            % Interações atuais:
            for ii = 1:numel(app.CallingApp.axes1.Toolbar.Children)
                if isprop(app.CallingApp.axes1.Toolbar.Children(ii), 'Value') && app.CallingApp.axes1.Toolbar.Children(ii).Value
                    layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', 'Operação não realizada! É preciso desabilitar qualquer interação com os plots...');
                    return
                end
            end

            % Interações desejadas:
            switch event.Source
                case app.plot_Datatip;     h = app.plot_DatatipVisibility;
                case app.plot_Pan;         h = app.plot_PanVisibility;
                case app.plot_ZoomIn;      h = app.plot_ZoomInVisibility;
                case app.plot_ZoomOut;     h = app.plot_ZoomOutVisibility;
                case app.plot_RestoreView; h = app.plot_RestoreViewVisibility;
            end
            h.Visible = ~h.Visible;
            
            finalInteractions = {};
            if app.plot_DatatipVisibility.Visible;     finalInteractions = [finalInteractions, 'datacursor' ]; end
            if app.plot_PanVisibility.Visible;         finalInteractions = [finalInteractions, 'pan'        ]; end
            if app.plot_ZoomInVisibility.Visible;      finalInteractions = [finalInteractions, 'zoomin'     ]; end
            if app.plot_ZoomOutVisibility.Visible;     finalInteractions = [finalInteractions, 'zoomout'    ]; end
            if app.plot_RestoreViewVisibility.Visible; finalInteractions = [finalInteractions, 'restoreview']; end

            plotFcn.axesInteractions(app.CallingApp.axes1, finalInteractions)
            plotFcn.axesInteractions(app.CallingApp.axes2, finalInteractions)

            if isempty(finalInteractions)
                app.CallingApp.axes1.PickableParts = 'none';
                app.CallingApp.axes2.PickableParts = 'none';
            else
                app.CallingApp.axes1.PickableParts = 'visible';
                app.CallingApp.axes2.PickableParts = 'visible';
            end

            Layout_plotInteractions(app)

        end

        % Value changed function: plot_TiledSpacing
        function plotDropDown_TiledSpacing(app, event)
            
            app.CallingApp.axes1.Parent.TileSpacing = app.plot_TiledSpacing.Value;
            
        end

        % Button pushed function: plot_colorsAverage, 
        % ...and 3 other components
        function plotButtonPushed_colors(app, event)
            
            if app.ButtonGroup_Edit.Value
                initialColor  = event.Source.BackgroundColor;
                selectedColor = uisetcolor(initialColor);
                figure(app.UIFigure)
    
                if ~isequal(initialColor, selectedColor)
                    switch event.Source
                        case app.plot_colorsMinHold;    app.plot_colorsMinHold.BackgroundColor    = selectedColor;
                        case app.plot_colorsAverage;    app.plot_colorsAverage.BackgroundColor    = selectedColor;
                        case app.plot_colorsMaxHold;    app.plot_colorsMaxHold.BackgroundColor    = selectedColor;
                        case app.plot_colorsClearWrite; app.plot_colorsClearWrite.BackgroundColor = selectedColor;
                    end
    
                    ValueChanged_Parameter(app, event)
                end
            end

        end

        % Button pushed function: toolButton_edit
        function toolButtonPushed_edit(app, event)
            
            % Finalizada a edição, avalia-se se algum parâmetro foi, de fato, 
            % alterado, colocando essa informação na variável app.editedFlag.
            % Por fim, volta-se ao "MODO DE VISUALIZAÇÃO", clicando-se
            % programaticamente no botão de controle e, posteriormente,
            % acionando o seu callback.

            if ~isequal(app.General, app.editedGeneral)
                app.General    = app.editedGeneral;
                app.editedFlag = true;
            end
            
            app.ButtonGroup_View.Value = 1;
            ValueChanged_OperationMode(app)

        end

        % Callback function: debug_Entry, debug_EntryList, 
        % ...and 4 other components
        function debugCallbacks(app, event)

            switch event.Source
                %---------------------------------------------------------%
                case app.debug_Image
                    if app.debug_PromptKey.Visible
                        app.debug_PromptKey.Visible = 0;
                    else
                        ccTools.compCustomization(app.debug_PromptKey, 'borderWidth', '0px');
                        app.debug_PromptKey.Visible = 1;
                    end


                %---------------------------------------------------------%
                case app.debug_PromptKey
                    try
                        decodedCode = matlabCommunity.invCipher(class.Constants.debugKey, strtrim(app.debug_PromptKey.Value));
                        switch decodedCode
                            case class.Constants.debugCode
                                app.UIFigure.Resize      = "on";
                                app.GridLayout.RowHeight = {0, 0, '1x', 22};
                                set(app.debug_Grid.Children, Enable="on")

                                app.toolGrid.ColumnWidth(1:2)    = {22, 0};
                                app.debug_ReturnSettings.Visible = "on";
                                app.debug_PromptKey.Visible      = "off";
                                app.toolButton_edit.Visible      = "off";
                                
                                app.CallingApp.auxiliarWin1     = [];
                                
                                focus(app.debug_Entry)
    
                            otherwise
                                error('Chave errada.')
                        end

                    catch ME
                        layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', ME.message);
                    end
                    app.debug_PromptKey.Value = '';


                %---------------------------------------------------------%
                case app.debug_ReturnSettings
                    app.UIFigure.Position(3:4) = [940,540]; 
                    app.UIFigure.Resize        = "off";
                    fcn.winPosition(app)

                    app.GridLayout.RowHeight   = {22, '1x', 0, 22};
                    set(app.debug_Grid.Children, Enable="off")

                    app.toolGrid.ColumnWidth(1:2)    = {0, 22};
                    app.debug_ReturnSettings.Visible = "off";

                    app.CallingApp.auxiliarWin1 = app;

                    app.ButtonGroup_View.Value  = 1;
                    app.ValueChanged_OperationMode(app)


                %---------------------------------------------------------%
                case app.debug_Entry
                    idx = cellfun(@(x) isempty(x), app.debug_Entry.Value);
                    app.debug_Entry.Value(idx) = [];
                    app.debug_Entry.Value = strtrim(app.debug_Entry.Value);

                    debugRun(app, app.debug_Entry.Value)


                %---------------------------------------------------------%
                case app.debug_EntryListDel
                    app.debug_EntryList.Items = {};


                %---------------------------------------------------------%
                case app.debug_EntryList
                    debugRun(app, app.debug_EntryList.Value)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ButtonGroupPanel = uibuttongroup(app.GridLayout);
            app.ButtonGroupPanel.SelectionChangedFcn = createCallbackFcn(app, @ValueChanged_OperationMode, true);
            app.ButtonGroup_View = uiradiobutton(app.ButtonGroupPanel);
            app.ButtonGroup_View.Text = 'Visualizar parâmetros';
            app.ButtonGroup_View.Value = true;
            app.ButtonGroup_Edit = uiradiobutton(app.ButtonGroupPanel);
            app.ButtonGroup_Edit.Text = 'Editar parâmetros';
            app.EditionModeGrid = uigridlayout(app.GridLayout);
            app.EditionModeLabel = uilabel(app.EditionModeGrid);
            app.EditionModeLabel.Text = 'MODO DE EDIÇÃO';
            app.MainPanel = uipanel(app.GridLayout);
            app.MainPanelGrid = uigridlayout(app.MainPanel);
            app.Tab1_GridTitle = uigridlayout(app.MainPanelGrid);
            app.Tab1_GridTitle.Tag = 'COLORLOCKED';
            app.Tab1_Title = uilabel(app.Tab1_GridTitle);
            app.Tab1_Title.Text = 'VERSÃO';
            app.Tab1_Image = uiimage(app.Tab1_GridTitle);
            app.Tab1_Image.ImageSource = 'LT_settings.png';
            app.Tab2_GridTitle = uigridlayout(app.MainPanelGrid);
            app.Tab2_GridTitle.Tag = 'COLORLOCKED';
            app.Tab2_Title = uilabel(app.Tab2_GridTitle);
            app.Tab2_Title.Text = 'CONFIGURAÇÕES GERAIS';
            app.Tab2_Image = uiimage(app.Tab2_GridTitle);
            app.Tab2_Image.ImageSource = 'LT_Dots2.png';
            app.Tab3_Grid = uigridlayout(app.MainPanelGrid);
            app.Tab3_Grid.Tag = 'COLORLOCKED';
            app.Tab3_Title = uilabel(app.Tab3_Grid);
            app.Tab3_Title.Text = 'PLOT';
            app.Tab3_Image = uiimage(app.Tab3_Grid);
            app.Tab3_Image.ImageSource = 'LT_Detection.png';
            app.plot_refresh = uiimage(app.Tab3_Grid);
            app.plot_refresh.ImageClickedFcn = createCallbackFcn(app, @plotImageClicked_refresh, true);
            app.plot_refresh.ImageSource = 'LT_refresh.png';
            app.Tab3_Panel = uipanel(app.MainPanelGrid);
            app.plot_Grid = uigridlayout(app.Tab3_Panel);
            app.plot_InteractionsLabel = uilabel(app.plot_Grid);
            app.plot_InteractionsLabel.Text = 'Interações:';
            app.plot_InteractionsPanel = uipanel(app.plot_Grid);
            app.plot_InteractionsGrid = uigridlayout(app.plot_InteractionsPanel);
            app.plot_Datatip = uiimage(app.plot_InteractionsGrid);
            app.plot_Datatip.ImageClickedFcn = createCallbackFcn(app, @plotImageClicked_Interactions, true);
            app.plot_Datatip.Tooltip = {'Datatip'};
            app.plot_Datatip.ImageSource = 'AxesToolbar_Datatip.png';
            app.plot_DatatipVisibility = uiimage(app.plot_InteractionsGrid);
            app.plot_DatatipVisibility.Tag = 'InteractionVisibility';
            app.plot_DatatipVisibility.Tooltip = {'Datatip'};
            app.plot_DatatipVisibility.ImageSource = 'LT_LineH.png';
            app.plot_Pan = uiimage(app.plot_InteractionsGrid);
            app.plot_Pan.ImageClickedFcn = createCallbackFcn(app, @plotImageClicked_Interactions, true);
            app.plot_Pan.Tooltip = {'Pan'};
            app.plot_Pan.ImageSource = 'AxesToolbar_Pan.png';
            app.plot_PanVisibility = uiimage(app.plot_InteractionsGrid);
            app.plot_PanVisibility.Tag = 'InteractionVisibility';
            app.plot_PanVisibility.Tooltip = {'Datatip'};
            app.plot_PanVisibility.ImageSource = 'LT_LineH.png';
            app.plot_ZoomIn = uiimage(app.plot_InteractionsGrid);
            app.plot_ZoomIn.ImageClickedFcn = createCallbackFcn(app, @plotImageClicked_Interactions, true);
            app.plot_ZoomIn.Tooltip = {'Zoom in'};
            app.plot_ZoomIn.ImageSource = 'AxesToolbar_ZoomIn.png';
            app.plot_ZoomInVisibility = uiimage(app.plot_InteractionsGrid);
            app.plot_ZoomInVisibility.Tag = 'InteractionVisibility';
            app.plot_ZoomInVisibility.Tooltip = {'Datatip'};
            app.plot_ZoomInVisibility.ImageSource = 'LT_LineH.png';
            app.plot_ZoomOut = uiimage(app.plot_InteractionsGrid);
            app.plot_ZoomOut.ImageClickedFcn = createCallbackFcn(app, @plotImageClicked_Interactions, true);
            app.plot_ZoomOut.Tooltip = {'Zoom out'};
            app.plot_ZoomOut.ImageSource = 'AxesToolbar_ZoomOut.png';
            app.plot_ZoomOutVisibility = uiimage(app.plot_InteractionsGrid);
            app.plot_ZoomOutVisibility.Tag = 'InteractionVisibility';
            app.plot_ZoomOutVisibility.Tooltip = {'Datatip'};
            app.plot_ZoomOutVisibility.ImageSource = 'LT_LineH.png';
            app.plot_RestoreView = uiimage(app.plot_InteractionsGrid);
            app.plot_RestoreView.ImageClickedFcn = createCallbackFcn(app, @plotImageClicked_Interactions, true);
            app.plot_RestoreView.Tooltip = {'Restore view'};
            app.plot_RestoreView.ImageSource = 'AxesToolbar_RestoreView.png';
            app.plot_RestoreViewVisibility = uiimage(app.plot_InteractionsGrid);
            app.plot_RestoreViewVisibility.Tag = 'InteractionVisibility';
            app.plot_RestoreViewVisibility.Tooltip = {'Datatip'};
            app.plot_RestoreViewVisibility.ImageSource = 'LT_LineH.png';
            app.plot_TiledSpacingLabel = uilabel(app.plot_Grid);
            app.plot_TiledSpacingLabel.Text = 'Espaçamento entre eixos:';
            app.plot_TiledSpacing = uidropdown(app.plot_Grid);
            app.plot_TiledSpacing.Items = {'loose', 'compact', 'tight', 'none'};
            app.plot_TiledSpacing.ValueChangedFcn = createCallbackFcn(app, @plotDropDown_TiledSpacing, true);
            app.plot_TiledSpacing.Value = 'loose';
            app.plot_colorsLabel = uilabel(app.plot_Grid);
            app.plot_colorsLabel.Text = 'Cores:';
            app.plot_colorsGrid = uigridlayout(app.plot_Grid);
            app.plot_colorsMinHold = uibutton(app.plot_colorsGrid, 'push');
            app.plot_colorsMinHold.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed_colors, true);
            app.plot_colorsMinHold.Text = 'MinHold';
            app.plot_colorsAverage = uibutton(app.plot_colorsGrid, 'push');
            app.plot_colorsAverage.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed_colors, true);
            app.plot_colorsAverage.Text = 'Tendência central';
            app.plot_colorsClearWrite = uibutton(app.plot_colorsGrid, 'push');
            app.plot_colorsClearWrite.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed_colors, true);
            app.plot_colorsClearWrite.Text = 'ClearWrite';
            app.plot_colorsMaxHold = uibutton(app.plot_colorsGrid, 'push');
            app.plot_colorsMaxHold.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed_colors, true);
            app.plot_colorsMaxHold.Text = 'MaxHold';
            app.plot_WaterfallLabel = uilabel(app.plot_Grid);
            app.plot_WaterfallLabel.Text = 'Waterfall:';
            app.plot_WaterfallPanel = uipanel(app.plot_Grid);
            app.plot_WaterfallGrid = uigridlayout(app.plot_WaterfallPanel);
            app.plot_WaterfallDepthLabel = uilabel(app.plot_WaterfallGrid);
            app.plot_WaterfallDepthLabel.Text = 'Profundidade:';
            app.plot_WaterfallDepth = uidropdown(app.plot_WaterfallGrid);
            app.plot_WaterfallDepth.Items = {'64', '128', '256', '512'};
            app.plot_WaterfallDepth.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.plot_WaterfallDepth.Value = '128';
            app.plot_WaterfallColormapLabel = uilabel(app.plot_WaterfallGrid);
            app.plot_WaterfallColormapLabel.Text = 'Mapa de cor:';
            app.plot_WaterfallColormap = uidropdown(app.plot_WaterfallGrid);
            app.plot_WaterfallColormap.Items = {'gray', 'hot', 'jet', 'summer', 'turbo', 'winter'};
            app.plot_WaterfallColormap.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.plot_WaterfallColormap.Value = 'jet';
            app.plot_IntegrationLabel = uilabel(app.plot_Grid);
            app.plot_IntegrationLabel.Text = 'Integração:';
            app.plot_IntegrationPanel = uipanel(app.plot_Grid);
            app.plot_IntegrationGrid = uigridlayout(app.plot_IntegrationPanel);
            app.plot_IntegrationTimeLabel = uilabel(app.plot_IntegrationGrid);
            app.plot_IntegrationTimeLabel.Text = 'Tempo médio escrita:';
            app.plot_IntegrationTime = uieditfield(app.plot_IntegrationGrid, 'numeric');
            app.plot_IntegrationTime.Limits = [3 100];
            app.plot_IntegrationTime.RoundFractionalValues = 'on';
            app.plot_IntegrationTime.ValueDisplayFormat = '%d';
            app.plot_IntegrationTime.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.plot_IntegrationTime.Editable = 'off';
            app.plot_IntegrationTime.Value = 10;
            app.plot_IntegrationTraceLabel = uilabel(app.plot_IntegrationGrid);
            app.plot_IntegrationTraceLabel.Text = 'Traço médio:';
            app.plot_IntegrationTrace = uieditfield(app.plot_IntegrationGrid, 'numeric');
            app.plot_IntegrationTrace.Limits = [3 100];
            app.plot_IntegrationTrace.RoundFractionalValues = 'on';
            app.plot_IntegrationTrace.ValueDisplayFormat = '%d';
            app.plot_IntegrationTrace.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.plot_IntegrationTrace.Editable = 'off';
            app.plot_IntegrationTrace.Value = 10;
            app.general_versionPanel = uipanel(app.MainPanelGrid);
            app.general_versionGrid = uigridlayout(app.general_versionPanel);
            app.general_version = uihtml(app.general_versionGrid);
            app.Tab1_Panel = uipanel(app.MainPanelGrid);
            app.general_Grid = uigridlayout(app.Tab1_Panel);
            app.general_stationLabel = uilabel(app.general_Grid);
            app.general_stationLabel.Text = 'Estação:';
            app.general_stationPanel = uipanel(app.general_Grid);
            app.general_stationGrid = uigridlayout(app.general_stationPanel);
            app.general_stationNameLabel = uilabel(app.general_stationGrid);
            app.general_stationNameLabel.Text = 'Nome:';
            app.general_stationName = uieditfield(app.general_stationGrid, 'text');
            app.general_stationName.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.general_stationName.Editable = 'off';
            app.general_stationTypeLabel = uilabel(app.general_stationGrid);
            app.general_stationTypeLabel.Text = 'Tipo:';
            app.general_stationType = uidropdown(app.general_stationGrid);
            app.general_stationType.Items = {'Fixed', 'Mobile'};
            app.general_stationType.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.general_stationType.Value = 'Fixed';
            app.general_stationLatitudeLabel = uilabel(app.general_stationGrid);
            app.general_stationLatitudeLabel.Text = {'Latitude:'; '(graus decimais)'};
            app.general_stationLatitude = uieditfield(app.general_stationGrid, 'numeric');
            app.general_stationLatitude.ValueDisplayFormat = '%.6f';
            app.general_stationLatitude.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.general_stationLatitude.Tag = 'task_Editable';
            app.general_stationLatitude.Editable = 'off';
            app.general_stationLatitude.Value = -1;
            app.general_stationLongitudeLabel = uilabel(app.general_stationGrid);
            app.general_stationLongitudeLabel.Text = {'Longitude:'; '(graus decimais)'};
            app.general_stationLongitude = uieditfield(app.general_stationGrid, 'numeric');
            app.general_stationLongitude.ValueDisplayFormat = '%.6f';
            app.general_stationLongitude.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.general_stationLongitude.Tag = 'task_Editable';
            app.general_stationLongitude.Editable = 'off';
            app.general_stationLongitude.Value = -1;
            app.general_lastSessionInfoGrid = uigridlayout(app.general_stationGrid);
            app.general_lastSessionInfoLabel = uilabel(app.general_lastSessionInfoGrid);
            app.general_lastSessionInfoLabel.Text = 'Leitura dados armazenados na última sessão.';
            app.general_lastSessionInfo = uicheckbox(app.general_lastSessionInfoGrid);
            app.general_lastSessionInfo.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.general_lastSessionInfo.Enable = 'off';
            app.general_lastSessionInfo.Text = '';
            app.general_versionLabel = uilabel(app.general_Grid);
            app.general_versionLabel.Text = 'Servidor TCP:';
            app.general_gpuLabel = uilabel(app.general_Grid);
            app.general_gpuLabel.Text = 'Unidade gráfica:';
            app.general_gpu = uidropdown(app.general_Grid);
            app.general_gpu.Items = {};
            app.general_gpu.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.general_gpu.Value = {};
            app.Tab2_Panel = uipanel(app.general_Grid);
            app.server_Grid = uigridlayout(app.Tab2_Panel);
            app.server_KeyLabel = uilabel(app.server_Grid);
            app.server_KeyLabel.Text = 'Chave:';
            app.server_Key = uieditfield(app.server_Grid, 'text');
            app.server_Key.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.server_Key.Editable = 'off';
            app.server_ClientListLabel = uilabel(app.server_Grid);
            app.server_ClientListLabel.Text = {'Lista de clientes:'; '(valores separados por vírgula)'};
            app.server_ClientList = uieditfield(app.server_Grid, 'text');
            app.server_ClientList.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.server_ClientList.Editable = 'off';
            app.server_IPLabel = uilabel(app.server_Grid);
            app.server_IPLabel.Text = 'Endereço IP (OpenVPN):';
            app.server_IP = uieditfield(app.server_Grid, 'text');
            app.server_IP.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.server_IP.Editable = 'off';
            app.server_PortLabel = uilabel(app.server_Grid);
            app.server_PortLabel.Text = 'Porta:';
            app.server_Port = uieditfield(app.server_Grid, 'numeric');
            app.server_Port.Limits = [1 65535];
            app.server_Port.RoundFractionalValues = 'on';
            app.server_Port.ValueDisplayFormat = '%d';
            app.server_Port.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.server_Port.Editable = 'off';
            app.server_Port.Value = 1;
            app.server_StatusLabel = uilabel(app.server_Grid);
            app.server_StatusLabel.Text = 'Estado:';
            app.server_Status = uidropdown(app.server_Grid);
            app.server_Status.Items = {'ON', 'OFF'};
            app.server_Status.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.server_Status.Value = 'ON';
            app.debug_Grid = uigridlayout(app.GridLayout);
            app.debug_EntryLabel = uilabel(app.debug_Grid);
            app.debug_EntryLabel.Enable = 'off';
            app.debug_EntryLabel.Text = '>>';
            app.debug_Entry = uitextarea(app.debug_Grid);
            app.debug_Entry.ValueChangedFcn = createCallbackFcn(app, @debugCallbacks, true);
            app.debug_Entry.Enable = 'off';
            app.debug_EntryList = uidropdown(app.debug_Grid);
            app.debug_EntryList.Items = {};
            app.debug_EntryList.ValueChangedFcn = createCallbackFcn(app, @debugCallbacks, true);
            app.debug_EntryList.Enable = 'off';
            app.debug_EntryList.Value = {};
            app.debug_EntryListDel = uiimage(app.debug_Grid);
            app.debug_EntryListDel.ImageClickedFcn = createCallbackFcn(app, @debugCallbacks, true);
            app.debug_EntryListDel.Enable = 'off';
            app.debug_EntryListDel.ImageSource = 'LT_redX.png';
            app.debug_Tree = uitree(app.debug_Grid);
            app.debug_Tree.Enable = 'off';
            app.debug_PromptPanel = uipanel(app.debug_Grid);
            app.debug_PromptPanel.Enable = 'off';
            app.debug_PromptGrid = uigridlayout(app.debug_PromptPanel);
            app.debug_Prompt = uihtml(app.debug_PromptGrid);
            app.debug_Prompt.HTMLSource = ' ';
            app.toolGrid = uigridlayout(app.GridLayout);
            app.debug_ReturnSettings = uiimage(app.toolGrid);
            app.debug_ReturnSettings.ImageClickedFcn = createCallbackFcn(app, @debugCallbacks, true);
            app.debug_ReturnSettings.Visible = 'off';
            app.debug_ReturnSettings.ImageSource = 'LT_Dots2.png';
            app.debug_Image = uiimage(app.toolGrid);
            app.debug_Image.ImageClickedFcn = createCallbackFcn(app, @debugCallbacks, true);
            app.debug_Image.Visible = 'off';
            app.debug_Image.ImageSource = 'LT_prompt.png';
            app.debug_PromptKey = uieditfield(app.toolGrid, 'text');
            app.debug_PromptKey.ValueChangedFcn = createCallbackFcn(app, @debugCallbacks, true);
            app.debug_PromptKey.Visible = 'off';
            app.toolButton_edit = uibutton(app.toolGrid, 'push');
            app.toolButton_edit.ButtonPushedFcn = createCallbackFcn(app, @toolButtonPushed_edit, true);
            app.toolButton_edit.Icon = 'LT_edit.png';
            app.toolButton_edit.Text = 'Confirma edição';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end