classdef winTracking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Grid               matlab.ui.container.GridLayout
        CancelButton_2     matlab.ui.control.Button
        CancelButton       matlab.ui.control.Button
        ErrorLabel         matlab.ui.control.Label
        Panel              matlab.ui.container.Panel
        GridLayout         matlab.ui.container.GridLayout
        ElevationGrid      matlab.ui.container.GridLayout
        ElevationGauge     matlab.ui.control.NinetyDegreeGauge
        Azimuth            matlab.ui.control.Label
        AzimuthLabel       matlab.ui.control.Label
        Elevation          matlab.ui.control.Label
        ElevationLabel     matlab.ui.control.Label
        Polarization       matlab.ui.control.Label
        PolarizationLabel  matlab.ui.control.Label
        PolarizationGauge  matlab.ui.control.Gauge
        AzimuthGauge       matlab.ui.control.Gauge
        PositionGrid       matlab.ui.container.GridLayout
        actualPosValue     matlab.ui.control.Label
        actualPosLabel     matlab.ui.control.Label
        finalPosValue      matlab.ui.control.Label
        finalPosLabel      matlab.ui.control.Label
        initialPosValue    matlab.ui.control.Label
        initialPosLabel    matlab.ui.control.Label
        antennaNameLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        CallingApp
        RootFolder
        EMSatObj

        errorTol     = class.Constants.errorPosTolerance
        antennaName
        LOG          = struct('type', {}, 'time', {}, 'msg',  {})
        UpdateStatus = true
    end
    

    methods (Access = private)
        %-----------------------------------------------------------------%
        function screenStartup(app, targetPos, antennaPos)

            app.antennaNameLabel.Text = antennaPos.Name;
            app.initialPosValue.Text  = sprintf('(%.3fº, %.3fº, %.3fº)', antennaPos.Azimuth, antennaPos.Elevation, antennaPos.Polarization);
            app.finalPosValue.Text    = sprintf('(%.3fº, %.3fº, %.3fº)', targetPos.Azimuth,  targetPos.Elevation,  targetPos.Polarization);
            screenUpdate(app, antennaPos)

            app.AzimuthGauge.Value = antennaPos.Azimuth;
            if targetPos.Azimuth ~= antennaPos.Azimuth
                set(app.AzimuthGauge, 'ScaleColors', [1 1 0],      ...
                                      'ScaleColorLimits', sort([targetPos.Azimuth, antennaPos.Azimuth]))
            end

            app.ElevationGauge.Value = antennaPos.Elevation;
            if targetPos.Elevation ~= antennaPos.Elevation
                set(app.ElevationGauge, 'ScaleColors', [1 1 0],        ...
                                        'ScaleColorLimits', sort([targetPos.Elevation, antennaPos.Elevation]))
            end

            app.PolarizationGauge.Value = antennaPos.Polarization;
            if targetPos.Polarization ~= antennaPos.Polarization
                set(app.PolarizationGauge, 'ScaleColors', [1 1 0],           ...
                                           'ScaleColorLimits', sort([targetPos.Polarization, antennaPos.Polarization]))
            end

            drawnow
        end


        %-----------------------------------------------------------------%
        function screenUpdate(app, antennaPos)

            app.actualPosValue.Text  = sprintf('(%.3fº, %.3fº, %.3fº)', antennaPos.Azimuth, antennaPos.Elevation, antennaPos.Polarization);

            app.Azimuth.Text      = sprintf('%.3fº', antennaPos.Azimuth);
            app.Elevation.Text    = sprintf('%.3fº', antennaPos.Elevation);
            app.Polarization.Text = sprintf('%.3fº', antennaPos.Polarization);


        end


        %-----------------------------------------------------------------%
        function setNewPosition(app, targetPos, antennaPos)

            if abs(targetPos.Azimuth      - antennaPos.Azimuth)      >= app.errorTol && ...
               abs(targetPos.Elevation    - antennaPos.Elevation)    >= app.errorTol && ...
               abs(targetPos.Polarization - antennaPos.Polarization) >= app.errorTol
                targetPos.Name         = antennaPos.Name;
                targetPos.TrackingMode = antennaPos.TrackingMode;
                if isfield(antennaPos, 'Target')
                    targetPos.Target   = antennaPos.Target;
                end
                
                % Apontamento do conjunto antena/LNB para a nova posição
                msgError = app.EMSatObj.AntennaPositionSET(targetPos);

                if ~isempty(msgError)
                    error(msgError)
                    closeFcn(app)
                end
            end
        end


        %-----------------------------------------------------------------%
        function updatePosition(app, targetPos, antennaPos)

            % Posição de referência
            refPos = struct('Azimuth', [], 'Elevation', [], 'Polarization', []);
            refPos = updateReference(app, refPos, antennaPos);

            trackTic = tic;
            while app.UpdateStatus
                if toc(trackTic) > 10
                    if isequal(refPos, antennaPos)
                        layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', 'Não alterado o apontamento da antena mesmo após decorridos 10 segundos...');
                    else
                        refPos = updateReference(app, refPos, antennaPos);
                    end
                    trackTic = tic;
                end

                [newAntennaPos, errorMsg] = app.EMSatObj.AntennaPositionGET(antennaPos.Name);
                Timestamp = datestr(now, 'dd/mm/yyyy HH:MM:ss');

                if isempty(errorMsg)
                    antennaPos = updateReference(app, antennaPos, newAntennaPos);
    
                    app.AzimuthGauge.Value      = antennaPos.Azimuth;
                    app.ElevationGauge.Value    = antennaPos.Elevation;
                    app.PolarizationGauge.Value = antennaPos.Polarization;
                    screenUpdate(app, antennaPos)
    
                    set(app.ErrorLabel, FontColor = 'black', ...
                                        Text = sprintf('%s - Coleta da posição atual do conjunto antena/LNB realizada com sucesso', Timestamp))
    
                    if abs(targetPos.Azimuth      - antennaPos.Azimuth)      < app.errorTol && ...
                       abs(targetPos.Elevation    - antennaPos.Elevation)    < app.errorTol && ...
                       abs(targetPos.Polarization - antennaPos.Polarization) < app.errorTol
                        break
                    end

                else
                    app.LOG(end+1) = struct('type', 'error', 'time', Timestamp, 'msg', errorMsg);
                    set(app.ErrorLabel, FontColor = 'red', ...
                                        Text = sprintf('%s - Registro de erro na coleta da posição atual do conjunto antena/LNB', Timestamp))
                end
                drawnow
            end

            closeFcn(app)
        end


        %-----------------------------------------------------------------%
        function refAntennaPos = updateReference(app, refAntennaPos, antennaPos)

            refAntennaPos.Azimuth      = antennaPos.Azimuth;
            refAntennaPos.Elevation    = antennaPos.Elevation;
            refAntennaPos.Polarization = antennaPos.Polarization;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, targetPos, antennaPos)
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            fcn.winPosition(app)

            app.EMSatObj   = app.CallingApp.EMSatObj;

            screenStartup(app, targetPos, antennaPos)
            setNewPosition(app, targetPos, antennaPos)
            updatePosition(app, targetPos, antennaPos)

        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            delete(app)
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            
            app.UpdateStatus = false;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid = uigridlayout(app.UIFigure);
            app.antennaNameLabel = uilabel(app.Grid);
            app.antennaNameLabel.Text = 'MCL-1';
            app.PositionGrid = uigridlayout(app.Grid);
            app.initialPosLabel = uilabel(app.PositionGrid);
            app.initialPosLabel.Text = 'Posição inicial:';
            app.initialPosValue = uilabel(app.PositionGrid);
            app.initialPosValue.Text = '(0.000º, 0.000º, 0.000º)';
            app.finalPosLabel = uilabel(app.PositionGrid);
            app.finalPosLabel.Text = 'Posição alvo:';
            app.finalPosValue = uilabel(app.PositionGrid);
            app.finalPosValue.Text = '(0.000º, 0.000º, 0.000º)';
            app.actualPosLabel = uilabel(app.PositionGrid);
            app.actualPosLabel.Text = 'Posição atual:';
            app.actualPosValue = uilabel(app.PositionGrid);
            app.actualPosValue.Text = '(0.000º, 0.000º, 0.000º)';
            app.Panel = uipanel(app.Grid);
            app.GridLayout = uigridlayout(app.Panel);
            app.AzimuthGauge = uigauge(app.GridLayout, 'circular');
            app.AzimuthGauge.Limits = [0 360];
            app.AzimuthGauge.MajorTicks = [0 45 90 135 180 225 270 315 360];
            app.AzimuthGauge.MajorTickLabels = {'0', '45', '90', '135', '180', '225', '270', '315', '360'};
            app.PolarizationGauge = uigauge(app.GridLayout, 'circular');
            app.PolarizationGauge.Limits = [0 360];
            app.PolarizationGauge.MajorTicks = [0 45 90 135 180 225 270 315 360];
            app.PolarizationGauge.MajorTickLabels = {'0', '45', '90', '135', '180', '225', '270', '315', '360'};
            app.PolarizationLabel = uilabel(app.GridLayout);
            app.PolarizationLabel.Text = 'POLARIZAÇÃO';
            app.Polarization = uilabel(app.GridLayout);
            app.Polarization.Text = '0.000º';
            app.ElevationLabel = uilabel(app.GridLayout);
            app.ElevationLabel.Text = 'ELEVAÇÃO';
            app.Elevation = uilabel(app.GridLayout);
            app.Elevation.Text = '0.000º';
            app.AzimuthLabel = uilabel(app.GridLayout);
            app.AzimuthLabel.Text = 'AZIMUTE';
            app.Azimuth = uilabel(app.GridLayout);
            app.Azimuth.Text = '0.000º';
            app.ElevationGrid = uigridlayout(app.GridLayout);
            app.ElevationGauge = uigauge(app.ElevationGrid, 'ninetydegree');
            app.ElevationGauge.Limits = [0 90];
            app.ElevationGauge.MajorTicks = [0 22.5 45 67.5 90];
            app.ElevationGauge.MajorTickLabels = {'0', '22.5', '45', '67.5', '90'};
            app.ElevationGauge.MinorTicks = [0 3 6 9 12 15 18 27 30 33 36 39 42 45 48 51 54 57 60 63 72 75 78 81 84 87 90];
            app.ErrorLabel = uilabel(app.Grid);
            app.ErrorLabel.Text = '';
            app.CancelButton = uibutton(app.Grid, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Icon = 'LT_redX.png';
            app.CancelButton.Tooltip = {''};
            app.CancelButton.Text = '';
            app.CancelButton_2 = uibutton(app.Grid, 'push');
            app.CancelButton_2.Icon = 'LT_log.png';
            app.CancelButton_2.Tooltip = {''};
            app.CancelButton_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winTracking(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end