classdef winAppColetaV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        menu_Panel            matlab.ui.container.Panel
        menu_Grid             matlab.ui.container.GridLayout
        menu_Settings         matlab.ui.control.Image
        menu_Position         matlab.ui.control.Image
        menu_searchPath       matlab.ui.control.Image
        menu_userPathGrid     matlab.ui.container.GridLayout
        menu_userPath         matlab.ui.control.DropDown
        menu_Button5          matlab.ui.control.Button
        menu_Separator2       matlab.ui.control.Image
        menu_Button4          matlab.ui.control.StateButton
        menu_Button3          matlab.ui.control.Button
        menu_Button2          matlab.ui.control.Button
        menu_Separator1       matlab.ui.control.Image
        menu_Button1          matlab.ui.control.StateButton
        TabGroup              matlab.ui.container.TabGroup
        Tab1_Home             matlab.ui.container.Tab
        home_Grid             matlab.ui.container.GridLayout
        home_Button5          ccTools.Button
        home_Button4          ccTools.Button
        home_Button3          ccTools.Button
        home_Button2          ccTools.Button
        home_Button1          ccTools.Button
        jsBackDoor            matlab.ui.control.HTML
        Tab2_Task             matlab.ui.container.Tab
        task_Grid             matlab.ui.container.GridLayout
        task_toolGrid         matlab.ui.container.GridLayout
        task_LayoutGrid       matlab.ui.container.GridLayout
        task_RightPanel       matlab.ui.control.Image
        task_LeftPanel        matlab.ui.control.Image
        task_TopPanel         matlab.ui.control.Image
        task_RevisitTime      matlab.ui.control.Label
        task_Status           matlab.ui.control.Label
        task_ControlsGrid     matlab.ui.container.GridLayout
        task_ButtonLOG        matlab.ui.control.Button
        task_Separator2       matlab.ui.control.Image
        task_ButtonDel        matlab.ui.control.Button
        task_ButtonPlay       matlab.ui.control.Button
        task_Separator1       matlab.ui.control.Image
        task_ButtonEdit       matlab.ui.control.Button
        task_ButtonNew        matlab.ui.control.Button
        task_docGrid          matlab.ui.container.GridLayout
        TaskInfo_Panel        matlab.ui.container.GridLayout
        lastGPS_Panel         matlab.ui.container.Panel
        lastGPS_Grid1         matlab.ui.container.GridLayout
        errorCount_img_2      matlab.ui.control.Image
        errorCount_txt_2      matlab.ui.control.Label
        lastGPS_Grid2         matlab.ui.container.GridLayout
        lastGPS_color         matlab.ui.control.Lamp
        lastGPS_text          matlab.ui.control.Label
        lastGPS_label         matlab.ui.control.Label
        lastMask_Panel        matlab.ui.container.Panel
        lastMask_Grid         matlab.ui.container.GridLayout
        lastMask_text         matlab.ui.control.Label
        lastMask_label        matlab.ui.control.Label
        Sweeps_Panel          matlab.ui.container.Panel
        Sweeps_Grid           matlab.ui.container.GridLayout
        errorCount_img        matlab.ui.control.Image
        errorCount_txt        matlab.ui.control.Label
        Sweeps                matlab.ui.control.Label
        Sweeps_Label          matlab.ui.control.Label
        Sweeps_REC            matlab.ui.control.Image
        PlotTool_Grid         matlab.ui.container.GridLayout
        Button_MaskPlot       matlab.ui.control.StateButton
        Button_Layout         matlab.ui.control.Button
        Button_peakExcursion  matlab.ui.control.StateButton
        Button_MaxHold        matlab.ui.control.StateButton
        Button_Average        matlab.ui.control.StateButton
        Button_MinHold        matlab.ui.control.StateButton
        Plot_Panel            matlab.ui.container.Panel
        MetaData_Panel        matlab.ui.container.Panel
        MetaData_Grid         matlab.ui.container.GridLayout
        MetaData              matlab.ui.control.HTML
        Tree                  matlab.ui.container.Tree
        Tree_Label            matlab.ui.control.Label
        Table                 matlab.ui.control.Table
    end

    
    properties (Access = public)    
        RootFolder
        General

        specObj      = class.specClass.empty
        revisitObj

        timerObj_startup
        timerObj_task
        
        taskList

        Flag_running = 0
        Flag_editing = 0
        plotLayout   = 1

        % O MATLAB não renderiza alguns dos componentes de abas (do TabGroup) 
        % não visíveis. E a customização de componentes, usando a lib ccTools, 
        % somente é possível após a sua renderização. Controla-se a aplicação 
        % da customizaçao por meio dessa propriedade jsBackDoorFlag.
        jsBackDoorFlag   = true(2, 1);

        % Janela de progresso já criada no DOM. Dessa forma, controla-se 
        % apenas a sua visibilidade - e tornando desnecessário criá-la a
        % cada chamada (usando uiprogressdlg, por exemplo).
        progressDialog

        %-----------------------------------------------------------------%
        % PLOT
        %-----------------------------------------------------------------%
        axes1
        axes2
        
        line_ClrWrite
        line_MinHold
        line_Average
        line_MaxHold
        peakExcursion
        surface_WFall

        %-----------------------------------------------------------------%
        % COMMUNICATION
        %-----------------------------------------------------------------%
        tcpServer
        
        receiverObj
        gpsObj
        udpPortArray = {}

        EB500Obj
        EMSatObj
        ERMxObj        

        %-----------------------------------------------------------------%
        % AUXILIAR APPS
        %-----------------------------------------------------------------%
        auxiliarWin1 = [] % auxApp.winSettings
        auxiliarWin2 = [] % auxApp.winAddTask
        auxiliarWin3 = [] % auxApp.winEditTaskList
        auxiliarWin4 = [] % auxApp.winEditInstrumentList
        auxiliarWin5 = [] % auxApp.winServerStatus
      % auxiliarWin6 = [] % auxApp.winTracking (MODAL)
    end

    
    methods (Access = private)
        %-----------------------------------------------------------------%
        % STARTUP
        %-----------------------------------------------------------------%
        function startup_timerCreation(app)
            
            % A criação desse timer tem como objetivo garantir uma renderização 
            % mais rápida dos componentes principais da GUI, possibilitando a 
            % visualização da sua tela inicialpelo usuário. Trata-se de aspecto 
            % essencial quando o app é compilado como webapp.

            app.timerObj_startup = timer("ExecutionMode", "fixedSpacing", ...
                                         "StartDelay",    1.5,            ...
                                         "Period",        .1,             ...
                                         "TimerFcn",      @(~,~)app.startup_timerFcn);
            start(app.timerObj_startup)
        end


        %-----------------------------------------------------------------%
        function startup_timerFcn(app)
            if ccTools.fcn.UIFigureRenderStatus(app.UIFigure)
                stop(app.timerObj_startup)

                % Customiza as aspectos estéticos de alguns dos componentes da GUI 
                % (diretamente em JS).
                jsBackDoor_Customizations(app)

                % Trecho migrado de "startupFcn" para "startup_timerFcn",
                % evitando que seja executado antes da renderização do app.
                startup_Config(app)
                RegularTask_timerCreation(app)
                if app.General.startupInfo
                    startup_specObjRead(app)
                end

                % Define tamanho mínimo da GUI...
                ccTools.compCustomization(app.UIFigure, 'windowMinSize', class.Constants.windowMinSize);
                app.UIFigure.Resize = "on";                
                drawnow
            end
        end


        %-----------------------------------------------------------------%
        function startup_AxesCreation(app)
            t = tiledlayout(app.Plot_Panel, 3, 1, "Padding", "tight", "TileSpacing", "tight");

            % app.axes1
            app.axes1 = uiaxes(t);
            app.axes1.Layout.Tile = 1;
            set(app.axes1, Color=[0 0 0], FontName='Helvetica', FontSize=9, FontSmoothing='on',     ...
                                          XGrid='on', XMinorGrid='on', YGrid='on', YMinorGrid='on', ...
                                          GridAlpha=.25, GridColor=[.94,.94,.94], MinorGridAlpha=.2, MinorGridColor=[.94,.94,.94], XTickLabel={}, ...
                                          Interactions=[])
            ylabel(app.axes1, 'Nível (dB)')

            % app.axes2
            app.axes2 = uiaxes(t);
            app.axes2.Layout.Tile = 2;
            app.axes2.Layout.TileSpan = [2,1];
            set(app.axes2, Color=[.98,.98,.98], FontName='Helvetica', FontSize=9, FontSmoothing='on', ...
                                                Interactions=[])
            xlabel(app.axes2, 'Frequência (MHz)')
            ylabel(app.axes2, 'Amostras')

            % Others aspects...
            hold(app.axes1, 'on')
            hold(app.axes2, 'on')

            Interactions = class.Constants.Interactions;
            plotFcn.axesInteractions(app.axes1, Interactions)
            plotFcn.axesInteractions(app.axes2, Interactions)

            linkaxes([app.axes1, app.axes2], 'x')
            task_ButtonPushed_plotLayout(app)
        end


        %-----------------------------------------------------------------%
        function startup_Config(app)
            % app.General
            app.General = jsondecode(fileread(fullfile(app.RootFolder, 'Settings', 'GeneralSettings.json')));
            eval(sprintf('opengl %s', app.General.openGL))

            userPaths = class.Constants.userPaths;
            if ~isempty(app.General.userPath)
                userPaths(end+1) = {app.General.userPath};
            end

            for ii = numel(userPaths):-1:1
                if ~isfolder(userPaths{ii})
                    userPaths(ii) = [];
                end
            end
            set(app.menu_userPath, 'Items', userPaths, 'Value', userPaths{end})
            app.General.userPath = app.menu_userPath.Value;
            
            app.General.stationInfo.Computer = getenv('COMPUTERNAME');
            app.General.version  = startup_Versions(app);


            % app.taskList
            [app.taskList, msgError] =  class.taskList.file2raw(fullfile(app.RootFolder, 'Settings', 'taskList.json'), 'winAppColetaV2');
            if ~isempty(msgError)
                appUtil.modalWindow(app.UIFigure, 'error', msgError);
            end


            % Others...
            app.receiverObj = class.ReceiverLib(app.RootFolder);
            app.gpsObj      = class.GPSLib(app.RootFolder);            
            app.EB500Obj    = class.EB500Lib(app.RootFolder);
            app.EMSatObj    = class.EMSatLib(app.RootFolder);
            app.ERMxObj     = class.ERMxLib(app.RootFolder);            

            if app.General.tcpServer.Status
                try
                    app.tcpServer = class.tcpServerLib(app);
                catch
                    app.tcpServer = [];
                end
            end
        end


        %-----------------------------------------------------------------%
        function appVersion = startup_Versions(app)
            appVersion = struct('OS', '', 'Matlab', '', 'OpenGL', '', class.Constants.appName, struct('Release', class.Constants.appRelease, ...
                                                                                                      'Version', class.Constants.appVersion, ...
                                                                                                      'RootFolder', app.RootFolder));
        
            % OS
            [~, WindowsVersion] = system('ver');
            appVersion.OS = strtrim(WindowsVersion);
        
            % MATLAB    
            [matVersion, matReleaseDate] = version;    
            matProducts = struct2table(ver);
        
            appVersion.Matlab = struct('Version',  sprintf('%s (Release date: %s)', matVersion, matReleaseDate), ...
                                       'Path',     matlabroot,                                                   ...
                                       'Products', strjoin(matProducts.Name + " v. " + matProducts.Version, ', '));
        
            % OPENGL
            graphRender = opengl('data');
            appVersion.OpenGL = rmfield(graphRender, {'MaxTextureSize', 'Visual', 'SupportsGraphicsSmoothing', 'SupportsDepthPeelTransparency', 'SupportsAlignVertexCenters', 'Extensions', 'MaxFrameBufferSize'});            
        end


        %-----------------------------------------------------------------%
        function startup_specObjRead(app)

            if isfile(fullfile(app.RootFolder, 'Settings', 'startupInfo.mat'))
                app.progressDialog.Visible = 'visible';

                load(fullfile(app.RootFolder, 'Settings', 'startupInfo.mat'), 'SpecObj');

                % É possível que o MATLAB não consiga instancionar o objeto
                % "class.specClass", lendo-o como "uint32", o que inviabiliza 
                % o aproveitamento da informação salva...

                % Warning: Variable 'SpecObj' originally saved as a class.specClass cannot be instantiated as an object and will be read in as a uint32.

                if exist('SpecObj', 'var') && isa(SpecObj, 'class.specClass') && ~isempty(SpecObj)
                    for ii = 1:numel(SpecObj)
                        SpecObj(ii) = startup_specObjRead_Receiver(app, SpecObj(ii));
                        SpecObj(ii) = startup_specObjRead_Streaming(app, SpecObj(ii));
                        SpecObj(ii) = startup_specObjRead_GPS(app, SpecObj(ii));

                        if ismember(SpecObj(ii).Status, {'Na fila', 'Em andamento'})
                            SpecObj(ii).Status = 'Erro';
                        end
                    end

                    app.specObj = SpecObj;                    

                    layout.tableBuilding(app, 1)
                    task_TreeSelectionChanged(app)

                    % Ida ao modo de "Execução das tarefas da monitoração"
                    % de forma programática:
                    app.menu_Button4.Value = 1;
                    menu_StateButtonPushed(app, struct('Source', app.menu_Button4))
                end

                app.progressDialog.Visible = 'hidden';
            end
        end


        %-----------------------------------------------------------------%
        function [SpecObj, msgError] = startup_specObjRead_Receiver(app, SpecObj)

            % Função funcionalmente idêntica à fcn.ConnectivityTest_Receiver.
            % A "duplicação" garante que seja usado a informação constante
            % no objeto SpecObj, ao invés da informação constante no arquivo 
            % "instrumentList.json", que pode ter sido editado.

            idx1 = find(strcmp(app.receiverObj.Config.Name, SpecObj.Task.Receiver.Selection.Name{1}), 1);
            instrSelected = struct('Type',       SpecObj.Task.Receiver.Selection.Type{1}, ...
                                   'Tag',        app.receiverObj.Config.Tag{idx1},        ...
                                   'Parameters', jsondecode(SpecObj.Task.Receiver.Selection.Parameters{1}));
            
            [idx2, msgError] = app.receiverObj.Connect(instrSelected);            
            
            if isempty(msgError)
                SpecObj.Task.Receiver.Handle = app.receiverObj.Table.Handle{idx2};
                SpecObj.hReceiver            = SpecObj.Task.Receiver.Handle;
            end
        end


        %-----------------------------------------------------------------%
        function SpecObj = startup_specObjRead_Streaming(app, SpecObj)

            receiverName = SpecObj.Task.Receiver.Selection.Name{1};
            taskType     = SpecObj.Task.Type;

            idx1 = SelectedReceiverIndex(app, receiverName, taskType);
            if ismember(app.receiverObj.Config.connectFlag(idx1), [2, 3])
                [app.udpPortArray, idx2] = fcn.udpSockets(app.udpPortArray, app.EB500Obj.udpPort);
                if ~isempty(idx2)
                    SpecObj.Task.Streaming.Handle = app.udpPortArray{idx2};
                    SpecObj.hStreaming            = SpecObj.Task.Streaming.Handle;
                end
            end
        end


        %-----------------------------------------------------------------%
        function [SpecObj, msgError] = startup_specObjRead_GPS(app, SpecObj)

            % Função funcionalmente idêntica à fcn.ConnectivityTest_GPS.
            % A "duplicação" garante que seja usado a informação constante
            % no objeto SpecObj, ao invés da informação constante no arquivo 
            % "instrumentList.json", que pode ter sido editado.

            msgError = '';

            if ~isempty(SpecObj.Task.GPS.Selection)
                instrSelected = struct('Type',       SpecObj.Task.GPS.Selection.Type{1}, ...
                                       'Parameters', jsondecode(SpecObj.Task.GPS.Selection.Parameters{1}));

                [idx2, msgError] = app.gpsObj.Connect(instrSelected);
                if isempty(msgError)
                    SpecObj.Task.GPS.Handle = app.gpsObj.Table.Handle{idx2};
                    SpecObj.GPS             = SpecObj.Task.GPS.Handle;
                end
            end
        end


        %-----------------------------------------------------------------%
        function jsBackDoor_Initialization(app)
            app.jsBackDoor.HTMLSource = ccTools.fcn.jsBackDoorHTMLSource;
        end


        %-----------------------------------------------------------------%
        function jsBackDoor_Customizations(app)
            % Cria um ProgressDialog...
            app.progressDialog = ccTools.ProgressDialog(app.jsBackDoor);

            % Customizações dos componentes...
            sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-theme-light',                                                   ...
                                                                                   'classAttributes', ['--mw-backgroundColor-dataWidget-selected: rgb(180 222 255 / 45%); ' ...
                                                                                                       '--mw-backgroundColor-selected: rgb(180 222 255 / 45%); '            ...
                                                                                                       '--mw-backgroundColor-selectedFocus: rgb(180 222 255 / 45%);']));

            sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-default-header-cell', ...
                                                                                   'classAttributes',  'font-size: 10px; white-space: pre-wrap; margin-bottom: 5px;'));
        end


        %-----------------------------------------------------------------%
        function idx = SelectedReceiverIndex(app, receiverName, taskType)
            idx = find(strcmp(app.receiverObj.Config.Name, receiverName));
            if numel(idx) > 1
                connectFlagList = app.receiverObj.Config.connectFlag(idx);
                if contains(taskType, 'Drive-test (Level+Azimuth)')
                    idx = idx(connectFlagList == 3);
                else
                    idx = idx(connectFlagList ~= 3);
                end
                idx = idx(1);
            end
        end


        %-----------------------------------------------------------------%
        % TIMER 
        %-----------------------------------------------------------------%
        function RegularTask_timerCreation(app)
            app.timerObj_task = timer("ExecutionMode", "fixedRate", ...
                                 "Period",        10,          ...
                                 "TimerFcn",      @(~,~)app.RegularTask_timerFcn);
            start(app.timerObj_task)
        end


        %-----------------------------------------------------------------%
        function RegularTask_timerFcn(app)
            if ~app.Flag_running
                Flag = false;
                for ii = 1:numel(app.specObj)
                    if RegularTask_StatusTaskCheck(app, ii, '')
                        Flag = true;
                        break
                    end
                end

                if Flag
                    RegularTask_MainLoop(app)
                end
            end

            if numel(app.specObj) ~= height(app.Table.Data)
                layout.tableBuilding(app, app.Table.Selection)
                task_TreeSelectionChanged(app)
            end
        end


        %-----------------------------------------------------------------%
        % REGULAR TASK
        %-----------------------------------------------------------------%
        function RegularTask_specObjSave(app)

            % Ao salvar "app.specObj" em um arquivo .MAT, reabrindo-o
            % posteriormente, os objetos de comunicação (tcpclient, por
            % exemplo) não retém o valor da propriedade "UserData".
            %
            % Por essa razão, esses objetos não serão salvos, devendo ser
            % recriados na inicialização do app.

            SpecObj = copy(app.specObj);
            
            for ii = 1:numel(SpecObj)
                SpecObj(ii).hReceiver  = [];
                SpecObj(ii).hStreaming = [];
                SpecObj(ii).hGPS       = [];

                SpecObj(ii).Task.Receiver.Handle  = [];
                SpecObj(ii).Task.Streaming.Handle = [];
                SpecObj(ii).Task.GPS.Handle       = [];
            end

            save(fullfile(app.RootFolder, 'Settings', 'startupInfo.mat'), 'SpecObj')
        end


        %-----------------------------------------------------------------%
        function Flag = RegularTask_StatusTaskCheck(app, idx, evtName)
            % Função responsável por trocar o estado das tarefas, de "Na
            % fila" para "Em andamento", "Em andamento" para "Cancelada",
            % "Em andamento" para "Erro" e por aí vai...
            %
            % Lembrando que o estado de uma nova tarefa é "Na fila", exceto
            % quando ocorre algum erro no processo de criação (decorrente 
            % de uma configuração de um parâmetro não aceito pelo receptor,
            % por exemplo). Nesse caso, o estado será "Erro".
            %
            % Caso não exista alguma tarefa em execução, o app.Flag_running
            % será igual a 0, e o app.timerObj estará ativo, o que o fará
            % avaliar a cada minuto o estado de todas as tarefas, nesta 
            % função, de forma que:
            % (a) Seja iniciada uma tarefa no estado "Na fila";
            % (b) Seja realizada uma nova tentativa de iniciar uma tarefa 
            %     no estado "Erro" (o que ocorrerá a cada 15 minutos).
            
            Timestamp = datetime('now');

            Flag = false;
            initialStatus = app.specObj(idx).Status;
            
            switch app.specObj(idx).Status
                case 'Em andamento'
                    if app.specObj(idx).Observation.EndTime < Timestamp || ismember(evtName, {'DeleteButtonPushed', 'ErrorTrigger'})
                        Flag = true;

                        if app.specObj(idx).Observation.EndTime < Timestamp
                            app.specObj(idx).Status = 'Concluída';
                        else
                            switch evtName
                                case 'DeleteButtonPushed'
                                    app.specObj(idx).Status = 'Cancelada';
                                case 'ErrorTrigger'
                                    app.specObj(idx).Status = 'Erro';
                            end
                        end
                        
                        app.specObj(idx).hReceiver.UserData.nTasks = app.specObj(idx).hReceiver.UserData.nTasks-1;
                        app.specObj(idx).LOG(end+1) = struct('type', 'task', 'time', char(Timestamp), 'msg', sprintf('Alterado o estado da tarefa: Em andamento → %s.', app.specObj(idx).Status));

                        for ii = 1:numel(app.specObj(idx).Band)
                            app.specObj(idx) = class.RFlookBinLib.CloseFile(app.specObj(idx), ii);
                            app.specObj(idx).Band(ii).Status = false;
                        end

                    else
                        if strcmp(app.specObj(idx).Task.Script.Observation.Type, 'Samples')
                            tempFlag = [];                            
                            for ii = 1:numel(app.specObj(idx).Band)
                                if app.specObj(idx).Band(ii).Status
                                    if app.specObj(idx).Band(ii).nSweeps == app.specObj(idx).Task.Script.Band(ii).instrObservationSamples
                                        app.specObj(idx) = class.RFlookBinLib.CloseFile(app.specObj(idx), ii);
                                        app.specObj(idx).Band(ii).Status = false;
                                        tempFlag(end+1) = true;

                                    else
                                        tempFlag(end+1) = false;
                                    end
                                end
                            end

                            if all(tempFlag)
                                Flag = true;

                                app.specObj(idx).Status = 'Concluída';
                                app.specObj(idx).hReceiver.UserData.nTasks = app.specObj(idx).hReceiver.UserData.nTasks-1;
                                app.specObj(idx).Observation.EndTime = Timestamp;
                                app.specObj(idx).LOG(end+1) = struct('type', 'task', 'time', char(Timestamp), 'msg', sprintf('Alterado o estado da tarefa: Em andamento → %s.', app.specObj(idx).Status));

                            elseif any(tempFlag)
                                Flag = true;
                            end
                        end
                    end

                case {'Na fila', 'Erro'}
                    if strcmp(app.specObj(idx).Status, 'Erro') 
                        if isnat(app.specObj(idx).Observation.StartUp)
                            app.specObj(idx).Observation.StartUp = Timestamp;
                        end

                        StartUp = app.specObj(idx).Observation.StartUp;
                        if isequal([year(Timestamp), month(Timestamp), day(Timestamp), hour(Timestamp), minute(Timestamp)], ...
                                [year(StartUp), month(StartUp), day(StartUp), hour(StartUp), minute(StartUp)])
                            return
                        end
                    end

                    if app.specObj(idx).Observation.BeginTime < Timestamp
                        switch app.specObj(idx).Task.Script.Observation.Type
                            case {'Duration', 'Time'}
                                if isnat(app.specObj(idx).Observation.EndTime) || (app.specObj(idx).Observation.EndTime > Timestamp)
                                    Flag = true;
                                end

                            case 'Samples'
                                Flag = true;
                        end
                    end

                    if Flag
                        try
                            if strcmp(app.timerObj_task.Running, 'on')
                                stop(app.timerObj_task)
                            end
                            RegularTask_StartUp(app, idx);

                            app.specObj(idx).Status = 'Em andamento';
                            app.specObj(idx).hReceiver.UserData.nTasks   = app.specObj(idx).hReceiver.UserData.nTasks+1;
                            app.specObj(idx).hReceiver.UserData.SyncMode = app.specObj(idx).Task.Receiver.Sync;
                            app.specObj(idx).LOG(end+1) = struct('type', 'task', 'time', char(Timestamp), 'msg', 'Iniciada a execução da tarefa.');

                        catch ME
                            if strcmp(app.timerObj_task.Running, 'off') && ~app.Flag_running
                                start(app.timerObj_task)
                            end
                            app.specObj(idx).Status = 'Erro';
                            app.specObj(idx).LOG(end+1) = struct('type', 'error', 'time', char(Timestamp), 'msg', getReport(ME));

                            Flag = false;
                        end
                    end
            end

            if Flag
                layout.tableBuilding(app, app.Table.Selection)
                task_TreeSelectionChanged(app)
            end

            if ~strcmp(initialStatus, app.specObj(idx).Status)
                RegularTask_specObjSave(app)
            end
        end


        %-----------------------------------------------------------------%
        function RegularTask_RestartStatus(app, idx, nSweepsFlag)

            for ii = 1:numel(app.specObj(idx).Band)
                app.specObj(idx).Band(ii).SyncModeRef   = -1;
                app.specObj(idx).Band(ii).LastTimeStamp = [];
                app.specObj(idx).Band(ii).Status        = true;

                if nSweepsFlag
                    app.specObj(idx).Band(ii).nSweeps   = 0;
                end
            end
        end


        %-----------------------------------------------------------------%
        % Notas sobre a interface TCPCLIENT:
        % (a) Não existe uma função que retorna os objetos TCPCLIENT 
        %     (como instrfind p/ os objetos TCPIP). 
        % (b) Algumas chamadas a um objeto não mais válido (decorrente 
        %     de uma perda de conectividade, por exemplo) apresentam a 
        %     mensagem de erro na janela de comandos do Matlab, mesmo 
        %     "protegidos" num bloco try/catch. A execução não para, 
        %     mas imprimir a mensagem na janela de comandos é um 
        %     comportamento não esperado.
        % (c) A propriedade que indica que o objeto não mais está conectado 
        %     ao instrumento é privada, sendo acessível usando struct.
        % (d) A propriedade "UserData" se perde quando da reinicialização
        %     do app, sendo necessária criá-la novamente.
        %-----------------------------------------------------------------%


        %-----------------------------------------------------------------%
        function RegularTask_StartUp(app, idx)
            Task = app.specObj(idx).Task;
            
            % RECEIVER
            msgError = app.receiverObj.ReconnectAttempt(app.specObj(idx).hReceiver.UserData.instrSelected, ...
                                                        app.specObj(idx).Task.Receiver.Config.connectFlag, ...
                                                        app.specObj(idx).Task.Receiver.Config.StartUp{1},  ...
                                                        app.specObj(idx).Band(1).SpecificSCPI);
            if ~isempty(msgError)
                error(msgError)
            end
            hReceiver = app.specObj(idx).hReceiver;

            % STREAMING
            if isempty(app.specObj(idx).hStreaming)
                if ismember(Task.Receiver.Config.connectFlag, [2, 3])
                    app.specObj(idx) = startup_specObjRead_Streaming(app, app.specObj(idx));
                end
            else
                if contains(app.specObj(idx).IDN, 'EB500')                 && ...
                        ~contains(Task.Type, 'Drive-test (Level+Azimuth)') &&...
                        isempty(app.specObj(idx).Band(1).Datagrams)

                    hStreaming = app.specObj(idx).hStreaming;
                    app.specObj(idx) = class.EB500Lib.DatagramRead_PSCAN_PreTask(app.EB500Obj, app.specObj(idx), hReceiver, hStreaming);
                end
            end

            % GPS
            if isempty(app.specObj(idx).hGPS)
                if ~isempty(Task.GPS.Selection)
                    [app.specObj(idx), msgError] = startup_specObjRead_GPS(app, app.specObj(idx));
                    if ~isempty(msgError)
                        error(msgError)
                    end
                end
            end

            % ANTENNA TRACKING (EMSat)
            if strcmp(Task.Antenna.Switch.Name, 'EMSat')
                fcn.antennaTracking(app, Task.Antenna.MetaData, app.progressDialog);
            end

            % MASK, FILE & WATERFALL MATRIX
            baseName = sprintf('appColeta_%s', datestr(now, 'yymmdd_THHMMSS'));
            for ii = 1:numel(app.specObj(idx).Band)
                ID = Task.Script.Band(ii).ID;

                % ANTENNA SWITCH & ACU
                % Esse trecho do código consiste na tentativa de obter a posição 
                % da antena, inserindo-a no arquivo binário e apresentando no 
                % painel de metadados. 
                % 
                % Erros retornáveis:
                % - Caso não tenha sido desabilitado o Polling/Bus da ACU 
                % no Compass.
                % - Caso a ACU não esteja acessível ('MCL-3' e 'MCC-1' ainda
                % não possuem; e 'MKA-1' ainda não é controlável por falta
                % de conectividade de rede (o app não "enxerga" a ACU).
                %
                % Os erros não travam a execução do código pois a antena
                % pode ter sido apontada manualmente ou automaticamente - este
                % último poderia ter sido conduzido no momento de criação da 
                % tarefa (e posteriormente reabilitado o controle da ACU pelo 
                % Compass.
                if strcmp(Task.Antenna.Switch.Name, 'EMSat')
                    antennaName = extractBefore(Task.Script.Band(ii).instrAntenna, ' ');
                    [antennaPos, errorMsg] = app.EMSatObj.AntennaPositionGET(antennaName);
                    app.specObj(idx).Band(ii).Antenna.Position = jsonencode(antennaPos);

                    if ~isempty(errorMsg)
                        app.specObj(idx).LOG(end+1) = struct('type', 'startup', 'time', datestr(now), 'msg', sprintf('ID: %.0f\n%s ACU - %s', ID, antennaName, errorMsg));
                    end
                end

                % MASK
                app.specObj(idx).Band(ii).Mask = [];
                if contains(Task.Type, 'Rompimento de Máscara Espectral') && Task.Script.Band(ii).MaskTrigger.Status
                    maskInfo  = class.maskLib.FileRead(Task.MaskFile);
                    maskArray = class.maskLib.ArrayConstructor(maskInfo, Task.Script.Band(ii));

                    FindPeaks = Task.Script.Band(ii).MaskTrigger.FindPeaks;
                    if isempty(FindPeaks)
                        FindPeaks = class.Constants.FindPeaks;
                    end

                    app.specObj(idx).Band(ii).Mask = struct('Table', maskInfo.Table, 'Array', maskArray, 'Validations', 0, ...
                                                            'BrokenArray', zeros(1, Task.Script.Band(ii).instrDataPoints), ...
                                                            'BrokenCount', 0, 'Peaks', '', 'TimeStamp', NaT, 'FindPeaks', FindPeaks);
                    app.specObj(idx).LOG(end+1)    = struct('type', 'mask', 'time', datestr(now), 'msg', sprintf('ID %.0f\n%s', ID, jsonencode(maskInfo.Table)));
                end

                % FILE
                app.specObj(idx).Band(ii).File = struct('Fileversion', class.Constants.fileVersion,     ...
                                                        'Basename', sprintf('%s_ID%.0f', baseName, ID), ...
                                                        'Filecount', 0, 'WritedSamples', 0, 'CurrentFile', []);

                [app.specObj(idx).Band(ii).File.Filecount, ...
                    app.specObj(idx).Band(ii).File.CurrentFile] = class.RFlookBinLib.OpenFile(app.specObj(idx), ii, app.General.userPath);

                logMsg = sprintf(['ID: %.0f\n'             ...
                                  'scpiSet_Config: "%s"\n' ...
                                  'scpiSet_Att: "%s"\n'    ...
                                  'rawMetaData: "%s"\n'    ...
                                  'Filename (base): %s'], ID,                                               ...
                                                          app.specObj(idx).Band(ii).SpecificSCPI.configSET, ...
                                                          app.specObj(idx).Band(ii).SpecificSCPI.attSET,    ...
                                                          app.specObj(idx).Band(ii).rawMetaData,            ...
                                                          app.specObj(idx).Band(ii).File.Basename);                
                app.specObj(idx).LOG(end+1) = struct('type', 'startup', 'time', datestr(now), 'msg', logMsg);


                % WATERFALL MATRIX
                DataPoints     = Task.Script.Band(ii).instrDataPoints;
                WaterfallDepth = app.General.Waterfall.Depth;   
                if strcmp(Task.Script.Observation.Type, 'Samples')
                    WaterfallDepth = min([WaterfallDepth, Task.Script.Band(ii).instrObservationSamples]);
                end                

                switch Task.Script.Band(ii).instrLevelUnit
                    case 'dBm';            refLevel = -120;
                    case {'dBµV', 'dBμV'}; refLevel = -13;
                end                

                app.specObj(idx).Band(ii).Waterfall = struct('idx', 0, 'Depth', WaterfallDepth, 'Matrix', -1000 .* ones(WaterfallDepth, DataPoints, 'single'));
            end

            RegularTask_RestartStatus(app, idx, 0)
        end


        %-----------------------------------------------------------------%
        function RegularTask_MainLoop(app)
            app.Flag_running = 1;
            app.Flag_editing = 1;

            stop(app.timerObj_task)

            while app.Flag_running
                if app.Flag_editing
                    app.revisitObj = fcn.RevisitFactors(app.specObj);
                    app.MetaData.HTMLSource = layout.metadataPanel(app);

                    if isempty(app.revisitObj.GlobalRevisitTime)
                        app.Flag_running = 0;
                        break
                    end
                    
                    nn = 0;
                    app.Flag_editing = 0;
                end

                sweepTic = tic;
                for ii = 1:numel(app.specObj)
                    if RegularTask_StatusTaskCheck(app, ii, '')
                        app.Flag_editing = 1;
                        break
                    end

                    if ~strcmp(app.specObj(ii).Status, 'Em andamento')
                        continue
                    end

                    regularTask = ~contains(app.specObj(ii).Task.Type, 'PRÉVIA');
                    
                    hReceiver   = app.specObj(ii).hReceiver;
                    hStreaming  = app.specObj(ii).hStreaming;
                    hGPS        = app.specObj(ii).hGPS;

                    configMode  = true;

                    nBands = numel(app.specObj(ii).Band);    
                    for jj = 0:nBands
                        if mod(nn, app.revisitObj.Band(ii).RevisitFactors(jj+1)) || app.revisitObj.Band(ii).RevisitFactors(jj+1) == -1
                            continue
                        end
                        newTimeStamp = datetime('now');
    
                        if jj == 0
                            % A atualização das coordenadas geográficas do
                            % ponto de monitoração não precisa ser feita para 
                            % a tarefa "Drive-test (Level+Azimuth)" porque essa 
                            % tarefa já possui, no seu datagrama, a informação 
                            % das coordenadas.

                            if app.specObj(ii).Task.Receiver.Config.connectFlag ~= 3
                                RegularTask_gpsData(app, ii, hReceiver, hGPS, newTimeStamp);
                            end

                        else
                            app.specObj(ii) = class.RFlookBinLib.CheckFile(app.specObj(ii), jj, app.General.userPath);
                            
                            try
                                % ANTENNA SWITCH (IF APPLICABLE)
                                RegularTask_AntennaSwitch(app, ii, jj)

                                % RECEIVER RECONFIGURATION (IF APPLICABLE)
                                if (nBands > 1) || (hReceiver.UserData.nTasks > 1)
                                    if configMode
                                        if ismember(app.specObj(ii).Task.Receiver.Config.connectFlag, [2, 3])                                            
                                            class.EB500Lib.OperationMode(hReceiver, app.specObj(ii).Task.Receiver.Config.connectFlag)
                                        end
                                        configMode = false;
                                    end

                                    RegularTask_ConfigBand(app, ii, jj, hReceiver)
                                end

                                attFactor = -1;
                                if ~isempty(app.specObj(ii).GeneralSCPI.attGET)
                                % Bloco try/catch protege eventual erro, o que não causará dano à 
                                % monitoração em si por se tratar de informação não essencial.
                                    try
                                        attFactor = str2double(fcn.WriteRead(hReceiver, app.specObj(ii).GeneralSCPI.attGET));
                                    catch
                                    end
                                end

                                % maskTrigger: Variável local que registra se foi evidenciado rompimento da máscara espectral.
                                maskTrigger = 0;

                                if isempty(app.specObj(ii).Band(jj).Mask)
                                    % SINGLE TRACE
                                    newArray = RegularTask_specData(app, ii, jj, hReceiver, hStreaming, newTimeStamp);
                                    app.specObj(ii).Band(jj).nSweeps = app.specObj(ii).Band(jj).nSweeps+1;
                                
                                else
                                    % BURST OF TRACES
                                    nSweeps  = app.specObj(ii).Band(jj).Mask.FindPeaks.nSweeps;
                                    newArray = zeros(nSweeps, app.specObj(ii).Band(jj).DataPoints, 'single');                                    
                                    for kk = 1:nSweeps
                                        newArray(kk,:) = RegularTask_specData(app, ii, jj, hReceiver, hStreaming, newTimeStamp);
                                        app.specObj(ii).Band(jj).nSweeps = app.specObj(ii).Band(jj).nSweeps+1;
                                    end
                                    smoothedArray = mean(newArray, 1);

                                    % METADATA UPDATE
                                    app.specObj(ii).Band(jj).Mask.Validations = app.specObj(ii).Band(jj).Mask.Validations + 1;

                                    % MASK BROKEN ANALISYS                                    
                                    validationArray = (smoothedArray - app.specObj(ii).Band(jj).Mask.Array) > 0;
                                    if any(validationArray)
                                        app.specObj(ii).Band(jj).Mask.BrokenArray = app.specObj(ii).Band(jj).Mask.BrokenArray + validationArray;

                                        peaksTable = fcn.FindPeaks(app.specObj(ii), jj, smoothedArray, validationArray);
                                        if ~isempty(peaksTable)
                                            app.specObj(ii).Band(jj).Mask.BrokenCount = app.specObj(ii).Band(jj).Mask.BrokenCount + 1;
                                            app.specObj(ii).Band(jj).Mask.Peaks       = peaksTable;
                                            app.specObj(ii).Band(jj).Mask.TimeStamp   = newTimeStamp;

                                            if regularTask
                                                writematrix(jsonencode(rmfield(app.specObj(ii).Band(jj).Mask, {'Table', 'Array', 'Validations', 'BrokenArray', 'FindPeaks'})), ...
                                                    replace(app.specObj(ii).Band(jj).File.CurrentFile.FullPath, {'~', '.bin'}, {'', '.txt'}), "QuoteStrings", "none", "WriteMode", "append")
                                            end

                                            maskTrigger = 1;
                                        end
                                    end

                                    newArray = newArray(end,:);
                                end
                                
                                app.specObj(ii).Error(1,2:4) = {NaT, NaT, 0};

                                % WATERFALL MATRIX
                                idx = app.specObj(ii).Band(jj).Waterfall.idx + 1;
                                if idx > app.specObj(ii).Band(jj).Waterfall.Depth; idx = 1;
                                end

                                app.specObj(ii).Band(jj).Waterfall.idx = idx;
                                app.specObj(ii).Band(jj).Waterfall.Matrix(idx,:) = newArray(:,:,1);

                                % ESTIMATED REVISIT TIME
                                if isempty(app.specObj(ii).Band(jj).LastTimeStamp)
                                    app.specObj(ii).Band(jj).RevisitTime = app.revisitObj.GlobalRevisitTime * app.revisitObj.Band(ii).RevisitFactors(jj+1);
                                else
                                    app.specObj(ii).Band(jj).RevisitTime = ((app.General.Integration.SampleTime-1)*app.specObj(ii).Band(jj).RevisitTime + seconds(newTimeStamp-app.specObj(ii).Band(jj).LastTimeStamp))/app.General.Integration.SampleTime;
                                end
                                app.specObj(ii).Band(jj).LastTimeStamp = newTimeStamp;

                                % PLOT, WRITEDSAMPLES & MASKINFO (IF APPLICABLE)
                                if app.Table.Selection == ii
                                    layout.errorCount(app, ii)

                                    if app.Tree.SelectedNodes.NodeData == jj
                                        plotFcn.Draw(app, ii, jj)
                                        if ~isempty(app.specObj(ii).Band(jj).Mask)
                                            layout.lastMaskValidation(app, maskTrigger, ii, jj)
                                        end
                                        app.task_RevisitTime.Text = sprintf('%d varreduras\n%.3f seg', app.specObj(ii).Band(jj).nSweeps, app.specObj(ii).Band(jj).RevisitTime);
                                    end
                                end

                                % FILE
                                if regularTask && (isempty(app.specObj(ii).Band(jj).Mask) || ismember(app.specObj(ii).Task.Script.Band(jj).MaskTrigger.Status, [0, 3]) || ((app.specObj(ii).Task.Script.Band(jj).MaskTrigger.Status == 2) && maskTrigger))
                                    class.RFlookBinLib.EditFile(app.specObj(ii), jj, newArray, attFactor, newTimeStamp)
                                    app.specObj(ii).Band(jj).File.WritedSamples = app.specObj(ii).Band(jj).File.WritedSamples + 1;

                                    if (app.Table.Selection == ii) && (app.Tree.SelectedNodes.NodeData == jj)
                                        app.Sweeps.Text = string(app.specObj(ii).Band(jj).File.WritedSamples);
                                    end                                    
                                end

                                if (app.Table.Selection == ii) && (app.Tree.SelectedNodes.NodeData == jj)
                                    drawnow
                                end
    
                            catch ME
                                % O controle de erro do GPS se dá na função "RegularTask_gpsData".
                                % 
                                % O controle de erro do RECEPTOR se dá aqui, neste trecho da função 
                                % "RegularTask_MainLoop".
                                %
                                % O app tentará reativar a conexão toda vez que o contador de
                                % erro atingir um múltiplo de "class.Constants.errorCountTrigger".
                                % E, além disso, caso ultrapassado o tempo (em segundos) definido 
                                % em "class.Constants.errorTimeTrigger", o app trocará o estado da 
                                % tarefa de "Em andamento" → "Erro".

                                if ME.message == "If you specify a message identifier argument, you must specify the message text argument."
                                    pause(1)
                                end

                                app.specObj(ii).LOG(end+1) = struct('type', 'error (RECEIVER)', 'time', char(newTimeStamp), 'msg', ME.message);
                                RegularTask_errorHandle(app, 'Receiver', ii, newTimeStamp)

                                if app.Table.Selection == ii
                                    layout.errorCount(app, ii)
                                    drawnow
                                end
                                beep

                                msgError = app.receiverObj.ReconnectAttempt(app.specObj(ii).hReceiver.UserData.instrSelected, ...
                                                                            app.specObj(ii).Task.Receiver.Config.connectFlag, ...
                                                                            app.specObj(ii).Task.Receiver.Config.StartUp{1},  ...
                                                                            app.specObj(ii).Band(jj).SpecificSCPI);
                                if ~isempty(msgError)
                                    RegularTask_StatusTaskCheck(app, ii, 'ErrorTrigger');
                                    break
                                end
                            end
                        end
                    end
                end

                nn = nn+1;
                pause(max(app.revisitObj.GlobalRevisitTime-toc(sweepTic), .001))
            end

            start(app.timerObj_task)

            app.revisitObj = [];
            app.MetaData.HTMLSource = layout.metadataPanel(app);
        end


        %-----------------------------------------------------------------%
        function RegularTask_ConfigBand(app, ii, jj, hReceiver)
            writeline(hReceiver, app.specObj(ii).Band(jj).SpecificSCPI.configSET);
            pause(.001)
            
            if ~isempty(app.specObj(ii).Band(jj).SpecificSCPI.attSET)
                writeline(hReceiver, app.specObj(ii).Band(jj).SpecificSCPI.attSET);
            end
        end


        %-----------------------------------------------------------------%
        function RegularTask_gpsData(app, ii, hReceiver, hGPS, newTimeStamp)
            % O controle de erro do RECEPTOR se dá na função "RegularTask_MainLoop".
            %
            % O controle de erro do GPS, por outro lado, se dá diretamente aqui, 
            % nesta função, e é restrito ao caso em que o receptor é "External",
            % ou seja, não se trata de GPS embarcado no RECEPTOR (GPS conectado
            % à porta USB do computador que executa o app, por exemplo).
            %
            % Caso a tarefa seja do tipo "Drive-test", toda vez que for manifestada 
            % uma desconexão, o app tentará reativar a conexao. Ou, em sendo uma tarefa 
            % de outro tipo, o app tentará reativar a conexão toda vez que o contador de
            % erro atingir um múltiplo de "class.Constants.errorGPSCountTrigger".
             
            gpsData = struct('Status', 0, 'Latitude', -1, 'Longitude', -1, 'TimeStamp', '');

            try
                switch app.specObj(ii).Task.Script.GPS.Type
                    case 'Built-in'
                        gpsData = fcn.gpsBuiltInReader(hReceiver);
                    case 'External'
                        gpsData = fcn.gpsExternalReader(hGPS, 1);
                        app.specObj(ii).Error(2,2:4) = {NaT, NaT, 0};
                end

            catch ME
                app.specObj(ii).LOG(end+1) = struct('type', 'error (GPS)', 'time', char(newTimeStamp), 'msg', ME.message);

                if strcmp(app.specObj(ii).Task.Script.GPS.Type, 'External')
                    RegularTask_errorHandle(app, 'GPS', ii, newTimeStamp)

                    if contains(app.specObj(ii).Task.Type, 'Drive-test') || ~mod(app.specObj(ii).Error.Count(2), class.Constants.errorGPSCountTrigger)
                        app.gpsObj.ReconnectAttempt(hGPS.UserData.instrSelected);
                    end
                end
            end

            % As coordenadas da estação - registradas em app.General.stationInfo
            % - são atualizadas apenas se a estação for do tipo móvel ("Mobile") 
            % e as novas coordenadas geográficas forem válidas.

            if strcmp(app.General.stationInfo.Type, 'Mobile') && gpsData.Status
                app.General.stationInfo.Latitude  = gpsData.Latitude;
                app.General.stationInfo.Longitude = gpsData.Longitude;
            end

            RegularTask_gpsUpdate(app, ii, gpsData, newTimeStamp)
        end


        %-----------------------------------------------------------------%
        function RegularTask_gpsUpdate(app, ii, gpsData, newTimeStamp)
            if isempty(gpsData.TimeStamp)
                gpsData.TimeStamp = char(newTimeStamp);
            end
            app.specObj(ii).lastGPS = gpsData;

            if (app.Table.Selection == ii)
                layout.lastGPS(app, gpsData)
            end
        end
        
        
        %-----------------------------------------------------------------%
        function RegularTask_AntennaSwitch(app, ii, jj)
            switch app.specObj(ii).Task.Antenna.Switch.Name
                case 'EMSat'
                    msgError = app.EMSatObj.MatrixSwitch(app.specObj(ii).Band(jj).Antenna.SwitchPort,    ...
                                                         app.specObj(ii).Task.Antenna.Switch.OutputPort, ...
                                                         app.specObj(ii).Band(jj).Antenna.LNBChannel,    ...
                                                         app.specObj(ii).Band(jj).Antenna.LNBIndex);
                    if ~isempty(msgError)
                        error(msgError)
                    end

                case 'ERMx'
                    msgError = app.ERMxObj.MatrixSwitch( app.specObj(ii).Band(jj).Antenna.SwitchPort, ...
                                                         app.specObj(ii).Task.Antenna.Switch.OutputPort);
                    if ~isempty(msgError)
                        error(msgError)
                    end
            end
        end


        %-----------------------------------------------------------------%
        function newArray = RegularTask_specData(app, ii, jj, hReceiver, hStreaming, newTimeStamp)
            Timeout = class.Constants.Timeout;
            Flag_success = false;

            switch app.specObj(ii).Task.Receiver.Config.connectFlag
                case 1
                    % Spectrum analyzers (R&S, KeySight, Tektronix, Anritsu)

                    recTic = tic;
                    t1 = toc(recTic);
                    while t1 < Timeout
                        try
                            writeline(hReceiver, app.specObj(ii).GeneralSCPI.dataGET);
                            newArray = readbinblock(hReceiver, 'single');
                                                        
                            if numel(newArray) == app.specObj(ii).Band(jj).DataPoints
                                if strcmp(app.specObj(ii).Task.Receiver.Sync, 'Continuous Sweep')
                                    SyncModeRef = sum(newArray);

                                    if SyncModeRef ~= app.specObj(ii).Band(jj).SyncModeRef
                                        app.specObj(ii).Band(jj).SyncModeRef = SyncModeRef;
                                    else
                                        continue
                                    end                                    
                                end

                                Flag_success = true;
                                break
                            end
    
                        catch
                        end
                        t1 = toc(recTic);
                    end
                    
                case 2
                    % R&S EB500: Tarefas ordinárias
                    
                    taskInfo = struct('Type',       app.specObj(ii).Task.Type,                      ...
                                      'FreqStart',  app.specObj(ii).Task.Script.Band(jj).FreqStart, ...
                                      'FreqStop',   app.specObj(ii).Task.Script.Band(jj).FreqStop,  ...
                                      'DataPoints', app.specObj(ii).Band(jj).DataPoints,            ...
                                      'nDatagrams', app.specObj(ii).Band(jj).Datagrams,             ...
                                      'udpPort',    app.EB500Obj.udpPort);

                    [newArray, Flag_success] = class.EB500Lib.DatagramRead_PSCAN(taskInfo, hReceiver, hStreaming);

                case 3
                    % R&S EB500 - Tarefa "Drive-test (Level+Azimuth)"
                    % O newArray gerado aqui, e apenas aqui, possui informações
                    % de nível, azimute e nota de qualidade do azimute. A dimensão 
                    % dele é 1 (Height) x DataPoints (Width) x 3 (Depth).

                    taskInfo = struct('Type',       app.specObj(ii).Task.Type,                                                                          ...
                                      'FreqCenter', (app.specObj(ii).Task.Script.Band(jj).FreqStart + app.specObj(ii).Task.Script.Band(jj).FreqStop)/2, ...
                                      'FreqSpan',   app.specObj(ii).Task.Script.Band(jj).FreqStop - app.specObj(ii).Task.Script.Band(jj).FreqStart,     ...
                                      'DataPoints', app.specObj(ii).Band(jj).DataPoints,                                                                ...
                                      'udpPort',    app.EB500Obj.udpPort);

                    [newArray, gpsData, Flag_success] = class.EB500Lib.DatagramRead_FFM(taskInfo, hReceiver, hStreaming);

                    % No datagrama tem a informação de gps... então vamos aproveitar! :)
                    RegularTask_gpsUpdate(app, ii, gpsData, newTimeStamp)
            end
            flush(hReceiver)
            
            if Flag_success
                if app.specObj(ii).Band(jj).FlipArray
                    newArray(:,:,1) = flip(newArray(:,:,1));
                end
            else
                error('Não foi lido corretamente o vetor de nível do receptor dentro do tempo limite (%.0f segundos).', Timeout)
            end            
        end


        %-----------------------------------------------------------------%
        function RegularTask_errorHandle(app, errorType, ii, newTimeStamp)
            switch errorType
                case 'Receiver'; idx = 1;
                case 'GPS';      idx = 2;
            end
                                
            if isnat(app.specObj(ii).Error.CreatedTime(idx))
                app.specObj(ii).Error.CreatedTime(idx) = newTimeStamp;
            end
            app.specObj(ii).Error.LastTime(idx) = newTimeStamp;
            app.specObj(ii).Error.Count(idx) = app.specObj(ii).Error.Count(idx) + 1;
        end


        %-----------------------------------------------------------------%
        function logMsg = Misc_logMsg(app, idx)
            logMsg = '';
            if ~isempty(app.specObj(idx).LOG)
                logTable = struct2table(app.specObj(idx).LOG);
                logMsg   = strjoin("<b>" + logTable.time + " - " + upper(logTable.type) + "</b>" + newline + logTable.msg, '\n\n');
            end
        end

    end

    
    methods (Access = public)
        %-----------------------------------------------------------------%
        function publicFcn_winAddTask(app, infoEdition, newTask)
            % Essa função é a interface com o módulo auxiliar auxApp.winAddTask,
            % possibilitando a inclusão/edição de tarefas.
            delete(app.auxiliarWin2)
            app.auxiliarWin2 = [];

            % O try/catch possibilita a inclusão do progressDialog sem que 
            % exista o risco dele ficar visível, caso ocorra algum erro não
            % mapeado no método da classe.
            try
                app.progressDialog.Visible = 'visible';
                [app.specObj, msgError]    = app.specObj.AddOrEditTask(infoEdition, newTask, app.EMSatObj, app.ERMxObj);
                app.progressDialog.Visible = 'hidden';

                if isempty(msgError)
                    RegularTask_timerFcn(app)                                 % Startup of every task
                else
                    appUtil.modalWindow(app.UIFigure, 'warning', msgError);
                end
            catch
            end

            app.progressDialog.Visible = 'hidden';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            try
                % PATH SEARCH
                appName = class.Constants.appName;

                if isdeployed
                    [~, result]    = system('path');
                    app.RootFolder = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
    
                    if ~isfile(fullfile(app.RootFolder, sprintf('%s.exe', appName)))
                        error('Não identificado o local de instalação do <i>app</i>.')
                    end                
                else
                    app.RootFolder = fileparts(mfilename('fullpath'));
                end

                % WARNING MESSAGES
                appUtil.disablingWarningMessages()

                % LOG (DIARY)
                diary(fullfile(app.RootFolder, 'Debug', '_log.txt'))                
                diary on
                fprintf(sprintf('<LogEntry>\n<BeginTime>%s</BeginTime>\n', datestr(now)))

                % STARTUP
                layout.winPosition(app)
                jsBackDoor_Initialization(app)
                startup_AxesCreation(app)
                startup_timerCreation(app)

            catch ME
                fprintf('%s\n', jsonencode(ME))
                appUtil.modalWindow(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end
            drawnow
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            % RUNNING TASK CHECK
            if app.Flag_running
                appUtil.modalWindow(app.UIFigure, 'warning', 'Existe uma tarefa em execução...');
                return
            end
            
            % LOG
            fprintf(sprintf('<EndTime>%s</EndTime>\n</LogEntry>\n', datestr(now)))
            diary off
            
            log = fileread(fullfile(app.RootFolder, 'Debug', '_log.txt'));
            if contains(log, 'error', 'IgnoreCase', true)
                writematrix(log, fullfile(app.RootFolder, 'Debug', '_error.txt'), "QuoteStrings", "none", "WriteMode", "append")
                writematrix("",  fullfile(app.RootFolder, 'Debug', '_log.txt'),   "QuoteStrings", "none", "WriteMode", "overwrite")
            end

            % STARTUP SPECOBJ
            if app.General.startupInfo
                RegularTask_specObjSave(app)
            else
                if isfile(fullfile(app.RootFolder, 'Settings', 'startupInfo.mat'))
                    delete(fullfile(app.RootFolder, 'Settings', 'startupInfo.mat'))
                end
            end

            % GENERAL SETTINGS
            if strcmp(app.General.stationInfo.Type, 'Mobile')
                fcn.GeneralSettings(app.General, app.RootFolder)
            end

            % DELETE TIMER
            h = timerfindall;
            stop(h); delete(h); clear h

            % DELETE SERVER
            if ~isempty(app.tcpServer)
                delete(app.tcpServer.Server)
            end

            % DELETE APPS
            if isdeployed
                delete(findall(groot, 'Type', 'Figure'))
            else
                delete(app.auxiliarWin1)
                delete(app.auxiliarWin2)
                delete(app.auxiliarWin3)
                delete(app.auxiliarWin4)
                delete(app.auxiliarWin5)
    
                delete(app)
            end
            
        end

        % Value changed function: menu_Button1, menu_Button4
        function menu_StateButtonPushed(app, event)
            
            if event.Source.Value
                set(findall(groot, 'Parent', app.menu_Grid, 'Type', 'uistatebutton', '-not', 'Tag', event.Source.Tag), 'Value', 0)
                
                switch event.Source.Tag
                    case 'HOME'; tabIndex = 1;
                    case 'TASK'; tabIndex = 2;
                end
                app.TabGroup.SelectedTab = app.TabGroup.Children(tabIndex);

            else
                event.Source.Value = 1;
                return
            end
            
        end

        % Callback function: menu_Button2, menu_Button3, menu_Button5, 
        % ...and 3 other components
        function menu_PushButtonPushed(app, event)
            
            switch event.Source
                case app.menu_Settings                                      % auxApp.winSettings
                    h = app.auxiliarWin1;

                case {app.task_ButtonNew, app.task_ButtonEdit}              % auxApp.winAddTask
                    h = app.auxiliarWin2;

                case app.menu_Button2                                       % auxApp.winEditTaskList
                    h = app.auxiliarWin3;

                case app.menu_Button3                                       % auxApp.winEditInstrumentList
                    h = app.auxiliarWin4;

                case app.menu_Button5                                       % auxApp.winServer
                    h = app.auxiliarWin5;
            end

            if isempty(h)
                app.progressDialog.Visible = 'visible';
                
                switch event.Source
                    case app.menu_Settings
                        app.auxiliarWin1 = auxApp.winSettings(app);
                    
                    case app.task_ButtonNew
                        app.auxiliarWin2 = auxApp.winAddTask(app, struct('type', 'new'));

                    case app.task_ButtonEdit
                        idx = app.Table.Selection;
                        if idx && ~strcmp(app.specObj(idx).Status, 'Em andamento')
                            app.auxiliarWin2 = auxApp.winAddTask(app, struct('type', 'edit', 'idx', app.Table.Selection));
                        end

                    case app.menu_Button2
                        app.auxiliarWin3 = auxApp.winEditTaskList(app);

                    case app.menu_Button3
                        app.auxiliarWin4 = auxApp.winEditInstrumentList(app);

                    case app.menu_Button5
                        app.auxiliarWin5 = auxApp.winServerStatus(app);
                end
                
                app.progressDialog.Visible = 'hidden';

            else
                figure(h.UIFigure)
            end

        end

        % Image clicked function: menu_Position
        function menu_PositionImageClicked(app, event)
            
            app.UIFigure.Position(3:4) = class.Constants.windowSize;
            layout.winPosition(app)

        end

        % Button pushed function: task_ButtonPlay
        function menu_PushButtonPushed_playTask(app, event)
            
            idx = app.Table.Selection;
            if idx 
                switch app.specObj(idx).Status
                    %-----------------------------------------------------%
                    % PLAY
                    %-----------------------------------------------------%
                    case {'Cancelada', 'Erro', 'Concluída'}
                        Timestamp = datetime('now');
        
                        switch app.specObj(idx).Task.Script.Observation.Type
                            case 'Duration'
                                app.specObj(idx).Observation.BeginTime = Timestamp;
                                app.specObj(idx).Observation.EndTime   = Timestamp + seconds(app.specObj(idx).Task.Script.Observation.Duration);
            
                            case 'Time'
                                if strcmp(app.specObj(idx).Status, 'Concluída')
                                    appUtil.modalWindow(app.UIFigure, 'warning', 'Uma tarefa no estado "Concluída" somente poderá ser executada novamente se o tipo do período de observação for "Duração" ou "Quantidade específica de amostras".');
                                    return
                                end
            
                            case 'Samples'
                                app.specObj(idx).Observation.BeginTime = Timestamp;
                                app.specObj(idx).Observation.EndTime   = NaT;
                        end
        
                        app.specObj(idx).Status = 'Na fila';
                        app.specObj(idx).LOG(end+1) = struct('type', 'task', 'time', char(Timestamp), 'msg', 'Reincluída na fila a tarefa.');

                        RegularTask_RestartStatus(app, idx, 1)        
                        RegularTask_timerFcn(app)

                    %-----------------------------------------------------%
                    % STOP
                    %-----------------------------------------------------%
                    case 'Em andamento'
                        RegularTask_StatusTaskCheck(app, idx, 'DeleteButtonPushed');
                end
            end
            
        end

        % Button pushed function: task_ButtonDel
        function menu_PushButtonPushed_delTask(app, event)
            
            idx = app.Table.Selection;
            if idx
                switch app.specObj(idx).Status
                    case 'Em andamento'
                        appUtil.modalWindow(app.UIFigure, 'warning', 'A tarefa precisa ser interrompida antes da tentativa de exclusão.');

                    otherwise
                        if ~app.Flag_running
                            app.specObj(idx) = [];
    
                            layout.tableBuilding(app, 1)
                            task_TreeSelectionChanged(app)
                        else
                            appUtil.modalWindow(app.UIFigure, 'warning', 'Uma tarefa poderá ser excluída, sendo eliminada da lista de tarefas, somente se não estiver sendo executada nenhuma tarefa.');
                        end
                end
            end

        end

        % Button pushed function: task_ButtonLOG
        function menu_PushButtonPushed_logTask(app, event)

            idx = app.Table.Selection;
            if idx
                appUtil.modalWindow(app.UIFigure, 'warning', Misc_logMsg(app, idx));
            end

        end

        % Image clicked function: task_LeftPanel, task_RightPanel, 
        % ...and 1 other component
        function menu_LayoutPanelVisibility(app, event)
            
            switch event.Source
                case app.task_TopPanel
                    if ~isnumeric(app.task_docGrid.RowHeight{1})
                        app.task_docGrid.RowHeight{1} = 0;
                        app.Table.Visible = 0;
                    else
                        app.task_docGrid.RowHeight{1} = '.75x';
                        app.Table.Visible = 1;
                    end

                case app.task_LeftPanel
                    if app.task_docGrid.ColumnWidth{1}
                        app.task_docGrid.ColumnWidth{1} = 0;
                    else
                        app.task_docGrid.ColumnWidth{1} = 325;
                    end

                case app.task_RightPanel
                    if app.task_docGrid.ColumnWidth{5}
                        app.task_docGrid.ColumnWidth{5} = 0;
                    else
                        app.task_docGrid.ColumnWidth{5} = 120;
                    end
            end
            
        end

        % Callback function: home_Button1, home_Button2, home_Button3, 
        % ...and 2 other components
        function mosaic_ButtonPushed(app, event)
            
            switch event.Source
                case app.home_Button1; menu_PushButtonPushed(app, struct('Source', app.menu_Button2))
                case app.home_Button2; menu_PushButtonPushed(app, struct('Source', app.menu_Button3))
                case app.home_Button3; app.menu_Button4.Value = 1; menu_StateButtonPushed(app, struct('Source', app.menu_Button4))
                case app.home_Button4; menu_PushButtonPushed(app, struct('Source', app.menu_Button5))
                case app.home_Button5; menu_PushButtonPushed(app, struct('Source', app.menu_Settings))
            end

        end

        % Selection changed function: Table
        function task_TableSelectionChanged(app, event)

            oldSelection = app.Table.UserData;
            newSelection = app.Table.Selection;

            if isempty(newSelection) && ~isempty(oldSelection)
                app.Table.Selection = oldSelection;
                drawnow

            else
                app.Table.UserData = newSelection;
                layout.treeBuilding(app, 1)
                task_TreeSelectionChanged(app)
            end
            
        end

        % Selection changed function: Tree
        function task_TreeSelectionChanged(app, event)

            try
                ii = app.Table.Selection;
                jj = app.Tree.SelectedNodes.NodeData;
    
                plotFcn.StartUp(app)
                if ~isempty(app.specObj(ii).Band(jj).Waterfall)
                    idx = app.specObj(ii).Band(jj).Waterfall.idx;
    
                    if idx
                        plotFcn.Draw(app, ii, jj)
                    end
                end
    
                % TASK INFO THAT ARE UPDATED IN REAL TIME
                % (LEFT PANEL)
                app.MetaData.HTMLSource = layout.metadataPanel(app);

    
                % (RIGHT PANEL)
                if ~isempty(app.specObj(ii).Band(jj).File); WritedSamples = app.specObj(ii).Band(jj).File.WritedSamples;
                else;                                       WritedSamples = -1; 
                end
                app.Sweeps.Text = string(WritedSamples);
    
                if ~contains(app.specObj(ii).Task.Type, 'PRÉVIA') && strcmp(app.specObj(ii).Status, 'Em andamento') && app.specObj(ii).Band(jj).Status
                    app.Sweeps_REC.Visible = 1;
                else
                    app.Sweeps_REC.Visible = 0;
                end
    
                if ~isempty(app.specObj(ii).Band(jj).Mask)
                    set(app.Button_MaskPlot, 'Enable', 1)
                    app.lastMask_text.Enable = 1;
                    layout.lastMaskValidation(app, true, ii, jj)
                else
                    set(app.Button_MaskPlot, 'Enable', 0, 'Value', 0)
                    layout.lastMaskInitialState(app)
                end
                layout.lastGPS(app, app.specObj(ii).lastGPS)
    
                % (DOWN STATUS PANEL)
                app.task_Status.Text = sprintf('%s\n%s', app.Table.Data.Receiver(ii), app.Tree.SelectedNodes.Text);
                if ~isempty(app.task_RevisitTime.Text); app.task_RevisitTime.Text = sprintf('%d varreduras\n%.3f seg', app.specObj(ii).Band(jj).nSweeps, app.specObj(ii).Band(jj).RevisitTime);
                else;                                   app.task_RevisitTime.Text = '';
                end

                % PLAY BUTTON
                switch app.specObj(ii).Status
                    case 'Na fila';      set(app.task_ButtonPlay, 'Enable', 'off', 'Icon', 'LT_play.png')
                    case 'Em andamento'; set(app.task_ButtonPlay, 'Enable', 'on',  'Icon', 'LT_stop.png')
                    otherwise;           set(app.task_ButtonPlay, 'Enable', 'on',  'Icon', 'LT_play.png')
                end

            catch
                % Return to initial layout aspect...
                plotFcn.StartUp(app)

                app.MetaData.HTMLSource   = ' ';
                app.Sweeps.Text           = '-1';
                app.Sweeps_REC.Visible    = 0;
                layout.errorCount(app, 0)
                
                set(app.Button_MaskPlot, 'Enable', 0, 'Value', 0)
                layout.lastMaskInitialState(app)
                
                app.task_Status.Text      = '';
                app.task_RevisitTime.Text = '';

                app.lastGPS_color.Color   = [0.502 0.502 0.502];
                app.lastGPS_text.Text     = {'<b style="color: #a2142f; font-size: 14;">-1.000</b> LAT '; '<b style="color: #a2142f; font-size: 14;">-1.000</b> LON '; ''; 'dd-mmm-yyyy '; 'HH:MM:SS '};
            end
            drawnow

        end

        % Value changed function: Button_Average, Button_MaxHold, 
        % ...and 2 other components
        function task_ButtonPushed_plotTraceMode(app, event)
            
            if ~app.Table.Selection || isempty(app.Tree.SelectedNodes) || app.Button_MaskPlot.Value
                return
            end

            ii = app.Table.Selection;
            jj = app.Tree.SelectedNodes.NodeData;

            if ~isempty(app.specObj(ii).Band(jj).Waterfall)
                idx = app.specObj(ii).Band(jj).Waterfall.idx;

                if idx
                    FreqStart = app.specObj(ii).Task.Script.Band(jj).FreqStart / 1e+6;
                    FreqStop  = app.specObj(ii).Task.Script.Band(jj).FreqStop  / 1e+6;
                    LevelUnit = app.specObj(ii).Task.Script.Band(jj).instrLevelUnit;

                    xArray    = linspace(FreqStart, FreqStop, app.specObj(ii).Band(jj).DataPoints);
                    newArray = app.specObj(ii).Band(jj).Waterfall.Matrix(idx,:);

                    switch event.Source
                        case app.Button_MinHold
                            if app.Button_MinHold.Value
                                plotFcn.minHold(app, ii, jj, xArray, newArray)
                                plotFcn.DataTipModel(app.line_MinHold, LevelUnit)
                            else
                                delete(app.line_MinHold)
                                app.line_MinHold = [];
                            end

                        case app.Button_Average
                            if app.Button_Average.Value
                                plotFcn.Average(app, ii, jj, xArray, newArray)
                                plotFcn.DataTipModel(app.line_Average, LevelUnit)
                            else
                                delete(app.line_Average)
                                app.line_Average = [];
                            end

                        case app.Button_MaxHold
                            if app.Button_MaxHold.Value
                                plotFcn.maxHold(app, ii, jj, xArray, newArray)
                                plotFcn.DataTipModel(app.line_MaxHold, LevelUnit)
                            else
                                delete(app.line_MaxHold)
                                app.line_MaxHold = [];
                            end

                        case app.Button_peakExcursion
                            if app.Button_peakExcursion.Value
                                plotFcn.peakExcursion(app, ii, jj, newArray)
                            else
                                delete(app.peakExcursion)
                                app.peakExcursion = [];
                            end
                    end
                    drawnow nocallbacks
                end
            end

        end

        % Button pushed function: Button_Layout
        function task_ButtonPushed_plotLayout(app, event)
            
            switch app.plotLayout
                case 1; app.plotLayout = 2;
                case 2; app.plotLayout = 3;
                case 3; app.plotLayout = 1;
            end
            plotFcn.Layout(app)

        end

        % Value changed function: Button_MaskPlot
        function task_ButtonPushed_MaskPlot(app, event)
            
            if ~app.Table.Selection || isempty(app.Tree.SelectedNodes)
                return
            end

            ii = app.Table.Selection;
            jj = app.Tree.SelectedNodes.NodeData;
    
            plotFcn.StartUp(app)
            plotFcn.Draw(app, ii, jj)
            drawnow
            
        end

        % Value changed function: menu_userPath
        function menu_userPathValueChanged(app, event)
            
            app.General.userPath = app.menu_userPath.Value;        

            if ~ismember(app.menu_userPath.Value, class.Constants.userPaths)
                fcn.GeneralSettings(app.General, app.RootFolder)
            end         

        end

        % Image clicked function: menu_searchPath
        function menu_searchPathImageClicked(app, event)
            
            newUserPath = uigetdir(app.menu_userPath.Value, 'Escolha a pasta de trabalho');
            figure(app.UIFigure)
            
            if newUserPath
                idx = find(strcmp(app.menu_userPath.Items, newUserPath), 1);
                
                if isempty(idx)
                    app.menu_userPath.Items(end+1) = {newUserPath};
                    app.menu_userPath.Value        = newUserPath;
                    
                    menu_userPathValueChanged(app)
                else
                    app.menu_userPath.Value        = app.menu_userPath.Items(idx);
                end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColetaV2 R2023a';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab1_Home = uitab(app.TabGroup);
            app.Tab1_Home.Title = 'HOME';
            app.home_Grid = uigridlayout(app.Tab1_Home);
            app.jsBackDoor = uihtml(app.home_Grid);
            app.home_Button1 = ccTools.Button(app.home_Grid);
            app.home_Button1.Text = 'Editar lista de tarefas|de monitoração';
            app.home_Button1.Description = 'Inclusão, edição e exclusão de tarefa|(parâmetros relacionados à detecção de emissões)';
            app.home_Button1.Icon = 'LT_task.png';
            app.home_Button1.VerticalAlign = 'top';
            app.home_Button1.RowTextSpacing = 14;
            app.home_Button1.IconWidth = 36;
            app.home_Button1.IconHeight = 36;
            app.home_Button1.ButtonPushedFcn = createCallbackFcn(app, @mosaic_ButtonPushed, true);
            app.home_Button2 = ccTools.Button(app.home_Grid);
            app.home_Button2.Text = 'Editar lista de|instrumentos';
            app.home_Button2.Description = 'Inclusão, edição e exclusão de instrumento|(IP, porta, teste de conectividade)';
            app.home_Button2.Icon = 'LT_connect.png';
            app.home_Button2.VerticalAlign = 'top';
            app.home_Button2.RowTextSpacing = 14;
            app.home_Button2.IconWidth = 36;
            app.home_Button2.IconHeight = 36;
            app.home_Button2.ButtonPushedFcn = createCallbackFcn(app, @mosaic_ButtonPushed, true);
            app.home_Button3 = ccTools.Button(app.home_Grid);
            app.home_Button3.Text = 'Acompanhar a execução|de tarefas de monitoração';
            app.home_Button3.Description = 'Apresentação de metadados e <i>plots</i>|(arquivo, rompimento de máscara, GPS)';
            app.home_Button3.Icon = 'LT_OCC.png';
            app.home_Button3.VerticalAlign = 'top';
            app.home_Button3.RowTextSpacing = 138;
            app.home_Button3.IconWidth = 36;
            app.home_Button3.IconHeight = 36;
            app.home_Button3.ButtonPushedFcn = createCallbackFcn(app, @mosaic_ButtonPushed, true);
            app.home_Button4 = ccTools.Button(app.home_Grid);
            app.home_Button4.Text = 'Servidor|TCP';
            app.home_Button4.Description = 'Registro de comunicação do servidor|TCP com os seus clientes';
            app.home_Button4.Icon = 'LT_Cloud.png';
            app.home_Button4.VerticalAlign = 'top';
            app.home_Button4.RowTextSpacing = 14;
            app.home_Button4.IconWidth = 36;
            app.home_Button4.IconHeight = 36;
            app.home_Button4.ButtonPushedFcn = createCallbackFcn(app, @mosaic_ButtonPushed, true);
            app.home_Button5 = ccTools.Button(app.home_Grid);
            app.home_Button5.Text = 'Configurações|gerais';
            app.home_Button5.Description = 'Atualização de componentes,<br>customizações de <i>plots</i> etc';
            app.home_Button5.Icon = 'LT_Dots.png';
            app.home_Button5.VerticalAlign = 'top';
            app.home_Button5.RowTextSpacing = 14;
            app.home_Button5.IconWidth = 36;
            app.home_Button5.IconHeight = 36;
            app.home_Button5.ButtonPushedFcn = createCallbackFcn(app, @mosaic_ButtonPushed, true);
            app.Tab2_Task = uitab(app.TabGroup);
            app.Tab2_Task.Title = 'TASK';
            app.task_Grid = uigridlayout(app.Tab2_Task);
            app.task_docGrid = uigridlayout(app.task_Grid);
            app.Table = uitable(app.task_docGrid);
            app.Table.ColumnName = {'ID'; 'TAREFA'; 'RECEPTOR'; 'INCLUSÃO'; 'INÍCIO|OBSERVAÇÃO'; 'FIM|OBSERVAÇÃO'; 'ESTADO'};
            app.Table.RowName = {};
            app.Table.SelectionType = 'row';
            app.Table.SelectionChangedFcn = createCallbackFcn(app, @task_TableSelectionChanged, true);
            app.Table.Multiselect = 'off';
            app.Tree_Label = uilabel(app.task_docGrid);
            app.Tree_Label.Text = 'FAIXA DE FREQUÊNCIA:';
            app.Tree = uitree(app.task_docGrid);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @task_TreeSelectionChanged, true);
            app.MetaData_Panel = uipanel(app.task_docGrid);
            app.MetaData_Grid = uigridlayout(app.MetaData_Panel);
            app.MetaData = uihtml(app.MetaData_Grid);
            app.MetaData.HTMLSource = ' ';
            app.Plot_Panel = uipanel(app.task_docGrid);
            app.PlotTool_Grid = uigridlayout(app.task_docGrid);
            app.Button_MinHold = uibutton(app.PlotTool_Grid, 'state');
            app.Button_MinHold.ValueChangedFcn = createCallbackFcn(app, @task_ButtonPushed_plotTraceMode, true);
            app.Button_MinHold.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_MinHold.png');
            app.Button_MinHold.Text = '';
            app.Button_Average = uibutton(app.PlotTool_Grid, 'state');
            app.Button_Average.ValueChangedFcn = createCallbackFcn(app, @task_ButtonPushed_plotTraceMode, true);
            app.Button_Average.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_Average.png');
            app.Button_Average.Text = '';
            app.Button_MaxHold = uibutton(app.PlotTool_Grid, 'state');
            app.Button_MaxHold.ValueChangedFcn = createCallbackFcn(app, @task_ButtonPushed_plotTraceMode, true);
            app.Button_MaxHold.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_MaxHold.png');
            app.Button_MaxHold.Text = '';
            app.Button_peakExcursion = uibutton(app.PlotTool_Grid, 'state');
            app.Button_peakExcursion.ValueChangedFcn = createCallbackFcn(app, @task_ButtonPushed_plotTraceMode, true);
            app.Button_peakExcursion.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_Detection.png');
            app.Button_peakExcursion.Text = '';
            app.Button_Layout = uibutton(app.PlotTool_Grid, 'push');
            app.Button_Layout.ButtonPushedFcn = createCallbackFcn(app, @task_ButtonPushed_plotLayout, true);
            app.Button_Layout.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_Layers.png');
            app.Button_Layout.Text = '';
            app.Button_MaskPlot = uibutton(app.PlotTool_Grid, 'state');
            app.Button_MaskPlot.ValueChangedFcn = createCallbackFcn(app, @task_ButtonPushed_MaskPlot, true);
            app.Button_MaskPlot.Enable = 'off';
            app.Button_MaskPlot.Icon = 'LT_mask.png';
            app.Button_MaskPlot.Text = '';
            app.TaskInfo_Panel = uigridlayout(app.task_docGrid);
            app.Sweeps_Panel = uipanel(app.TaskInfo_Panel);
            app.Sweeps_Grid = uigridlayout(app.Sweeps_Panel);
            app.Sweeps_Grid.Tag = 'COLORLOCKED';
            app.Sweeps_REC = uiimage(app.Sweeps_Grid);
            app.Sweeps_REC.Visible = 'off';
            app.Sweeps_REC.ImageSource = fullfile(pathToMLAPP, 'Icons', 'REC.gif');
            app.Sweeps_Label = uilabel(app.Sweeps_Grid);
            app.Sweeps_Label.Text = {'VARREDURAS'; 'EM ARQUIVO'};
            app.Sweeps = uilabel(app.Sweeps_Grid);
            app.Sweeps.Text = '-1';
            app.errorCount_txt = uilabel(app.Sweeps_Grid);
            app.errorCount_txt.Visible = 'off';
            app.errorCount_txt.Text = '0';
            app.errorCount_img = uiimage(app.Sweeps_Grid);
            app.errorCount_img.Visible = 'off';
            app.errorCount_img.ImageSource = 'LT_redWarn.png';
            app.lastMask_Panel = uipanel(app.TaskInfo_Panel);
            app.lastMask_Grid = uigridlayout(app.lastMask_Panel);
            app.lastMask_Grid.Tag = 'COLORLOCKED';
            app.lastMask_label = uilabel(app.lastMask_Grid);
            app.lastMask_label.Text = {'MÁSCARA'; 'ESPECTRAL'};
            app.lastMask_text = uilabel(app.lastMask_Grid);
            app.lastMask_text.Interpreter = 'html';
            app.lastMask_text.Enable = 'off';
            app.lastMask_text.Text = {'<b style="color: #a2142f; font-size: 14;">-1</b> '; 'VALIDAÇÕES '; '<b style="color: #a2142f; font-size: 14;">-1</b> '; 'ROMPIMENTOS '; '<font style="color: #a2142f;">-1.000 MHz '; '⌂ -1.0 kHz '; 'Ʌ -1.0 dB </font>'; 'dd-mmm-yyyy '; 'HH:MM:SS '};
            app.lastGPS_Panel = uipanel(app.TaskInfo_Panel);
            app.lastGPS_Grid1 = uigridlayout(app.lastGPS_Panel);
            app.lastGPS_Grid1.Tag = 'COLORLOCKED';
            app.lastGPS_label = uilabel(app.lastGPS_Grid1);
            app.lastGPS_label.Text = {'COORDENADAS'; 'GEOGRÁFICAS'};
            app.lastGPS_text = uilabel(app.lastGPS_Grid1);
            app.lastGPS_text.Interpreter = 'html';
            app.lastGPS_text.Text = {'<b style="color: #a2142f; font-size: 14;">-1.000</b> LAT '; '<b style="color: #a2142f; font-size: 14;">-1.000</b> LON '; 'dd-mmm-yyyy '; 'HH:MM:SS '};
            app.lastGPS_Grid2 = uigridlayout(app.lastGPS_Grid1);
            app.lastGPS_color = uilamp(app.lastGPS_Grid2);
            app.errorCount_txt_2 = uilabel(app.lastGPS_Grid1);
            app.errorCount_txt_2.Visible = 'off';
            app.errorCount_txt_2.Text = '0';
            app.errorCount_img_2 = uiimage(app.lastGPS_Grid1);
            app.errorCount_img_2.Visible = 'off';
            app.errorCount_img_2.ImageSource = 'LT_redWarn.png';
            app.task_toolGrid = uigridlayout(app.task_Grid);
            app.task_ControlsGrid = uigridlayout(app.task_toolGrid);
            app.task_ButtonNew = uibutton(app.task_ControlsGrid, 'push');
            app.task_ButtonNew.ButtonPushedFcn = createCallbackFcn(app, @menu_PushButtonPushed, true);
            app.task_ButtonNew.Tag = 'subTask';
            app.task_ButtonNew.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_AddFiles.png');
            app.task_ButtonNew.Tooltip = {''};
            app.task_ButtonNew.Text = '';
            app.task_ButtonEdit = uibutton(app.task_ControlsGrid, 'push');
            app.task_ButtonEdit.ButtonPushedFcn = createCallbackFcn(app, @menu_PushButtonPushed, true);
            app.task_ButtonEdit.Tag = 'subTask';
            app.task_ButtonEdit.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_edit.png');
            app.task_ButtonEdit.Enable = 'off';
            app.task_ButtonEdit.Tooltip = {''};
            app.task_ButtonEdit.Text = '';
            app.task_Separator1 = uiimage(app.task_ControlsGrid);
            app.task_Separator1.Enable = 'off';
            app.task_Separator1.ImageSource = 'LT_LineV.png';
            app.task_ButtonPlay = uibutton(app.task_ControlsGrid, 'push');
            app.task_ButtonPlay.ButtonPushedFcn = createCallbackFcn(app, @menu_PushButtonPushed_playTask, true);
            app.task_ButtonPlay.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_play.png');
            app.task_ButtonPlay.Enable = 'off';
            app.task_ButtonPlay.Tooltip = {''};
            app.task_ButtonPlay.Text = '';
            app.task_ButtonDel = uibutton(app.task_ControlsGrid, 'push');
            app.task_ButtonDel.ButtonPushedFcn = createCallbackFcn(app, @menu_PushButtonPushed_delTask, true);
            app.task_ButtonDel.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_redX.png');
            app.task_ButtonDel.Enable = 'off';
            app.task_ButtonDel.Tooltip = {''};
            app.task_ButtonDel.Text = '';
            app.task_Separator2 = uiimage(app.task_ControlsGrid);
            app.task_Separator2.Enable = 'off';
            app.task_Separator2.ImageSource = 'LT_LineV.png';
            app.task_ButtonLOG = uibutton(app.task_ControlsGrid, 'push');
            app.task_ButtonLOG.ButtonPushedFcn = createCallbackFcn(app, @menu_PushButtonPushed_logTask, true);
            app.task_ButtonLOG.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_log.png');
            app.task_ButtonLOG.Enable = 'off';
            app.task_ButtonLOG.Tooltip = {''};
            app.task_ButtonLOG.Text = '';
            app.task_Status = uilabel(app.task_toolGrid);
            app.task_Status.Text = '';
            app.task_RevisitTime = uilabel(app.task_toolGrid);
            app.task_RevisitTime.Text = '';
            app.task_LayoutGrid = uigridlayout(app.task_toolGrid);
            app.task_TopPanel = uiimage(app.task_LayoutGrid);
            app.task_TopPanel.ImageClickedFcn = createCallbackFcn(app, @menu_LayoutPanelVisibility, true);
            app.task_TopPanel.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_layoutTop.png');
            app.task_LeftPanel = uiimage(app.task_LayoutGrid);
            app.task_LeftPanel.ImageClickedFcn = createCallbackFcn(app, @menu_LayoutPanelVisibility, true);
            app.task_LeftPanel.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_layoutLeft.png');
            app.task_RightPanel = uiimage(app.task_LayoutGrid);
            app.task_RightPanel.ImageClickedFcn = createCallbackFcn(app, @menu_LayoutPanelVisibility, true);
            app.task_RightPanel.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_layoutRight.png');
            app.menu_Panel = uipanel(app.GridLayout);
            app.menu_Grid = uigridlayout(app.menu_Panel);
            app.menu_Grid.Tag = 'COLORLOCKED';
            app.menu_Button1 = uibutton(app.menu_Grid, 'state');
            app.menu_Button1.ValueChangedFcn = createCallbackFcn(app, @menu_StateButtonPushed, true);
            app.menu_Button1.Tag = 'HOME';
            app.menu_Button1.Tooltip = {''};
            app.menu_Button1.Icon = fullfile(pathToMLAPP, 'Icons', 'DT_home.png');
            app.menu_Button1.Text = '';
            app.menu_Button1.Value = true;
            app.menu_Separator1 = uiimage(app.menu_Grid);
            app.menu_Separator1.Enable = 'off';
            app.menu_Separator1.ImageSource = fullfile(pathToMLAPP, 'Icons', 'DT_LineV.png');
            app.menu_Button2 = uibutton(app.menu_Grid, 'push');
            app.menu_Button2.ButtonPushedFcn = createCallbackFcn(app, @menu_PushButtonPushed, true);
            app.menu_Button2.Icon = fullfile(pathToMLAPP, 'Icons', 'DT_task.png');
            app.menu_Button2.Tooltip = {''};
            app.menu_Button2.Text = '';
            app.menu_Button3 = uibutton(app.menu_Grid, 'push');
            app.menu_Button3.ButtonPushedFcn = createCallbackFcn(app, @menu_PushButtonPushed, true);
            app.menu_Button3.Icon = fullfile(pathToMLAPP, 'Icons', 'DT_connect.png');
            app.menu_Button3.Tooltip = {''};
            app.menu_Button3.Text = '';
            app.menu_Button4 = uibutton(app.menu_Grid, 'state');
            app.menu_Button4.ValueChangedFcn = createCallbackFcn(app, @menu_StateButtonPushed, true);
            app.menu_Button4.Tag = 'TASK';
            app.menu_Button4.Tooltip = {''};
            app.menu_Button4.Icon = fullfile(pathToMLAPP, 'Icons', 'DT_playback.png');
            app.menu_Button4.Text = '';
            app.menu_Separator2 = uiimage(app.menu_Grid);
            app.menu_Separator2.Enable = 'off';
            app.menu_Separator2.ImageSource = fullfile(pathToMLAPP, 'Icons', 'DT_LineV.png');
            app.menu_Button5 = uibutton(app.menu_Grid, 'push');
            app.menu_Button5.ButtonPushedFcn = createCallbackFcn(app, @menu_PushButtonPushed, true);
            app.menu_Button5.Icon = fullfile(pathToMLAPP, 'Icons', 'DT_Cloud.png');
            app.menu_Button5.Tooltip = {''};
            app.menu_Button5.Text = '';
            app.menu_userPathGrid = uigridlayout(app.menu_Grid);
            app.menu_userPath = uidropdown(app.menu_userPathGrid);
            app.menu_userPath.Items = {};
            app.menu_userPath.ValueChangedFcn = createCallbackFcn(app, @menu_userPathValueChanged, true);
            app.menu_userPath.Value = {};
            app.menu_searchPath = uiimage(app.menu_Grid);
            app.menu_searchPath.ImageClickedFcn = createCallbackFcn(app, @menu_searchPathImageClicked, true);
            app.menu_searchPath.ImageSource = fullfile(pathToMLAPP, 'Icons', 'DT_zoom.png');
            app.menu_Position = uiimage(app.menu_Grid);
            app.menu_Position.ImageClickedFcn = createCallbackFcn(app, @menu_PositionImageClicked, true);
            app.menu_Position.ImageSource = fullfile(pathToMLAPP, 'Icons', 'DT_layout1.png');
            app.menu_Settings = uiimage(app.menu_Grid);
            app.menu_Settings.ImageClickedFcn = createCallbackFcn(app, @menu_PushButtonPushed, true);
            app.menu_Settings.ImageSource = fullfile(pathToMLAPP, 'Icons', 'DT_Dots.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winAppColetaV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end