classdef winMain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        MetaData_Panel     matlab.ui.container.Panel
        MetaData_Grid      matlab.ui.container.GridLayout
        MetaData           matlab.ui.control.HTML
        MetadadosrelacionadosfaixaselecionadaLabel  matlab.ui.control.Label
        GridLayout3        matlab.ui.container.GridLayout
        Panel_7            matlab.ui.container.Panel
        GridLayout2_6      matlab.ui.container.GridLayout
        lastMask_text      matlab.ui.control.Label
        lastMask_label     matlab.ui.control.Label
        Panel_6            matlab.ui.container.Panel
        GridLayout2_3      matlab.ui.container.GridLayout
        GridLayout7        matlab.ui.container.GridLayout
        lastGPS_color      matlab.ui.control.Lamp
        lastGPS_text       matlab.ui.control.Label
        lastGPS_label      matlab.ui.control.Label
        Panel_5            matlab.ui.container.Panel
        GridLayout2_2      matlab.ui.container.GridLayout
        Channel            matlab.ui.control.Label
        tool_REC           matlab.ui.control.Image
        ChannelLabel       matlab.ui.control.Label
        Tree               matlab.ui.container.Tree
        Export_Grid        matlab.ui.container.GridLayout
        table_Del_2        matlab.ui.control.Image
        table_Separator_2  matlab.ui.control.Image
        table_Separator    matlab.ui.control.Image
        table_Play         matlab.ui.control.Image
        table_Stop         matlab.ui.control.Image
        table_Edit         matlab.ui.control.Image
        table_New          matlab.ui.control.Image
        table_Del          matlab.ui.control.Image
        FaixasdefrequnciarelacionadastarefaselecionadaLabel  matlab.ui.control.Label
        Plot_Panel         matlab.ui.container.Panel
        table_Grid         matlab.ui.container.GridLayout
        Column10           matlab.ui.control.Label
        Column8            matlab.ui.control.Label
        Column7            matlab.ui.control.Label
        Column3            matlab.ui.control.Label
        Column5            matlab.ui.control.Label
        Column2            matlab.ui.control.Label
        Column1            matlab.ui.control.Label
        UITable            matlab.ui.control.Table
    end

    
    properties (Access = public)
        
        General
        RootFolder

        taskList
        scpiList
        instrInfo

        EMSatObj
        EB500Obj
        
        axes1
        axes2

        specObj      = specClass
        revisitObj
        timerObj

        instrHandles = table('Size', [0, 5],                                                    ...
                             'VariableTypes', {'string', 'string', 'string', 'cell', 'double'}, ...
                             'VariableNames', {'Type', 'IDN', 'Socket', 'Handle', 'Status'})
        udpPortArray = {}

        Flag_running = 0
        Flag_editing = 0

        auxWin1 = []
        auxWin2 = []
        auxWin3 = []

    end

    
    methods (Access = private)

        function startup_Layout(app)
            mainMonitor = get(0, 'MonitorPositions');

            [~, ind]    = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4)-30)/2)];
        end


        function startup_AxesCreation(app)
            t = tiledlayout(app.Plot_Panel, 3, 1, "Padding", "tight", "TileSpacing", "compact");

            % app.UIAxes1
            app.axes1 = uiaxes(t);
            app.axes1.Layout.Tile = 1;
            set(app.axes1, Color = [0.94, .94, .94], ...
                           FontSize = 8)
            ylabel(app.axes1, 'Nível (dB)')


            % app.UIAxes2
            app.axes2 = uiaxes(t);
            app.axes2.Layout.Tile = 2;
            app.axes2.Layout.TileSpan  = [2,1];
            set(app.axes2, Color = [0.94, .94, .94], ...
                           FontSize = 8)
            xlabel(app.axes2, 'Frequência (MHz)')
            ylabel(app.axes2, 'Amostras')
        end


        function startup_ConfigFiles(app)
            global appGeneral

            % app.General
            tempObj = jsondecode(fileread(fullfile(app.RootFolder, 'Settings', 'GeneralSettings.json')));
            app.General = tempObj;

            userpaths = {fullfile(getenv('USERPROFILE'), 'Documents'); ...
                         fullfile(getenv('USERPROFILE'), 'Downloads')};

            if ~isempty(app.General.userPath)
                userpaths(end+1) = {app.General.userPath};
            end

            for ii = numel(userpaths):-1:1
                if ~isfolder(userpaths{ii})
                    userpaths(ii) = [];
                end
            end
            app.General.userPath   = userpaths{end};
            app.General.RootFolder = app.RootFolder;

            appGeneral = app.General;


            % app.taskList
            taskTemp = jsondecode(fileread('taskList.json'));
            for ii = 1:numel(taskTemp)
                app.taskList{ii} = taskTemp(ii);
    
                if strcmp(app.taskList{ii}.Observation.Type, 'Duration') & isempty(app.taskList{ii}.Observation.Duration)
                    app.taskList{ii}.Observation.Duration = inf;
                end
    
                if ~any([app.taskList{ii}.Band.Enable])
                    app.taskList{ii}.Band(1).Enable = 1;
                end
            end


            % app.scpiList
            tempList = jsondecode(fileread('scpiList.json'));
            for ii = 1:numel(tempList)
                tempList(ii).Parameters = jsonencode(tempList(ii).Parameters);
            end

            app.scpiList     = struct2table(tempList, 'AsArray', true);
            app.scpiList.idx = (1:numel(tempList))';
            app.scpiList     = movevars(app.scpiList, 'idx', 'Before', 1);


            % app.instrInfo, app.EMSatObj & app.EB500Obj
            app.instrInfo = struct2table(jsondecode(fileread(fullfile(app.RootFolder, 'Settings', 'instrInfo.json'))));
            app.EMSatObj  = EMSatLib;
            app.EB500Obj  = EB500Lib;
        end


        function startup_Timer(app)
            app.timerObj = timer("ExecutionMode", "fixedSpacing",        ...
                                 "BusyMode",      "queue",               ...
                                 "StartDelay",    Timer_StartDelay(app), ...
                                 "Period",        900,                   ...
                                 "TimerFcn",      @(~,~)app.Timer_RegularTaskCheck);
            start(app.timerObj)
        end


        function StartDelay = Timer_StartDelay(app)
            StartDelay = 0;
            if mod(minute(datetime('now')), 15)
                StartDelay = (15 - mod(minute(datetime('now')), 15)) * 60;
            end
        end


        function Timer_RegularTaskCheck(app)
            if ~app.Flag_running
                Flag = [];
                for ii = 1:numel(app.specObj)
                    if RegularTask_StatusTaskCheck(app, ii)
                        Flag(end+1) = true;
                    end
                end

                if ~isempty(Flag)
                    RegularTask_MainLoop(app)
                end
            end
        end


        function MessageBox(app, type, msg)
            appName = 'appColeta';

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, appName, 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, appName, 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, appName, 'Interpreter', 'html', 'Icon', 'LT_info.png')
                case 'startup'; app.UIFigure.Visible = 1; uialert(app.UIFigure, msg, appName, 'Interpreter', 'html', 'CloseFcn', @(~,~)closeFcn(app));
            end
        end


        function uiTable_Building(app)
            Selection = app.UITable.Selection;
            if isempty(Selection); Selection = 1;
            end

            tempTable = table('Size', [0, 7],                                                                          ...
                              'VariableTypes', {'double', 'string', 'string', 'string', 'string', 'string', 'string'}, ...
                              'VariableNames', {'ID', 'Name', 'Receiver', 'Created', 'BeginTime', 'EndTime', 'Status'});

            
            for ii = 1:numel(app.specObj)
                EndTime = '-';
                if ~isnat(app.specObj(ii).Observation.EndTime)
                    EndTime = datestr(app.specObj(ii).Observation.EndTime, 'dd/mm/yyyy HH:MM:SS');
                end

                tempTable(end+1,:) = {app.specObj(ii).ID,                        ...
                                      app.specObj(ii).taskObj.General.Task.Name, ...
                                      app.specObj(ii).hReceiver.UserData.IDN,    ...
                                      app.specObj(ii).Observation.Created,       ...
                                      datestr(app.specObj(ii).Observation.BeginTime, 'dd/mm/yyyy HH:MM:SS'), ...
                                      EndTime,                                   ...
                                      app.specObj(ii).Status};
            end

            app.UITable.Data      = tempTable;
            app.UITable.Selection = Selection;
            uiTable_Style(app)

            if ~isempty(app.Tree.SelectedNodes); uiTree_Building(app, app.Tree.SelectedNodes.NodeData)
            else;                                uiTree_Building(app, 1)
            end
            drawnow
        end


        function uiTable_Style(app)
            removeStyle(app.UITable)

            idxStyle1 = find(app.UITable.Data.Status ~= "Em andamento...");
            if ~isempty(idxStyle1); addStyle(app.UITable, uistyle('FontColor', '#808080'), 'row', idxStyle1); end
            
            idxStyle2 = find(app.UITable.Data.Status == "Erro");
            if ~isempty(idxStyle2); addStyle(app.UITable, uistyle('FontColor', '#a2142f'), 'row', idxStyle2); end

%             addStyle(app.UITable, uistyle('BackgroundColor', '#bfe5ff'), 'row', app.UITable.Selection)
        end


        function uiTable_ToolbarLayout(app)


        end


        function uiTree_Building(app, Selection)            
            delete(app.Tree.Children);

            idx = app.UITable.Selection;
            for ii = 1:numel(app.specObj(idx).Band)
                Antenna = app.specObj(idx).taskObj.General.Task.Band(ii).instrAntenna;
                if ~isempty(Antenna)
                    Antenna = sprintf('(%s)', Antenna);
                end

                newNode = uitreenode(app.Tree, 'Text', sprintf('ID %d: %.3f - %.3f MHz %s',                                     ...
                                                               app.specObj(idx).taskObj.General.Task.Band(ii).ThreadID,         ...
                                                               app.specObj(idx).taskObj.General.Task.Band(ii).FreqStart / 1e+6, ...
                                                               app.specObj(idx).taskObj.General.Task.Band(ii).FreqStop  / 1e+6, ...
                                                               Antenna),                                                        ...
                                               'NodeData', ii);
            end
            
            app.Tree.SelectedNodes = app.Tree.Children(Selection);
            TreeSelectionChanged(app)            
        end


        function uiTree_Style(app, idx)
            removeStyle(app.Tree)

            idxStyle = app.Tree.Children(~[app.specObj(idx).Band.Status]);
            if ~isempty(idxStyle); addStyle(app.Tree, uistyle('FontColor', '#808080'), 'node', idxStyle); end

%             addStyle(app.Tree, uistyle('BackgroundColor', '#bfe5ff'), 'node', app.Tree.SelectedNodes)
        end


        function RegularTask_MainLoop(app)
            stop(app.timerObj)
            
            app.Flag_running = 1;
            app.Flag_editing = 1;
            
            while app.Flag_running
                if app.Flag_editing
                    app.revisitObj = task_RevisitFactors(app.specObj);
                    if isempty(app.revisitObj.GlobalRevisitTime)
                        app.Flag_running = 0;
                        break
                    end
                    
                    nn = 0;
                    app.Flag_editing = 0;
                end

                sweepTic = tic;
                for ii = 1:numel(app.specObj)
                    if RegularTask_StatusTaskCheck(app, ii)
                        app.Flag_editing = 1;
                        break
                    end

                    if ~strcmp(app.specObj(ii).Status, 'Em andamento...')
                        continue
                    end
                    
                    hReceiver  = app.specObj(ii).hReceiver;
                    hStreaming = app.specObj(ii).hStreaming;
                    hGPS       = app.specObj(ii).hGPS;

                    nBands = numel(app.specObj(ii).Band);    
                    for jj = 0:nBands
                        if mod(nn, app.revisitObj.Band(ii).RevisitFactors(jj+1)) || app.revisitObj.Band(ii).RevisitFactors(jj+1) == -1
                            continue
                        end
    
                        if jj == 0
                            app.specObj(ii).lastGPS = RegularTask_gpsData(app, ii, hReceiver, hGPS);

                        else
                            app.specObj(ii) = RFlookBinLib.CheckFile(app.specObj(ii), jj);
    
                            try
                                % ANTENNA SWITCH (IF APPLICABLE)
                                RegularTask_AntennaSwitch(app, ii)

                                % RECEIVER RECONFIGURATION (IF APPLICABLE)
                                if (nBands > 1) || (hReceiver.UserData.nTasks > 1)
                                    writeline(hReceiver, app.specObj(ii).Band(jj).scpiSet_Config);
                                    pause(.001)
                                    
                                    if ~isempty(app.specObj(ii).Band(jj).scpiSet_Att)
                                        writeline(hReceiver, app.specObj(ii).Band(jj).scpiSet_Att);
                                    end
                                end

                                attFactor = -1;
                                if ~isempty(app.specObj(ii).SCPI.scpiGet_Att)
                                    attFactor = str2double(writeread(hReceiver, app.specObj(ii).SCPI.scpiGet_Att));
                                end
                                
                                fileTrigger  = [];
                                brokeTrigger = [];
                                if isempty(app.specObj(ii).Band(jj).Mask)
                                    % SINGLE TRACE
                                    newArray = RegularTask_specData(app, ii, jj, hReceiver, hStreaming);
                                
                                else
                                    % BURST OF TRACES
                                    nSweeps  = app.General.FindPeaks.nSweeps;
                                    newArray = zeros(nSweeps, app.specObj(ii).Band(jj).DataPoints, 'single');                                    
                                    for kk = 1:nSweeps
                                        newArray(kk,:) = RegularTask_specData(app, ii, jj, hReceiver, hStreaming);
                                    end
                                    smoothedArray = mean(newArray);

                                    % METADATA UPDATE
                                    app.specObj(ii).Band(jj).Mask.Validations = app.specObj(ii).Band(jj).Mask.Validations + 1;
                                    
                                    brokeTrigger = false;
                                    switch app.specObj(ii).taskObj.General.Task.Band(jj).Trigger
                                        case 2;    fileTrigger = true;
                                        otherwise; fileTrigger = false;
                                    end

                                    % MASK BROKEN ANALISYS                                    
                                    validationArray = (smoothedArray - app.specObj(ii).Band(jj).Mask.Array) > 0;
                                    if any(validationArray)
                                        app.specObj(ii).Band(jj).Mask.BrokenArray = app.specObj(ii).Band(jj).Mask.BrokenArray + validationArray;

                                        peaksTable  = task_FindPeaks(app.specObj(ii), jj, smoothedArray, validationArray, app.General.FindPeaks);
                                        if ~isempty(peaksTable)
                                            app.specObj(ii).Band(jj).Mask.BrokenCount = app.specObj(ii).Band(jj).Mask.BrokenCount + 1;
                                            app.specObj(ii).Band(jj).Mask.MainPeaks   = peaksTable;
                                            app.specObj(ii).Band(jj).Mask.TimeStamp   = datetime('now');
                                            
                                            brokeTrigger = true;
                                        end
                                    end

                                    newArray = newArray(end,:);
                                end

                                % WATERFALL MATRIX
                                idx = app.specObj(ii).Band(jj).Waterfall.idx + 1;
                                if idx > app.General.Waterfall.Depth; idx = 1;
                                end

                                app.specObj(ii).Band(jj).Waterfall.idx = idx;
                                app.specObj(ii).Band(jj).Waterfall.Matrix(idx,:) = newArray;

                                % PLOT, WRITEDSAMPLES & MASKINFO (IF APPLICABLE)
                                if (app.UITable.Selection == ii) && (app.Tree.SelectedNodes.NodeData == jj)
                                    PlotTask(app, ii, jj, newArray)                                    
                                    if ~isempty(brokeTrigger)
                                        Layout_lastMaskValidation(app, brokeTrigger, ii, jj)
                                    end
                                    drawnow
                                end

                                % FILE
                                if fileTrigger & ~brokeTrigger
                                    continue
                                else
                                    RFlookBinLib.EditFile(app.specObj(ii), jj, newArray, attFactor)
                                    app.specObj(ii).Band(jj).File.WritedSamples = app.specObj(ii).Band(jj).File.WritedSamples + 1;

                                    if (app.UITable.Selection == ii) && (app.Tree.SelectedNodes.NodeData == jj)
                                        app.Channel.Text = string(app.specObj(ii).Band(jj).File.WritedSamples);
                                    end
                                    drawnow
                                end                                
    
                            catch ME
                                % CONTADOR DE ERROS - SE CHEGAR A 10
                                % SUCESSIVOS (PRECISA ZERAR SE DEU CERTO), FAZ-SE UMA CHECAGEM NAS
                                % COISAS.
                                getReport(ME)
                            end
                        end
                    end
                end
                
                nn = nn+1;
                pause(app.revisitObj.GlobalRevisitTime-toc(sweepTic))
            end

            set(app.timerObj, 'StartDelay', Timer_StartDelay(app))
            start(app.timerObj)
        end


        function Flag = RegularTask_StatusTaskCheck(app, idx)
            Flag = false;
            switch app.specObj(idx).Status        
                case 'Em andamento...'
                    if app.specObj(idx).Observation.EndTime < datetime('now')
                        Flag = true;

                        app.specObj(idx).Status = 'Concluída';
                        app.specObj(idx).hReceiver.UserData.nTasks = app.specObj(idx).hReceiver.UserData.nTasks-1;

                        for ii = 1:numel(app.specObj(idx).Band)
                            app.specObj(idx) = RFlookBinLib.CloseFile(app.specObj(idx), ii);
                            app.specObj(idx).Band(ii).Status = false;
                        end

                    else
                        if strcmp(app.specObj(idx).taskObj.General.Task.Observation.Type, 'Samples')
                            tempFlag = [];                            
                            for ii = 1:numel(app.specObj(idx).Band)
                                if app.specObj(idx).Band(ii).Status
                                    if app.specObj(idx).Band(ii).File.WritedSamples == app.specObj(idx).taskObj.General.Task.Band(ii).instrObservationSamples
                                        app.specObj(idx) = RFlookBinLib.CloseFile(app.specObj(idx), ii);
                                        app.specObj(idx).Band(ii).Status = false;
                                        tempFlag(end+1) = true;

                                    else
                                        tempFlag(end+1) = false;
                                    end
                                end
                            end

                            if all(tempFlag)
                                Flag = true;

                                app.specObj(idx).Status = 'Concluída';
                                app.specObj(idx).hReceiver.UserData.nTasks = app.specObj(idx).hReceiver.UserData.nTasks-1;

                            elseif any(tempFlag)
                                Flag = true;
                            end
                        end
                    end

                case {'Na fila...', 'Erro'}
                    Timestamp = datetime('now');                    
                    if strcmp(app.specObj(idx).Status, 'Erro') 
                        if ~mod(minute(Timestamp), 15) 
                            if isnat(app.specObj(idx).Observation.StartUp)
                                app.specObj(idx).Observation.StartUp = Timestamp;
                            end
    
                            StartUp = app.specObj(idx).Observation.StartUp;
                            if isequal([year(Timestamp), month(Timestamp), day(Timestamp), hour(Timestamp), minute(Timestamp)], ...
                                    [year(StartUp), month(StartUp), day(StartUp), hour(StartUp), minute(StartUp)])
                                return
                            end
                        else
                            return
                        end
                    end

                    if app.specObj(idx).Observation.BeginTime < datetime('now')
                        switch app.specObj(idx).taskObj.General.Task.Observation.Type
                            case {'Duration', 'Time'}
                                if isnat(app.specObj(idx).Observation.EndTime) || (app.specObj(idx).Observation.EndTime > datetime('now'))
                                    Flag = true;
                                end

                            case 'Samples'
                                Flag = true;
                        end
                    end

                    if Flag
                        try
                            RegularTask_StartUp(app, idx);

                            app.specObj(idx).Status = 'Em andamento...';
                            app.specObj(idx).hReceiver.UserData.nTasks   = app.specObj(idx).hReceiver.UserData.nTasks+1;
                            app.specObj(idx).hReceiver.UserData.SyncMode = app.specObj(idx).taskObj.Receiver.Sync;

                        catch ME
                            app.specObj(idx).LOG(end+1) = struct('type', 'error', 'time', datestr(now, 'dd/mm/yyyy HH:MM:SS'), 'msg', ME.message);
                            app.specObj(idx).Status = 'Erro';
                            Flag = false;
                        end
                    end
            end

            if Flag
                uiTable_Building(app)
                drawnow nocallbacks
            end
        end


        function gpsData = RegularTask_gpsData(app, idx, hReceiver, hGPS)
            gpsData = struct('Status', 0, 'Latitude', -1, 'Longitude', -1, 'TimeStamp', '');

            try
                switch app.specObj(idx).taskObj.General.Task.GPS.Type
                    case 'Built-in'; gpsData = task_gpsDecode(hReceiver);
                    case 'External'; gpsData = task_gpsReader(hGPS, 1);
                end
            catch
            end

            if isempty(gpsData.TimeStamp)
                gpsData.TimeStamp = datestr(now, 'dd/mm/yyyy HH:MM:ss');
            end

            if (app.UITable.Selection == idx)
                Layout_lastGPS(app, gpsData)
            end
        end


        function Layout_lastGPS(app, gpsData)
            switch gpsData.Status
                case  1; newColor = [0.47,0.67,0.19];
                case  0; newColor = [0.64,0.08,0.18];
                case -1; newColor = [0.50,0.50,0.50];
            end

            app.lastGPS_text.Text   = sprintf(['<b style="color: #a2142f; font-size: 14;">%.3f</b> LAT \n'   ...
                                               '<b style="color: #a2142f; font-size: 14;">%.3f</b> LON \n\n' ...
                                               '%s \n%s '], gpsData.Latitude, gpsData.Longitude,             ...
                                                            extractBefore(gpsData.TimeStamp, ' '),           ...
                                                            extractAfter(gpsData.TimeStamp, ' '));
            app.lastGPS_color.Color = newColor;
            drawnow
        end


        function Layout_lastMaskValidation(app, brokeTrigger, ii, jj)
            if brokeTrigger
                Validations = app.specObj(ii).Band(jj).Mask.Validations;
                BrokenCount = app.specObj(ii).Band(jj).Mask.BrokenCount;

                if ~isempty(app.specObj(ii).Band(jj).Mask.MainPeaks)
                    FreqCenter  = app.specObj(ii).Band(jj).Mask.MainPeaks.FreqCenter(1);
                    BandWidth   = app.specObj(ii).Band(jj).Mask.MainPeaks.BW(1);
                    dTimeStamp  = datestr(app.specObj(ii).Band(jj).Mask.TimeStamp, 'dd/mm/yyyy');
                    hTimeStamp  = datestr(app.specObj(ii).Band(jj).Mask.TimeStamp, 'HH:MM:SS');
                else
                    FreqCenter  = -1;
                    BandWidth   = -1;
                    dTimeStamp  = 'dd/mm/yyyy';
                    hTimeStamp  = 'HH:MM:SS';
                end
    
                app.lastMask_text.Text = sprintf(['<b style="color: #a2142f; font-size: 14;">%.0f</b> \nVALIDAÇÕES \n'    ...
                                                  '<b style="color: #a2142f; font-size: 14;">%.0f</b> \nROMPIMENTOS \n\n' ...
                                                  '<font style="color: #a2142f;">%.3f MHz \n⌂ %.3f kHz</font> \n\n%s \n%s '], ...
                                                  Validations, BrokenCount, FreqCenter, BandWidth, dTimeStamp, hTimeStamp);
            else
                app.lastMask_text.Text = replace(app.lastMask_text.Text, [extractBefore(app.lastMask_text.Text, 'VALIDAÇÕES') 'VALIDAÇÕES'], ...
                    sprintf('<b style="color: #a2142f; font-size: 14;">%.0f</b> \nVALIDAÇÕES', app.specObj(ii).Band(jj).Mask.Validations));
            end
        end
        
        
        function RegularTask_AntennaSwitch(app, idx)
            % PENDENTE!!!
            % Se não conseguir configurar adequadamente, retorna um erro,
            % indicando o que foi tentado, o que obtido de resposta do
            % comutador ect.
        end


        function newArray = RegularTask_specData(app, ii, jj, hReceiver, hStreaming)
            Timeout = 10;
            Flag_success = false;

            switch app.specObj(ii).taskObj.General.SCPI.connectFlag{1}
                case 1                                                      % Spectrum analyzers (R&S, KeySight, Tektronix, Anritsu)
                    recTic = tic;
                    t1 = toc(recTic);
                    while t1 < Timeout
                        try
                            writeline(hReceiver, app.specObj(ii).SCPI.scpiGet_Data);
                            newArray = readbinblock(hReceiver, 'single');
                                                        
                            if numel(newArray) == app.specObj(ii).Band(jj).DataPoints
                                if strcmp(app.specObj(ii).taskObj.Receiver.Sync, 'Continuous Sweep')
                                    SyncModeRef = sum(newArray);

                                    if SyncModeRef ~= app.specObj(ii).Band(jj).SyncModeRef
                                        app.specObj(ii).Band(jj).SyncModeRef = SyncModeRef;
                                    else
                                        continue
                                    end                                    
                                end

                                Flag_success = true;
                                break
                            end
    
                        catch
                        end
                        t1 = toc(recTic);
                    end
                    
                case 2                                                      % R&S EB500                    
                    taskInfo = struct('FreqStart',  app.specObj(ii).taskObj.General.Task.Band(jj).FreqStart, ...
                                      'FreqStop',   app.specObj(ii).taskObj.General.Task.Band(jj).FreqStop,  ...
                                      'DataPoints', app.specObj(ii).Band(jj).DataPoints,                     ...
                                      'nDatagrams', app.specObj(ii).Band(jj).Datagrams,                      ...
                                      'udpPort',    app.EB500Obj.udpPort);
                    [newArray, Flag_success] = EB500Lib.DatagramRead_Task(taskInfo, hReceiver, hStreaming);
            end
            flush(hReceiver)
            
            if Flag_success
                if app.specObj(ii).Band(jj).FlipArray
                    newArray = flip(newArray);
                end
            else
                error('Não foi lido corretamente o vetor de nível do receptor dentro do tempo limite (10 segundos).')
            end            
        end


        function PlotTask(app, ii, jj, newArray)
            FreqStart  = app.specObj(ii).taskObj.General.Task.Band(jj).FreqStart / 1e+6;
            FreqStop   = app.specObj(ii).taskObj.General.Task.Band(jj).FreqStop  / 1e+6;
            DataPoints = app.specObj(ii).Band(jj).DataPoints;

            Tag = sprintf('%.3f - %.3f (%.0f points)', FreqStart, FreqStop, DataPoints);

%             TimeStamp = datetime('now');
%             app.specMatrix.Data{1}(ind)   = datetime('now');
%             app.specMatrix.Data{2}(ind,:) = newArray;
% 
%             app.specMatrix.SampleTime  = ((app.General.SampleTimeFactor-1)*app.specMatrix.SampleTime + str2double(datestr(app.specMatrix.Data{1}(indMatrix(end))-app.specMatrix.Data{1}(indMatrix(end-1)), 'ss.FFF'))*1000) / app.General.SampleTimeFactor;


            tempObj = findobj(app.axes1, 'Tag', Tag);
            if isempty(tempObj)
                plot(app.axes1, linspace(FreqStart, FreqStop, DataPoints), newArray, 'Tag', Tag);
            else
                tempObj.YData = newArray;
            end

            % PENDENTE ATUALIAÇÃO DO WATERFALL
        end


        function RegularTask_StartUp(app, idx)
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html', 'Message', 'Em andamento...');

            taskObj  = app.specObj(idx).taskObj;
            baseName = sprintf('appColeta_%s', datestr(now, 'yymmdd_THHMMSS'));

            % HANDLES
            hReceiver  = app.specObj(idx).hReceiver;
            hStreaming = app.specObj(idx).hStreaming;
            hGPS       = app.specObj(idx).hGPS;

            % RECEIVER TEST
            flush(hReceiver)

            writeline(hReceiver, '*IDN?'); 
            pause(.1); 
            IDN = replace(deblank(read(hReceiver, hReceiver.NumBytesAvailable, 'char')), '"', '');
            
%             IDN = replace(deblank(writeread(hReceiver, '*IDN?')), '"', '');
            if ~strcmp(hReceiver.UserData.IDN, IDN)
                error('Não se trata do instrumento originalmente inserido na tarefa.\nEsperado: %s\nIdentificado: %s', hReceiver.UserData.IDN, IDN)
            end

            % RECEIVER TEST (DATAGRAMS ESTIMATION)
            if ~isempty(hStreaming)
                if contains(hReceiver.UserData.IDN, 'EB500') && isempty(app.specObj(idx).Band(1).Datagrams)
                    app.specObj(idx) = EB500Lib.DatagramRead_PreTask(app.EB500Obj, app.specObj(idx), hReceiver, hStreaming);
                end
            end

            % GPS TEST
            if ~isempty(hGPS)
                % TESTAR GPS EXTERNO
            end

            for ii = 1:numel(app.specObj(idx).Band)
                ThreadID = taskObj.General.Task.Band(ii).ThreadID;

                % ANTENNA SWITCH & ACU TESTS
                if strcmp(taskObj.Antenna.Switch, 'EMSat')
                    Antenna = jsondecode(app.specObj(idx).Band(ii).Antenna);
                    idxFlip = find(strcmp(app.EMSatObj.LNB.Name, Antenna.Name), 1);

                    app.specObj(idx).Band(ii).FlipArray = app.EMSatObj.LNB.Inverted(idxFlip);

%                     app.auxWin2 = winTracking(app, idx);
%                     drawnow
                end

                % MASK
                app.specObj(idx).Band(ii).Mask = [];
                if strcmp(taskObj.General.Type, 'Rompimento de Máscara Espectral') && taskObj.General.Task.Band(ii).Trigger
                    maskInfo = mask_FileRead(taskObj.General.Maskfile);

                    app.specObj(idx).Band(ii).Mask = struct('Array', mask_ArrayConstructor(maskInfo, taskObj.General.Task.Band(ii)), ...
                                                            'Validations', 0,                                                        ...
                                                            'BrokenArray', zeros(1, taskObj.General.Task.Band(ii).instrDataPoints),  ...
                                                            'BrokenCount', 0,                                                        ...
                                                            'MainPeaks', '',                                                         ...
                                                            'TimeStamp', NaT);
                    app.specObj(idx).LOG(end+1)    = struct('type', 'mask', 'time', datestr(now, 'dd/mm/yyyy HH:MM:SS'), 'msg', sprintf('ID %.0f\n%s', ThreadID, jsonencode(maskInfo.Table)));
                end


                % FILE
                app.specObj(idx).Band(ii).File = struct('Fileversion', app.General.File.Version,              ...
                                                        'Basename', sprintf('%s_ID%.0f', baseName, ThreadID), ...
                                                        'Filecount', 0, 'WritedSamples', 0, 'CurrentFile', []);

                [app.specObj(idx).Band(ii).File.Filecount, ...
                    app.specObj(idx).Band(ii).File.CurrentFile] = RFlookBinLib.OpenFile(app.specObj(idx), ii);

                logMsg = sprintf(['ID: %.0f\n'             ...
                                  'scpiSet_Config: "%s"\n' ...
                                  'scpiSet_Att: "%s"\n'    ...
                                  'scpiSet_Answer: "%s"\n' ...
                                  'Filename (base): %s\n'  ...
                                  'AlocatedSamples: %.0f'], ThreadID,                                 ...
                                                            app.specObj(idx).Band(ii).scpiSet_Config, ...
                                                            app.specObj(idx).Band(ii).scpiSet_Att,    ...
                                                            app.specObj(idx).Band(ii).scpiSet_Answer, ...
                                                            app.specObj(idx).Band(ii).File.Basename,  ...
                                                            app.specObj(idx).Band(ii).File.CurrentFile.AlocatedSamples);

                app.specObj(idx).LOG(end+1) = struct('type', 'startup', 'time', datestr(now, 'dd/mm/yyyy HH:MM:SS'), 'msg', logMsg);


                % WATERFALL MATRIX
                WaterfallDepth = app.General.Waterfall.Depth;   
                DataPoints     = taskObj.General.Task.Band(ii).instrDataPoints;

                switch taskObj.General.Task.Band(ii).instrLevelUnit
                    case 'dBm';            refLevel = -120;
                    case {'dBµV', 'dBμV'}; refLevel = -13;
                end
                
                if strcmp(taskObj.General.Task.Observation.Type, 'Samples')
                    WaterfallDepth  = min([WaterfallDepth,  taskObj.General.Task.Band(ii).instrObservationSamples]);
                end
                app.specObj(idx).Band(ii).Waterfall = struct('idx', 0, 'Matrix', refLevel .* ones(WaterfallDepth, DataPoints, 'single'));
            end
            delete(d)
        end


        function logMsg = Misc_logMsg(app, idx)
            logMsg = '';
            if ~isempty(app.specObj(idx).LOG)
                logTable = struct2table(app.specObj(idx).LOG);
                logMsg   = strjoin("<b>" + logTable.time + " - " + upper(logTable.type) + "</b>" + newline + logTable.msg, '\n\n');
            end
        end

    end

    
    methods (Access = public)

        function Misc_WindowBackground(app, Type)
            switch Type
                case 'Create'
                    uihtml(app.UIFigure, "HTMLSource", '<html><body style="background-color: rgb(0, 0 ,0, 0.45);"></body></html>', ...
                                         "Position", [1, 1, app.UIFigure.Position(3:4)], ...
                                         "Tag", "WindowBackground");
                case 'Delete'
                    delete(findobj(app.UIFigure, "Tag", "WindowBackground"))
            end
        end

        
        function winAux_AddOrEditTask(app, taskObj)
            delete(app.auxWin1); app.auxWin1 = [];
            Misc_WindowBackground(app, 'Delete')            

            [app.specObj, idx] = Fcn_AddTask(app.specObj, taskObj);
            if ~isempty(app.specObj(idx).LOG)
                MessageBox(app, 'info', Misc_logMsg(app, idx))
            end

            Timer_RegularTaskCheck(app)
            
            uiTable_Building(app)
            drawnow nocallbacks
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.UIFigure.Visible = 0;            
            appName = 'appColeta_Task';
                        
            % PATH SEARCH
            Flag = 1;
            if isdeployed
                [~, result]    = system('path');
                app.RootFolder = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));

                if ~isfile(fullfile(app.RootFolder, sprintf('%s.exe', appName)))
                    Flag = 0;
                    MessageBox(app, 'startup', 'Não identificado local de instalação do <i>app</i>.')
                end
                
            else
                prjPath = matlab.project.rootProject;
                appPath = fullfile(char(com.mathworks.appmanagement.MlappinstallUtil.getAppInstallationFolder), appName);
                
                if ~isempty(prjPath) & strcmp(prjPath.Name, appName)
                    app.RootFolder = char(prjPath.RootFolder);
                elseif isfolder(appPath)
                    app.RootFolder = appPath;                    
                else
                    Flag = 0;
                    MessageBox(app, 'startup', 'Não identificado local de instalação do <i>app</i>.')
                end
            end

            % INITIALIZATION            
            if Flag
                try
                    % <LOG>
                    diary(fullfile(app.RootFolder, 'Debug', '_log.txt'))
                    
                    diary on
                    fprintf(sprintf('<LogEntry>\n<BeginTime>%s</BeginTime>\n', datestr(now,'dd/mm/yyyy HH:MM:SS')))
                    % </LOG>

                    warning('off', 'MATLAB:ui:javaframe:PropertyToBeRemoved')
                    warning('off', 'MATLAB:subscripting:noSubscriptsSpecified')
                    warning('off', 'MATLAB:structOnObject')
                    warning('off', 'MATLAB:class:DestructorError')

                    startup_Layout(app)
                    startup_AxesCreation(app)
                    startup_ConfigFiles(app)
                    startup_Timer(app)

                catch ME
                    fprintf('%s\n', jsonencode(ME))
                    MessageBox(app, 'startup', getReport(ME))
                end

                app.UIFigure.Visible = 1;
                drawnow nocallbacks
            end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
%             if app.Flag_running
%                 msg = 'Existe uma tarefa em execução...';
%                 MessageBox(app, 'warning', msg)
% 
%                 return
%             end

            delete(app.auxWin1)
            delete(app.auxWin2)
            delete(app.auxWin3)

            h = timerfindall;
            stop(h); delete(h); clear h

            delete(app)
            
        end

        % Callback function: UITable
        function UITableSelectionChanged(app, event)

            uiTree_Building(app, 1)
            drawnow nocallbacks
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)

            cla(app.axes1)
            cla(app.axes2)

            ii = app.UITable.Selection;
            jj = app.Tree.SelectedNodes.NodeData;
            
            uiTree_Style(app, ii)

            if ~isempty(app.specObj(ii).Band(jj).Waterfall)
                idx = app.specObj(ii).Band(jj).Waterfall.idx;

                if idx
                    newArray = app.specObj(ii).Band(jj).Waterfall.Matrix(idx,:);
                    PlotTask(app, ii, jj, newArray)
                end
            end

            % INFORMAÇÕES DA TAREFA QUE SÃO ATUALIZADAS EM TEMPO REAL
            % (LAYOUT)
            Task = app.specObj(ii).taskObj.General.Task;

            sRevisitTime = sprintf('%.2f seg', Task.Band(jj).RevisitTime);
            try
                if app.revisitObj.Band(ii).RevisitFactors(jj+1) ~= -1
                    sRevisitTime = sprintf('%.2f → %.2f seg (normalizado)', Task.Band(jj).RevisitTime, ...
                                                                            app.revisitObj.GlobalRevisitTime * app.revisitObj.Band(ii).RevisitFactors(jj+1));
                end
            catch
            end

            app.MetaData.HTMLSource = sprintf(['<font style="font-family: Helvetica; font-size: 10px;">'  ...
                                               '<b style="color: #808080;">DESCRIÇÃO: </b>%s<br><br>',    ...
                                               '<b style="color: #808080;">PASSO: </b>%.3f kHz<br>',      ...
                                               '<b style="color: #808080;">PONTOS: </b>%.0f<br>',         ...
                                               '<b style="color: #808080;">RESOLUÇÃO: </b>%s<br>',        ...
                                               '<b style="color: #808080;">DETECTOR: </b>%s<br><br>',     ...
                                               '<b style="color: #808080;">TRAÇO: </b>%s<br>',            ...
                                               '<b style="color: #808080;">INTEGRAÇÃO: </b>%.0f<br><br>', ...
                                               '<b style="color: #808080;">REVISITA: </b>%s</font>'],     ...
                                               Task.Band(jj).Description,           ...
                                               Task.Band(jj).instrStepWidth / 1e+3, ...
                                               Task.Band(jj).instrDataPoints,       ...
                                               Task.Band(jj).instrResolution,       ...
                                               Task.Band(jj).instrDetector,         ...
                                               Task.Band(jj).TraceMode,             ...
                                               Task.Band(jj).IntegrationFactor,     ...                                               
                                               sRevisitTime);

            if ~isempty(app.specObj(ii).Band(jj).File); WritedSamples = app.specObj(ii).Band(jj).File.WritedSamples;
            else;                                       WritedSamples = -1; 
            end
            app.Channel.Text = string(WritedSamples);

            if strcmp(app.specObj(ii).Status, 'Em andamento...') && app.specObj(ii).Band(jj).Status
                app.tool_REC.Visible = 1;
            else
                app.tool_REC.Visible = 0;
            end

            if ~isempty(app.specObj(ii).Band(jj).Mask)
                app.lastMask_text.Enable = 1;
                Layout_lastMaskValidation(app, true, ii, jj)
            else
                app.lastMask_text.Enable = 0;
                app.lastMask_text.Text = {'<b style="color: #a2142f; font-size: 14;">-1</b> '; 'VALIDAÇÕES '; '<b style="color: #a2142f; font-size: 14;">-1</b> '; 'ROMPIMENTOS '; ''; '<font style="color: #a2142f;">-1.000 MHz '; '⌂ -1.000 kHz </font>'; ''; 'dd/mm/yyyy '; 'HH:MM:SS '};
            end
            drawnow
            
            Layout_lastGPS(app, app.specObj(ii).lastGPS)            
            
        end

        % Image clicked function: table_Del, table_Edit, table_New, 
        % table_Play, table_Stop
        function TableToolbarCallbacks(app, event)
            
            switch event.Source
                case app.table_Play
                    if ~isempty(app.specObj)
                        RegularTask_MainLoop(app)
                    end

                case app.table_Stop
                    app.Flag_running = 0;                    

                case {app.table_New, app.table_Edit}
                        d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html', 'Message', '<font style="font-size:12;">Em andamento...</font>');
        
                        switch event.Source
                            case app.table_New;  tempVar = struct('type', 'new');
                            case app.table_Edit; tempVar = struct('type', 'edit', 'idx', app.UITable.Selection);
                        end        
                        app.auxWin1 = winTask(app, tempVar);
                        drawnow

                        Misc_WindowBackground(app, 'Create')
                        delete(d)

                case app.table_Del
                    idx = app.UITable.Selection;

                    if ismember(app.specObj(idx).Status, ["Na fila...", "Em andamento...", "Erro"])
                        if strcmp(app.specObj(idx).Status , 'Em andamento...')
                            app.Flag_editing = 1;
                        end
                        app.specObj(idx).Status = "Cancelada";
                    end
            end

        end

        % Image clicked function: table_Del_2
        function table_Del_2ImageClicked(app, event)
            
            pause(1)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta: Tarefas';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.table_Grid = uigridlayout(app.GridLayout);
            app.UITable = uitable(app.table_Grid);
            app.UITable.ColumnName = {''; ''; ''; ''; ''; ''; ''};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.Multiselect = 'off';
            app.Column1 = uilabel(app.table_Grid);
            app.Column1.Text = {'  ID'; '  '};
            app.Column2 = uilabel(app.table_Grid);
            app.Column2.Text = {'  Tarefa'; '  '};
            app.Column5 = uilabel(app.table_Grid);
            app.Column5.Text = {'  Receptor'; '  '};
            app.Column3 = uilabel(app.table_Grid);
            app.Column3.Text = {'   Inclusão'; '  '};
            app.Column7 = uilabel(app.table_Grid);
            app.Column7.Text = {'  Início'; '  Observação'};
            app.Column8 = uilabel(app.table_Grid);
            app.Column8.Text = {'  Fim'; '  Observação'};
            app.Column10 = uilabel(app.table_Grid);
            app.Column10.Text = {'  Estado'; '  '};
            app.Plot_Panel = uipanel(app.GridLayout);
            app.FaixasdefrequnciarelacionadastarefaselecionadaLabel = uilabel(app.GridLayout);
            app.FaixasdefrequnciarelacionadastarefaselecionadaLabel.Text = 'Faixa(s) de frequência relacionada(s) à tarefa selecionada:';
            app.Export_Grid = uigridlayout(app.GridLayout);
            app.table_Del = uiimage(app.Export_Grid);
            app.table_Del.ImageClickedFcn = createCallbackFcn(app, @TableToolbarCallbacks, true);
            app.table_Del.Tooltip = {'Excluir tarefa'};
            app.table_Del.ImageSource = 'LT_redX.png';
            app.table_New = uiimage(app.Export_Grid);
            app.table_New.ImageClickedFcn = createCallbackFcn(app, @TableToolbarCallbacks, true);
            app.table_New.Tooltip = {'Nova tarefa'};
            app.table_New.ImageSource = 'LT_AddFiles.png';
            app.table_Edit = uiimage(app.Export_Grid);
            app.table_Edit.ImageClickedFcn = createCallbackFcn(app, @TableToolbarCallbacks, true);
            app.table_Edit.Tooltip = {'Editar tarefa'};
            app.table_Edit.ImageSource = 'LT_edit.png';
            app.table_Stop = uiimage(app.Export_Grid);
            app.table_Stop.ImageClickedFcn = createCallbackFcn(app, @TableToolbarCallbacks, true);
            app.table_Stop.Tooltip = {'Parar tarefa'};
            app.table_Stop.ImageSource = 'LT_stop.png';
            app.table_Play = uiimage(app.Export_Grid);
            app.table_Play.ImageClickedFcn = createCallbackFcn(app, @TableToolbarCallbacks, true);
            app.table_Play.Tooltip = {'Executar tarefa'};
            app.table_Play.ImageSource = 'LT_play.png';
            app.table_Separator = uiimage(app.Export_Grid);
            app.table_Separator.ImageSource = 'LT_LineV.png';
            app.table_Separator_2 = uiimage(app.Export_Grid);
            app.table_Separator_2.ImageSource = 'LT_LineV.png';
            app.table_Del_2 = uiimage(app.Export_Grid);
            app.table_Del_2.ImageClickedFcn = createCallbackFcn(app, @table_Del_2ImageClicked, true);
            app.table_Del_2.Tooltip = {'LOG tarefa'};
            app.table_Del_2.ImageSource = 'LT_log.png';
            app.Tree = uitree(app.GridLayout);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.Panel_5 = uipanel(app.GridLayout3);
            app.GridLayout2_2 = uigridlayout(app.Panel_5);
            app.GridLayout2_2.Tag = 'COLORLOCKED';
            app.ChannelLabel = uilabel(app.GridLayout2_2);
            app.ChannelLabel.Text = {'VARREDURAS'; 'EM ARQUIVO'};
            app.tool_REC = uiimage(app.GridLayout2_2);
            app.tool_REC.Visible = 'off';
            app.tool_REC.ImageSource = 'REC.gif';
            app.Channel = uilabel(app.GridLayout2_2);
            app.Channel.Text = '-1';
            app.Panel_6 = uipanel(app.GridLayout3);
            app.GridLayout2_3 = uigridlayout(app.Panel_6);
            app.GridLayout2_3.Tag = 'COLORLOCKED';
            app.lastGPS_label = uilabel(app.GridLayout2_3);
            app.lastGPS_label.Text = {'COORDENADAS'; 'GEOGRÁFICAS'};
            app.lastGPS_text = uilabel(app.GridLayout2_3);
            app.lastGPS_text.Interpreter = 'html';
            app.lastGPS_text.Text = {'<b style="color: #a2142f; font-size: 14;">-1.000</b> LAT '; '<b style="color: #a2142f; font-size: 14;">-1.000</b> LON '; ''; 'dd/mm/yyyy '; 'HH:MM:SS '};
            app.GridLayout7 = uigridlayout(app.GridLayout2_3);
            app.lastGPS_color = uilamp(app.GridLayout7);
            app.Panel_7 = uipanel(app.GridLayout3);
            app.GridLayout2_6 = uigridlayout(app.Panel_7);
            app.GridLayout2_6.Tag = 'COLORLOCKED';
            app.lastMask_label = uilabel(app.GridLayout2_6);
            app.lastMask_label.Text = {'MÁSCARA'; 'ESPECTAL'};
            app.lastMask_text = uilabel(app.GridLayout2_6);
            app.lastMask_text.Interpreter = 'html';
            app.lastMask_text.Enable = 'off';
            app.lastMask_text.Text = {'<b style="color: #a2142f; font-size: 14;">-1</b> '; 'VALIDAÇÕES '; '<b style="color: #a2142f; font-size: 14;">-1</b> '; 'ROMPIMENTOS '; ''; '<font style="color: #a2142f;">-1.000 MHz '; '⌂ -1.000 kHz </font>'; ''; 'dd/mm/yyyy '; 'HH:MM:SS '};
            app.MetadadosrelacionadosfaixaselecionadaLabel = uilabel(app.GridLayout);
            app.MetadadosrelacionadosfaixaselecionadaLabel.Text = 'Metadados relacionados à faixa selecionada:';
            app.MetaData_Panel = uipanel(app.GridLayout);
            app.MetaData_Grid = uigridlayout(app.MetaData_Panel);
            app.MetaData = uihtml(app.MetaData_Grid);
            app.MetaData.HTMLSource = ' ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winMain

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end