classdef winTask < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        mainGrid                    matlab.ui.container.GridLayout
        RightPanel                  matlab.ui.container.Panel
        RightPanel_Grid             matlab.ui.container.GridLayout
        MainButton                  matlab.ui.control.Button
        Band_Panel                  matlab.ui.container.Panel
        Band_Grid                   matlab.ui.container.GridLayout
        AntenaLabel                 matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        Band_Antenna_Grid           matlab.ui.container.GridLayout
        Band_Target                 matlab.ui.control.DropDown
        Band_TargetLabel            matlab.ui.control.Label
        Band_Antenna                matlab.ui.control.DropDown
        Band_AntennaLabel           matlab.ui.control.Label
        Band_SubPanel               matlab.ui.container.Panel
        Band_SubGrid                matlab.ui.container.GridLayout
        Band_Detector               matlab.ui.control.DropDown
        Band_DetectorLabel          matlab.ui.control.Label
        Band_IntegrationTime        matlab.ui.control.NumericEditField
        Band_IntegrationTimeLabel   matlab.ui.control.Label
        Band_Preamp                 matlab.ui.control.DropDown
        Band_PreampLabel            matlab.ui.control.Label
        Band_attValue               matlab.ui.control.DropDown
        Band_attValueLabel          matlab.ui.control.Label
        Band_attMode                matlab.ui.control.DropDown
        Band_attModeLabel           matlab.ui.control.Label
        Band_Resolution             matlab.ui.control.DropDown
        Band_ResolutionLabel        matlab.ui.control.Label
        Band_Selectivity            matlab.ui.control.DropDown
        Band_SelectivityLabel       matlab.ui.control.Label
        Band_DataPoints2            matlab.ui.control.NumericEditField
        Band_DataPoints1            matlab.ui.control.Spinner
        Band_DataPointsLabel        matlab.ui.control.Label
        Band_StepWidth2             matlab.ui.control.DropDown
        Band_StepWidth1             matlab.ui.control.NumericEditField
        Band_StepWidthLabel         matlab.ui.control.Label
        Band_Label                  matlab.ui.control.Label
        Band_Samples                matlab.ui.control.NumericEditField
        Band_SamplesLabel           matlab.ui.control.Label
        Band_PanelLabel_Grid        matlab.ui.container.GridLayout
        Band_Refresh                matlab.ui.control.Image
        Band_PanelLabel             matlab.ui.control.Label
        MetaData_Panel              matlab.ui.container.Panel
        MetaData_Grid               matlab.ui.container.GridLayout
        MetaData                    matlab.ui.control.HTML
        MetaDataLabel               matlab.ui.control.Label
        Band_Tree                   matlab.ui.container.Tree
        Band_TreeLabel              matlab.ui.control.Label
        LeftPanel                   matlab.ui.container.Panel
        LeftPanel_Grid              matlab.ui.container.GridLayout
        Tab3_Panel                  matlab.ui.container.GridLayout
        AntennaList_Tree            matlab.ui.container.Tree
        AddAntenna_Image            matlab.ui.control.Image
        AntennaName                 matlab.ui.control.DropDown
        AntennaNameLabel            matlab.ui.control.Label
        Antenna_Panel               matlab.ui.container.Panel
        Antenna_Grid                matlab.ui.container.GridLayout
        Antenna_TrackingMode        matlab.ui.control.DropDown
        Antenna_TrackingModeLabel   matlab.ui.control.Label
        AntennaPolarizationLabel    matlab.ui.control.Label
        AntennaPolarization         matlab.ui.control.NumericEditField
        AntennaAzimuth_Grid         matlab.ui.container.GridLayout
        AntennaAzimuthRef           matlab.ui.control.DropDown
        AntennaAzimuthLabel         matlab.ui.control.Label
        AntennaAzimuth              matlab.ui.control.NumericEditField
        AntennaElevationLabel       matlab.ui.control.Label
        AntennaElevation            matlab.ui.control.NumericEditField
        AntennaHeightLabel          matlab.ui.control.Label
        AntennaHeight               matlab.ui.control.NumericEditField
        AntennaSwitch_Grid          matlab.ui.container.GridLayout
        AntennaSwitch_Name          matlab.ui.control.EditField
        AntennaSwitch_Mode          matlab.ui.control.CheckBox
        AntennaSwitch_Connectivity  matlab.ui.control.Button
        Tab3_Grid                   matlab.ui.container.GridLayout
        Tab3_Image                  matlab.ui.control.Image
        Tab3_Title                  matlab.ui.control.Label
        Tab2_Panel                  matlab.ui.container.GridLayout
        GPS_Panel                   matlab.ui.container.Panel
        GPS_Grid                    matlab.ui.container.GridLayout
        GPS_manualLongitude         matlab.ui.control.NumericEditField
        GPS_manualLongitudeLabel    matlab.ui.control.Label
        GPS_manualLatitude          matlab.ui.control.NumericEditField
        GPS_manualLatitudeLabel     matlab.ui.control.Label
        GPS_RevisitTime             matlab.ui.control.NumericEditField
        GPS_RevisitTimeLabel        matlab.ui.control.Label
        GPS_ListGrid                matlab.ui.container.GridLayout
        GPS_Connectivity            matlab.ui.control.Button
        GPS_List                    matlab.ui.control.DropDown
        GPS_ListLabel               matlab.ui.control.Label
        Receiver_Panel              matlab.ui.container.Panel
        Receiver_Grid               matlab.ui.container.GridLayout
        Receiver_SyncRef            matlab.ui.control.DropDown
        Receiver_SyncRefLabel       matlab.ui.control.Label
        Receiver_RstCommand         matlab.ui.control.DropDown
        Receiver_RstCommandLabel    matlab.ui.control.Label
        Receiver_ListGrid           matlab.ui.container.GridLayout
        Receiver_ListLabel          matlab.ui.control.Label
        Receiver_List               matlab.ui.control.DropDown
        Receiver_Connectivity       matlab.ui.control.Button
        Tab2_Grid                   matlab.ui.container.GridLayout
        Tab2_Image                  matlab.ui.control.Image
        Tab2_Title                  matlab.ui.control.Label
        Tab1_Panel                  matlab.ui.container.GridLayout
        BitsPerPointLabel           matlab.ui.control.Label
        BitsPerPoint                matlab.ui.control.DropDown
        ObservationPanel            matlab.ui.container.Panel
        ObservationPanel_Grid       matlab.ui.container.GridLayout
        Duration_Grid               matlab.ui.container.GridLayout
        DurationUnit                matlab.ui.control.DropDown
        Duration                    matlab.ui.control.NumericEditField
        ObservationTypeLabel        matlab.ui.control.Label
        ObservationType             matlab.ui.control.DropDown
        SpecificTime_Grid           matlab.ui.container.GridLayout
        SpecificTime_Mark2          matlab.ui.control.Label
        SpecificTime_Mark1          matlab.ui.control.Label
        SpecificTime_Spinner4       matlab.ui.control.Spinner
        SpecificTime_Spinner3       matlab.ui.control.Spinner
        SpecificTime_DatePicker2    matlab.ui.control.DatePicker
        SpecificTime_Spinner2       matlab.ui.control.Spinner
        SpecificTime_Spinner1       matlab.ui.control.Spinner
        SpecificTime_DatePicker1    matlab.ui.control.DatePicker
        ObservationLabel            matlab.ui.control.Label
        TaskType_Grid               matlab.ui.container.GridLayout
        TaskTypeLabel               matlab.ui.control.Label
        TaskType                    matlab.ui.control.DropDown
        MaskFile_Button             matlab.ui.control.Button
        AddMaskFile_Button          matlab.ui.control.Button
        TaskNameLabel               matlab.ui.control.Label
        TaskName                    matlab.ui.control.DropDown
        Tab1_Grid                   matlab.ui.container.GridLayout
        Tab1_Image                  matlab.ui.control.Image
        Tab1_Title                  matlab.ui.control.Label
        ContextMenu                 matlab.ui.container.ContextMenu
        ExcluirMenu                 matlab.ui.container.Menu
    end


    properties (Access = public)
        
        CallingApp
        RootFolder

        taskList
        scpiList
        
        instrInfo
        EB500Mapping
        switchList
        satelliteList

    end


    methods (Access = private)

        function startup_ModeOperation(app, tempVar)
            
            % app.taskList
            switch tempVar.type
                case 'new';  idx1 = 1;
                case 'edit'; idx1 = tempVar.idx;
            end
            
            app.TaskName.Items = {};
            for ii = 1:numel(app.taskList)
                app.TaskName.Items(end+1) = {app.taskList{ii}.Name};
    
                NN = numel(app.taskList{ii}.Band);
                for jj = NN:-1:1
                    if app.taskList{ii}.Band(jj).Enable
                        app.taskList{ii}.Band(jj).EditedFlag = 0;
                    else
                        app.taskList{ii}.Band(jj) = [];
                    end
                end
            end
            app.TaskName.Value = app.TaskName.Items{idx1};


            % app.scpiList
            idx2 = intersect(find(app.scpiList.Family == "Receptor"), find(app.scpiList.Enable))';

            app.Receiver_List.Items = {};
            for ii = idx2
                Parameters = ConnectivityTest_MissingParameters(app, ii);

                if ~isempty(Parameters.IP) && ~isempty(Parameters.Port)
                    Socket = sprintf('%s:%s', Parameters.IP, Parameters.Port);
                elseif ~isempty(Parameters.IP)
                    Socket = Parameters.IP;
                else
                    Socket = Parameters.Port;
                end

                app.Receiver_List.Items(end+1) = {sprintf('ID %d: %s - %s', ii, app.scpiList.Name{ii}, Socket)};
            end

            General_Task(app)

        end


        function MaskInfo = General_SpectralMask_FileRead(app, maskText)
                
            MaskInfo.Table           = struct2table(regexp(maskText, '(?<FreqStart>\d*),(?<FreqStop>\d*),(?<THR>[-]{0,1}\d*)', 'names'));
            MaskInfo.Table.FreqStart = str2double(MaskInfo.Table.FreqStart) / 1e+3;
            MaskInfo.Table.FreqStop  = str2double(MaskInfo.Table.FreqStop)  / 1e+3;
            MaskInfo.Table.THR       = str2double(MaskInfo.Table.THR);

            MaskInfo.THR       = MaskInfo.Table.THR;
            MaskInfo.FreqStart = MaskInfo.Table.FreqStart(1);
            MaskInfo.FreqStop  = MaskInfo.Table.FreqStop(end);

            MaskInfo.unmasked  = table('Size', [height(MaskInfo.Table)-1, 3],         ...
                                       'VariableTypes', {'double', 'double', 'cell'}, ...
                                       'VariableNames', {'Frequency', 'BW', 'Source'});

            MaskInfo.unmasked.BW(:)        = MaskInfo.Table.FreqStart(2:end)  - MaskInfo.Table.FreqStop(1:end-1);
            MaskInfo.unmasked.Frequency(:) = MaskInfo.Table.FreqStop(1:end-1) + MaskInfo.unmasked.BW/2;
            MaskInfo.unmasked.Source(:)    = {'refMask'};

        end


        function Parameters = ConnectivityTest_MissingParameters(app, idx)

            Parameters = jsondecode(app.scpiList.Parameters{idx});

            if ~isfield(Parameters, 'IP')
                Parameters.IP = '';
            end

            if ~isfield(Parameters, 'Port')
                Parameters.Port = '';
            end

        end


        function [instrHandle, errorMsg] = ConnectivityTest_ResponseAnalysis(app, instrStruct, MessageBoxFlag)

            switch instrStruct.type
                case 'handles'
                    app.CallingApp.instrHandles = instrStruct.instrHandles;

                    instrHandle = instrStruct.instrHandles.Handle{instrStruct.idx};
                    errorMsg    = '';

                    if MessageBoxFlag; MessageBox(app, 'info', sprintf('Conectado ao %s', instrStruct.instrHandles.IDN(instrStruct.idx)))
                    end

                case 'error'
                    instrHandle = [];
                    errorMsg    = instrStruct.msg;

                    if MessageBoxFlag; MessageBox(app, 'error', instrStruct.msg)
                    end
            end

        end


        function [instrHandle, errorMsg] = ConnectivityTest_Receiver_Aux(app, MessageBoxFlag)

            idx1 = str2double(char(extractBetween(app.Receiver_List.Value, 'ID', ':')));
            idx2 = find(app.instrInfo.Name == string(extractBetween(app.Receiver_List.Value, ': ', ' -')), 1);

            Parameters    = ConnectivityTest_MissingParameters(app, idx1);
            instrSelected = struct('Type', app.scpiList.Type{idx1}, ...
                                   'Tag',  app.instrInfo.Tag{idx2}, ...
                                   'IP',   Parameters.IP,           ...
                                   'Port', str2double(Parameters.Port));

            [instrHandle, errorMsg] = ConnectivityTest_ResponseAnalysis(app, connect_Receiver(app.CallingApp.instrHandles, instrSelected), MessageBoxFlag);

        end


        function [instrHandle, errorMsg] = ConnectivityTest_GPS_Aux(app)

            idx = str2double(char(extractBetween(app.GPS_List.Value, 'ID', ':')));
            Parameters = ConnectivityTest_MissingParameters(app, idx);

            switch app.scpiList.Type{idx}
                case 'Serial'
                    instrSelected = struct('Type', app.scpiList.Type{idx}, 'Port', Parameters.Port, 'BaudRate', 9600);
                case 'TCPIP Socket'
                    instrSelected = struct('Type', app.scpiList.Type{idx}, 'Port', Parameters.Port, 'IP', Parameters.IP);
            end

            [instrHandle, errorMsg] = ConnectivityTest_ResponseAnalysis(app, connect_gps(app.CallingApp.instrHandles, instrSelected), 0);

        end


        function AntennaConfig_Layout(app, Type)

            switch Type
                case "Enable"
                    set(findobj('Parent', app.Antenna_Grid, '-not', 'Type', 'uigrid'), 'Enable', 1)
                    set(app.AntennaAzimuth_Grid.Children, 'Enable', 1)                                

                case "Disable"
                    set(findobj('Parent', app.Antenna_Grid,        '-not', {'Type', 'uigrid', '-or', 'Type', 'uilabel'}), 'Enable', 0)
                    set(findobj('Parent', app.AntennaAzimuth_Grid, '-not', 'Type', 'uilabel'),                            'Enable', 0)
            end

        end


        function BandView_TreeBuilding(app, ind1)

            delete(app.Band_Tree.Children);

            for ii = 1:numel(app.taskList{ind1}.Band)
                str = sprintf('ID %d: %.3f - %.3f MHz', app.taskList{ind1}.Band(ii).ThreadID,          ...
                                                        app.taskList{ind1}.Band(ii).FreqStart ./ 1e+6, ...
                                                        app.taskList{ind1}.Band(ii).FreqStop  ./ 1e+6);

                node = uitreenode(app.Band_Tree, 'Text', str, 'NodeData', ii, 'Icon', 'LT_playback.png');

                if (app.TaskType.Value == "Rompimento de Máscara Espectral") & app.taskList{ind1}.Band(ii).Trigger
                    node.Icon = "LT_OCC.png";
                end
            end
            app.Band_Tree.SelectedNodes = app.Band_Tree.Children(1);

        end


        function BandView_SatelliteList(app)
            
            idx = [];
            if ~isempty(app.Band_Target.Value)
                idx = find(string(app.satelliteList.Satellite) == app.Band_Target.Value, 1);
            end

            if ~isempty(idx)
                set(app.Band_Antenna, 'Items', [{''}; struct2table(app.satelliteList.Antennas{idx}).Name], 'Value', '')
            else
                set(app.Band_Antenna, 'Items', app.switchList.Antennas{2}, 'Value', '')
            end

        end


        function BandView_EditablesParameters_Visibility(app)

%           idx1 = find(strcmp(app.TaskName.Items, app.TaskName.Value), 1);
%           idx2 = app.Band_Tree.SelectedNodes.NodeData;
            idx3 = find(app.instrInfo.Name == string(extractBetween(app.Receiver_List.Value, ': ', ' -')), 1);

            switch app.instrInfo.connectFlag{idx3}
                case 1
                    app.Band_SubGrid.RowHeight(2:3)    = {22,0};
                    set(findobj(groot, 'Parent', app.Band_SubGrid, 'Tag', 'task_Set1'), 'Enable', 1)
                    set(findobj(groot, 'Parent', app.Band_SubGrid, 'Tag', 'task_Set2'), 'Enable', 0)

                    app.Band_Preamp.Enable                = 1;
                    
                    app.Band_SelectivityLabel.Visible     = 0;
                    app.Band_Selectivity.Visible          = 0;

                    app.Band_ResolutionLabel.Layout.Column = 1;
                    app.Band_Resolution.Layout.Column      = 1;

                    app.Band_IntegrationTimeLabel.Visible  = 0;
                    app.Band_IntegrationTime.Visible       = 0;

                case 2
                    app.Band_SubGrid.RowHeight(2:3)    = {0,22};
                    set(findobj(groot, 'Parent', app.Band_SubGrid, 'Tag', 'task_Set1'), 'Enable', 0)
                    set(findobj(groot, 'Parent', app.Band_SubGrid, 'Tag', 'task_Set2'), 'Enable', 1)

                    app.Band_Preamp.Enable                = 0;

                    app.Band_SelectivityLabel.Visible     = 1;
                    app.Band_Selectivity.Visible          = 1;

                    app.Band_ResolutionLabel.Layout.Column = 2;
                    app.Band_Resolution.Layout.Column      = 2;
            end

        end


        function instrSettings = BandView_EditablesParameters_GetValues(app, idx1, idx2)

%           idx1 = find(strcmp(app.TaskName.Items, app.TaskName.Value), 1);
%           idx2 = app.Band_Tree.SelectedNodes.NodeData;
            idx3 = find(app.instrInfo.Name == string(extractBetween(app.Receiver_List.Value, ': ', ' -')), 1);

            instrSettings = struct('StepWidth_Items',   [], 'StepWidth',       [], ...
                                   'DataPoints_Limits', [], 'DataPoints',      [], ...
                                   'Resolution_Items',  [], 'Resolution',      [], ...
                                   'Selectivity_Items', [], 'Selectivity',     [], ...
                                   'SensitivityMode',   [], 'Preamp',          [], ...
                                   'AttMode',           [], 'AttFactor_Items', [], ...
                                   'AttFactor',         [], 'LevelUnit',       [], ...
                                   'Detector_Items',    [], 'IntegrationTime', []);

            span = app.taskList{idx1}.Band(idx2).FreqStop - app.taskList{idx1}.Band(idx2).FreqStart;            
            
            instrSettings.AttMode         = 'Auto';
            instrSettings.AttFactor_Items = strsplit(app.instrInfo.Attenuation_Values{idx3}, ',');
            instrSettings.AttFactor       = instrSettings.AttFactor_Items{1};
            instrSettings.Detector_Items  = strsplit(app.instrInfo.Detector_Items{idx3}, ',');

            switch app.instrInfo.connectFlag{idx3}
                case 1
                    instrSettings.Resolution_Items = strsplit(app.instrInfo.Resolution_Values{idx3}, ',');

                    rbwValues = [];
                    for ii = 1:numel(instrSettings.Resolution_Items)
                        rbwValues = [rbwValues, str2double(extractBefore(instrSettings.Resolution_Items{ii}, ' kHz')).*1000];
                    end

                    instrSettings.DataPoints_Limits = app.instrInfo.DataPoints_Limits{idx3};

                    DataPoints = round(span/app.taskList{idx1}.Band(idx2).StepWidth + 1);
                    if     DataPoints < instrSettings.DataPoints_Limits(1); instrSettings.DataPoints = instrSettings.DataPoints_Limits(1);
                    elseif DataPoints > instrSettings.DataPoints_Limits(2); instrSettings.DataPoints = fix(instrSettings.DataPoints_Limits(2));
                    else;                                                   instrSettings.DataPoints = DataPoints;
                    end
                    instrSettings.StepWidth = span/(instrSettings.DataPoints - 1);

                    rbwIndex  = find(abs(rbwValues - app.taskList{idx1}.Band(idx2).Resolution) == min(abs(rbwValues - app.taskList{idx1}.Band(idx2).Resolution)));
                    instrSettings.Resolution = instrSettings.Resolution_Items{rbwIndex};

                    instrSettings.Selectivity     = '';

                    instrSettings.SensitivityMode = '0';
                    instrSettings.Preamp          = 'On';

                    instrSettings.LevelUnit       = app.taskList{idx1}.Band(idx2).LevelUnit;
                    if contains(app.Receiver_List.Value, 'N9344C')
                        instrSettings.LevelUnit   = 'dBm';
                    end

                    switch app.taskList{idx1}.Band(idx2).RFMode
                        case 'High Sensitivity'
                            instrSettings.AttMode         = 'Manual';
                            instrSettings.SensitivityMode = '1';
                        case 'Low Distortion'
                            instrSettings.Preamp          = 'Off';
                    end

                case 2
                    instrSettings.StepWidth_Items   = strsplit(app.instrInfo.StepWidth_Values{idx3}, ',');
                    instrSettings.Selectivity_Items = {'Normal', 'Narrow', 'Sharp'};

                    stepValues = [];
                    for ii = 1:length(instrSettings.StepWidth_Items)
                        stepValues = [stepValues, str2double(extractBefore(instrSettings.StepWidth_Items{ii}, ' kHz')).*1000];
                    end

                    stepIndex  = find(abs(stepValues - app.taskList{idx1}.Band(idx2).StepWidth) == min(abs(stepValues - app.taskList{idx1}.Band(idx2).StepWidth)));
                    instrSettings.StepWidth  = instrSettings.StepWidth_Items{stepIndex};

                    instrSettings.DataPoints = span/(str2double(extractBefore(instrSettings.StepWidth, ' kHz'))*1000) + 1;

                    rbwValues = [];
                    rbwItems  = {};
                    for ii = 1:3
                        rbwValues = [rbwValues, app.EB500Mapping{instrSettings.StepWidth, ii}];
                        rbwItems  = [rbwItems,  sprintf('%.3f kHz', rbwValues(ii)/1000)];
                    end
                    instrSettings.Resolution_Items = rbwItems;

                    rbwIndex = find(abs(rbwValues - app.taskList{idx1}.Band(idx2).Resolution) == min(abs(rbwValues - app.taskList{idx1}.Band(idx2).Resolution)));
                    instrSettings.Resolution  = instrSettings.Resolution_Items{rbwIndex};
                    instrSettings.Selectivity = app.Band_Selectivity.Items{rbwIndex};

                    instrSettings.SensitivityMode = 'NORM';
                    instrSettings.Preamp          = 'Off';
                    instrSettings.LevelUnit       = 'dBμV';

                    switch app.taskList{idx1}.Band(idx2).RFMode
                        case 'High Sensitivity'
                            instrSettings.AttMode = 'Manual';
                        case 'Low Distortion'
                            instrSettings.SensitivityMode = 'LOWD';
                    end

                    switch app.taskList{idx1}.Band(idx2).TraceMode
                        case 'ClearWrite'
                            instrSettings.IntegrationTime = 0;
                        otherwise
                            instrSettings.IntegrationTime = 10 * app.taskList{idx1}.Band(idx2).IntegrationFactor;
                    end
            end

        end


        function BandView_EditablesParameters_SaveValues(app, idx1, idx2)

            instrSettings = BandView_EditablesParameters_GetValues(app, idx1, idx2);

            app.taskList{idx1}.Band(idx2).instrStepWidth_Items   = instrSettings.StepWidth_Items;
            app.taskList{idx1}.Band(idx2).instrStepWidth         = instrSettings.StepWidth;

            app.taskList{idx1}.Band(idx2).instrDataPoints_Limits = instrSettings.DataPoints_Limits;
            app.taskList{idx1}.Band(idx2).instrDataPoints        = instrSettings.DataPoints;

            app.taskList{idx1}.Band(idx2).instrResolution_Items  = instrSettings.Resolution_Items;
            app.taskList{idx1}.Band(idx2).instrResolution        = instrSettings.Resolution;
            
            app.taskList{idx1}.Band(idx2).instrSelectivity       = instrSettings.Selectivity;
            app.taskList{idx1}.Band(idx2).instrSensitivityMode   = instrSettings.SensitivityMode;
            
            app.taskList{idx1}.Band(idx2).instrPreamp            = instrSettings.Preamp;
            app.taskList{idx1}.Band(idx2).instrAttMode           = instrSettings.AttMode;
            app.taskList{idx1}.Band(idx2).instrAttFactor_Items   = instrSettings.AttFactor_Items;
            app.taskList{idx1}.Band(idx2).instrAttFactor         = instrSettings.AttFactor;

            app.taskList{idx1}.Band(idx2).instrDetector_Items    = instrSettings.Detector_Items;
            idx3 = find(strcmp(app.taskList{idx1}.Band(idx2).instrDetector_Items, app.taskList{idx1}.Band(idx2).Detector), 1);
            if isempty(idx3); idx3 = 1;
            end
            app.taskList{idx1}.Band(idx2).instrDetector          = instrSettings.Detector_Items{idx3};

            app.taskList{idx1}.Band(idx2).instrLevelUnit         = instrSettings.LevelUnit;
            app.taskList{idx1}.Band(idx2).instrIntegrationTime   = instrSettings.IntegrationTime;


            if ~isempty(app.taskList{idx1}.Band(idx2).ObservationSamples)
                app.taskList{idx1}.Band(idx2).instrObservationSamples = app.taskList{idx1}.Band(idx2).ObservationSamples;
            else
                app.taskList{idx1}.Band(idx2).instrObservationSamples = -1;
            end

            app.taskList{idx1}.Band(idx2).instrTarget  = '';
            app.taskList{idx1}.Band(idx2).instrAntenna = '';

            app.taskList{idx1}.Band(idx2).EditedFlag = 0;
            app.Band_Refresh.Visible                 = 0;

        end


        function BandView_EditablesParameters_ShowValues(app, idx1, idx2)

%           idx1 = find(strcmp(app.TaskName.Items, app.TaskName.Value), 1);
%           idx2 = app.Band_Tree.SelectedNodes.NodeData;
            idx3 = find(app.instrInfo.Name == string(extractBetween(app.Receiver_List.Value, ': ', ' -')), 1);
            
            if ~app.taskList{idx1}.Band(idx2).EditedFlag
                BandView_EditablesParameters_SaveValues(app, idx1, idx2)
            else
                app.Band_Refresh.Visible = 1;
            end

            switch app.instrInfo.connectFlag{idx3}
                case 1
                    if fix(diff(app.instrInfo.DataPoints_Limits{idx3}))
                        set(app.Band_StepWidth1,  'Editable', 1, ...
                                                  'Value',  app.taskList{idx1}.Band(idx2).instrStepWidth / 1000);
                        set(app.Band_DataPoints1, 'Limits', app.taskList{idx1}.Band(idx2).instrDataPoints_Limits, ...
                                                  'Value',  app.taskList{idx1}.Band(idx2).instrDataPoints)
                    else
                        set(app.Band_StepWidth1,  'Editable', 0, ...
                                                  'Value',  app.taskList{idx1}.Band(idx2).instrStepWidth / 1000);
                        set(app.Band_DataPoints1, 'Limits', app.taskList{idx1}.Band(idx2).instrDataPoints_Limits, ...
                                                  'Value',  app.instrInfo.DataPoints_Limits{idx3}(1))
                    end

                    app.Band_IntegrationTimeLabel.Visible = 0;
                    app.Band_IntegrationTime.Visible      = 0;

                case 2
                    set(app.Band_StepWidth2, 'Items', app.taskList{idx1}.Band(idx2).instrStepWidth_Items, ...
                                             'Value', app.taskList{idx1}.Band(idx2).instrStepWidth)
                    app.Band_DataPoints2.Value = app.taskList{idx1}.Band(idx2).instrDataPoints;
                    app.Band_Selectivity.Value = app.taskList{idx1}.Band(idx2).instrSelectivity;

                    app.Band_IntegrationTimeLabel.Visible = 1;
                    set(app.Band_IntegrationTime, 'Visible', 1, ...
                                                  'Value', app.taskList{idx1}.Band(idx2).instrIntegrationTime)

                    switch app.taskList{idx1}.Band(idx2).TraceMode
                        case 'ClearWrite'
                            app.Band_IntegrationTime.Enable = 0;
                        otherwise
                            app.Band_IntegrationTime.Enable = 1;
                    end
            end

            set(app.Band_Resolution, 'Items', app.taskList{idx1}.Band(idx2).instrResolution_Items, ...
                                     'Value', app.taskList{idx1}.Band(idx2).instrResolution)

            app.Band_Preamp.Value  = app.taskList{idx1}.Band(idx2).instrPreamp;
            app.Band_attMode.Value = app.taskList{idx1}.Band(idx2).instrAttMode;

            set(app.Band_attValue, 'Items', app.taskList{idx1}.Band(idx2).instrAttFactor_Items, ...
                                   'Value', app.taskList{idx1}.Band(idx2).instrAttFactor)

            set(app.Band_Detector, 'Items', app.taskList{idx1}.Band(idx2).instrDetector_Items, ...
                                   'Value', app.taskList{idx1}.Band(idx2).instrDetector)

            if strcmp(app.Band_attMode.Value, 'Auto')
                app.Band_attValueLabel.Visible = 0;
                app.Band_attValue.Visible      = 0;
            else
                app.Band_attValueLabel.Visible = 1;
                app.Band_attValue.Visible      = 1;
            end

            if ~isempty(app.taskList{idx1}.Band(idx2).instrObservationSamples)
                app.Band_Samples.Value = app.taskList{idx1}.Band(idx2).instrObservationSamples;
            end

            if ismember(app.taskList{idx1}.Band(idx2).instrTarget, app.Band_Target.Items)
                app.Band_Target.Value = app.taskList{idx1}.Band(idx2).instrTarget;
            end
            BandView_SatelliteList(app)

            if ismember(app.taskList{idx1}.Band(idx2).instrAntenna, app.Band_Antenna.Items)
                app.Band_Antenna.Value = app.taskList{idx1}.Band(idx2).instrAntenna;
            end

        end


        function MainButtonPushed_Validations(app)

            % (A) VALIDAÇÕES
            % (A1) Verificar inclusão do arquivo de máscara, caso selecionada
            % a tarefa do tipo "Rompimento de Máscara Espectral".
            if (app.TaskType.Value == "Rompimento de Máscara Espectral") & isempty(app.MaskFile_Button.Tooltip{1})
                error('Não selecionado o arquivo de máscara espectral.')
            end

            % (A2) Verificar validade do período de observação.
            idx1 = find(strcmp(app.TaskName.Items, app.TaskName.Value), 1);            
            
            switch app.ObservationType.Value
                case 'Período específico'
                    BeginTime = app.SpecificTime_DatePicker1.Value + hours(app.SpecificTime_Spinner1.Value) + minutes(app.SpecificTime_Spinner2.Value);
                    EndTime   = app.SpecificTime_DatePicker2.Value + hours(app.SpecificTime_Spinner3.Value) + minutes(app.SpecificTime_Spinner4.Value);
                    
                    if isnat(BeginTime) | isnat(EndTime) | (BeginTime > EndTime)
                        error('Período de observação inválido.')
                    end

                case 'Quantidade específica de amostras'
                    jj = [];
                    for ii = 1:numel(app.taskList{idx1}.Band)
                        if app.taskList{idx1}.Band(ii).instrObservationSamples < 0
                            jj = [jj, ii];
                        end
                    end

                    if ~isempty(jj)
                        error('Quantidade inválida de amostras da(s) faixa(s) de frequência indicada(s) a seguir.\nID: %s', strjoin(string(jj), ', '))
                    end
            end

            % (A3) GPS manual com valores [-1,-1]
            if (app.GPS_List.Value == "ID 0: Manual") & (app.GPS_manualLatitude.Value == -1) & (app.GPS_manualLongitude.Value == -1)
                error('Coordenadas geográficas inválidas.')
            end

            % (A4) Comutador de antenas
            if app.AntennaSwitch_Mode.Value
                jj = [];
                for ii = 1:numel(app.taskList{idx1}.Band)
                    if isempty(app.taskList{idx1}.Band(ii).instrAntenna)
                        jj = [jj, ii];
                    end
                end

                if ~isempty(jj)
                    error('Não selecionada a antena da(s) faixa(s) de frequência indicada(s) a seguir.\nID: %s', strjoin(string(jj), ', '))
                end
            end

            % (A5) Metadados das antenas em uso
            if isempty(app.AntennaList_Tree.Children)
                error('Não indicada(s) a(s) antena(s).')
            else
                AntennaList = {};
                for ii = 1:numel(app.taskList{idx1}.Band)
                    if ~isempty(app.taskList{idx1}.Band(ii).instrAntenna)
                        AntennaList{end+1} = app.taskList{idx1}.Band(ii).instrAntenna;
                    end
                end
                AntennaList = unique(AntennaList); 
    
                if ~isempty(AntennaList)
                    AntennaMetaData = {app.AntennaList_Tree.Children.Text};
    
                    idx2 = contains(AntennaList, AntennaMetaData);
                    if ~all(idx2)
                        error('Os parâmetros de instalação da(s) antena(s) indicada(s) a seguir não foram configurados.\nAntenas: %s', strjoin(AntennaList(~idx2), ', '))
                    end
                end
            end

        end


        function MessageBox(app, type, msg)

            appName = 'appColeta';

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, appName, 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, appName, 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, appName, 'Interpreter', 'html', 'Icon', 'LT_info.png')
                case 'startup'; app.UIFigure.Visible = 1; uialert(app.UIFigure, msg, appName, 'Interpreter', 'html', 'CloseFcn', @(~,~)closeFcn(app));
            end

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, tempVar)
            
            app.UIFigure.Position(4) = 532;
            Layout_LeftPanelTab(app, struct('Source', app.Tab1_Image))

            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind]    = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4)-30)/2)];

            app.CallingApp = mainapp;

            % READ ONLY VARIABLES:
            app.RootFolder = app.CallingApp.RootFolder;
            app.taskList   = app.CallingApp.taskList;
            app.scpiList   = app.CallingApp.scpiList;
            app.instrInfo  = app.CallingApp.instrInfo;

            load('Misc_EB500Map.mat', 'EB500Map')
            app.EB500Mapping  = EB500Map;            
            app.switchList    = struct2table(jsondecode(fileread(fullfile(app.RootFolder, 'Settings', 'switchList.json'))));
            app.satelliteList = struct2table(jsondecode(fileread(fullfile(app.RootFolder, 'Settings', 'satelliteList.json'))));

            % VARIABLES SUBJECT TO EDIT:
            % app.CallingApp.specObj
            % app.CallingApp.timeObj
            % app.CallingApp.udpPort
            
            startup_ModeOperation(app, tempVar)

        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin1 = [];
            delete(app.CallingApp.d)

            delete(app)
            
        end

        % Image clicked function: Tab1_Image, Tab2_Image, Tab3_Image
        function Layout_LeftPanelTab(app, event)
            
            switch event.Source
                case app.Tab1_Image; app.LeftPanel_Grid.RowHeight(2:2:6) = {'1x',0,0};
                case app.Tab2_Image; app.LeftPanel_Grid.RowHeight(2:2:6) = {0,'1x',0};
                case app.Tab3_Image; app.LeftPanel_Grid.RowHeight(2:2:6) = {0,0,'1x'};
            end

        end

        % Value changed function: TaskName
        function General_Task(app, event)

            idx1 = find(strcmp(app.TaskName.Items, app.TaskName.Value), 1);

            app.BitsPerPoint.Value = sprintf('%d bits', app.taskList{idx1}.BitsPerSample);

            % OBSERVATION TIME
            switch app.taskList{idx1}.Observation.Type
                case 'Duration'
                    app.ObservationType.Value = app.ObservationType.Items{1};

                    Duration_sec = app.taskList{idx1}.Observation.Duration;
                    if Duration_sec >= 3600
                        app.Duration.Value     = Duration_sec ./ 3600;
                        app.DurationUnit.Value = 'hr';
                    else
                        app.Duration.Value     = Duration_sec ./ 60;
                        app.DurationUnit.Value = 'min';
                    end

                case 'Time'
                    app.ObservationType.Value = app.ObservationType.Items{2};
                    
                    BeginTime = datetime(app.taskList{idx1}.Observation.BeginTime, "InputFormat", "dd/MM/yyyy HH:mm:ss", "Format", "dd/MM/yyyy HH:mm:ss");
                    EndTime   = datetime(app.taskList{idx1}.Observation.EndTime,   "InputFormat", "dd/MM/yyyy HH:mm:ss", "Format", "dd/MM/yyyy HH:mm:ss");

                    app.SpecificTime_DatePicker1.Value = BeginTime;
                    app.SpecificTime_Spinner1.Value = hour(BeginTime);
                    app.SpecificTime_Spinner2.Value = minute(BeginTime);
                    
                    app.SpecificTime_DatePicker2.Value = EndTime;
                    app.SpecificTime_Spinner3.Value = hour(EndTime);
                    app.SpecificTime_Spinner4.Value = minute(EndTime);

                case 'Samples'
                    app.ObservationType.Value = app.ObservationType.Items{3};
            end
            General_ObservationType(app)


            % GPS
            app.GPS_manualLatitude.Value  = -1;
            app.GPS_manualLongitude.Value = -1;
            app.GPS_RevisitTime.Value     = 60;
            try
                if app.taskList{idx1}.GPS.Type == "manual"
                    app.GPS_manualLatitude.Value  = app.taskList{idx1}.GPS.Latitude;
                    app.GPS_manualLongitude.Value = app.taskList{idx1}.GPS.Longitude;
                else
                    app.GPS_RevisitTime.Value = app.taskList{idx1}.GPS.RevisitTime;
                end
            catch
            end
            
            BandView_TreeBuilding(app, idx1)
            Instruments_Receiver(app)
            BandView_TreeSelectionChanged(app)

        end

        % Value changed function: TaskType
        function General_TaskType(app, event)

            switch app.TaskType.Value
                case "Rompimento de Máscara Espectral"
                    app.TaskType_Grid.ColumnWidth(2:3) = {22, 22};
                    if ~isempty(app.MaskFile_Button.Tooltip{1})
                        app.MaskFile_Button.Enable = 1;
                    end

                otherwise
                    app.TaskType_Grid.ColumnWidth(2:3) = {0, 0};
                    app.MaskFile_Button.Enable = 0;
            end

            ind1 = find(strcmp(app.TaskName.Items, app.TaskName.Value), 1);
            BandView_TreeBuilding(app, ind1)
            
        end

        % Button pushed function: AddMaskFile_Button
        function General_SpectralMask_Add(app, event)
            
            [Filename, Filepath] = uigetfile({'*.csv', '(*.csv)'}, ...
                                              'Selecione um arquivo de máscara espectral', 'MultiSelect', 'off');
            figure(app.UIFigure)
            
            if Filename
                try
                    General_SpectralMask_FileRead(app, fileread(fullfile(Filepath, Filename)));
                    set(app.MaskFile_Button, 'Enable', 1, 'Tooltip', {fullfile(Filepath, Filename)})

                catch ME
                    fprintf('%s\n', jsonencode(ME))
                    MessageBox(app, 'error', getReport(ME))

                    set(app.MaskFile_Button, 'Enable', 0, 'Tooltip', {''})                    
                end
            end

        end

        % Button pushed function: MaskFile_Button
        function General_SpectralMask_View(app, event)
            
            try
                msg = fileread(app.MaskFile_Button.Tooltip{1});
                MessageBox(app, 'info', msg)

            catch ME
                fprintf('%s\n', jsonencode(ME))
                MessageBox(app, 'error', getReport(ME))
            end

        end

        % Value changed function: ObservationType
        function General_ObservationType(app, event)
            
            switch app.ObservationType.Value
                case 'Duração'
                    app.ObservationPanel_Grid.RowHeight{3} = 22;
                    set(app.Duration_Grid.Children,    'Enable', 1, 'Visible', 1)
                    set(app.SpecificTime_Grid.Children, 'Enable', 0, 'Visible', 0)
                    app.Band_Samples.Enable = 0;

                case 'Período específico'
                    app.ObservationPanel_Grid.RowHeight{3} = 0;
                    set(app.Duration_Grid.Children,    'Enable', 0, 'Visible', 0)
                    set(app.SpecificTime_Grid.Children, 'Enable', 1, 'Visible', 1)
                    app.Band_Samples.Enable = 0;

                case 'Quantidade específica de amostras'
                    app.ObservationPanel_Grid.RowHeight{3} = 0;
                    set(app.Duration_Grid.Children,    'Enable', 0, 'Visible', 0)
                    set(app.SpecificTime_Grid.Children, 'Enable', 0, 'Visible', 0)
                    app.Band_Samples.Enable = 1;
            end
            
        end

        % Value changed function: Receiver_List
        function Instruments_Receiver(app, event)
            
            idx1 = find(strcmp(app.TaskName.Items, app.TaskName.Value), 1);
            idx2 = app.Band_Tree.SelectedNodes.NodeData;
            idx3 = find(app.instrInfo.Name == string(extractBetween(app.Receiver_List.Value, ': ', ' -')), 1);

            
            % GPS
            app.GPS_List.Items = {'ID 0: Manual'};

            indGPSs = intersect(find(strcmp(app.scpiList.Family, 'GPS')), find(app.scpiList.Enable));
            for ii = 1:numel(indGPSs)
                Parameters = ConnectivityTest_MissingParameters(app, ii);
                switch app.scpiList.Type{ii}
                    case 'Serial'
                        Socket = Parameters.Port;
                    case 'TCPIP Socket'
                        Socket = sprintf('%s:%s', Parameters.IP, Parameters.Port);
                end

                app.GPS_List.Items = [app.GPS_List.Items, sprintf('ID %d: %s - %s', indGPSs(ii), app.scpiList.Name{indGPSs(ii)}, Socket)];
            end           

            if ~isempty(app.instrInfo.scpiGPS{idx3})
                app.GPS_List.Items = [app.GPS_List.Items, app.Receiver_List.Value];

                if app.taskList{idx1}.GPS.Type ~= "manual"
                    app.GPS_List.Value = app.Receiver_List.Value;
                end
            end
            Instruments_GPS(app)

            
            % RECEIVER
            app.Receiver_SyncRef.Items = strsplit(app.instrInfo.SyncOptions{idx3}, ',');

            for ii = 1:numel(app.taskList{idx1}.Band)
                BandView_EditablesParameters_SaveValues(app, idx1, ii)
            end

            BandView_EditablesParameters_Visibility(app)
            BandView_EditablesParameters_ShowValues(app, idx1, idx2)


            % ANTENNA SWITCH
            set(app.AntennaSwitch_Name, 'Enable', 0, 'Value', '')
            app.AntennaSwitch_Connectivity.Enable = 0;

            idx4 = find(cellfun(@(x) contains(app.Receiver_List.Value, x), app.switchList.Receiver), 1);
            if ~isempty(idx4)
                set(app.AntennaSwitch_Mode, 'Enable', 1, 'Value', 0)
            else
                set(app.AntennaSwitch_Mode, 'Enable', 0, 'Value', 0)
            end
            AntennaConfig_SwitchMode(app)

        end

        % Value changed function: GPS_List
        function Instruments_GPS(app, event)

            switch app.GPS_List.Value
                case 'ID 0: Manual'  
                    set(app.GPS_Grid.Children, 'Enable', 1)                
                    app.GPS_RevisitTime.Enable = 0;

                otherwise
                    set(app.GPS_Grid.Children, 'Enable', 0)                                
                    app.GPS_RevisitTime.Enable = 1;
                    
                    app.GPS_RevisitTimeLabel.Enable  = 1;
                    app.GPS_manualLatitudeLabel.Enable  = 1;
                    app.GPS_manualLongitudeLabel.Enable = 1;
            end

        end

        % Value changed function: AntennaSwitch_Mode
        function AntennaConfig_SwitchMode(app, event)
            
            idx1 = find(strcmp(app.TaskName.Items, app.TaskName.Value), 1);
            idx2 = app.Band_Tree.SelectedNodes.NodeData;

            if app.AntennaSwitch_Mode.Value
                set(app.AntennaSwitch_Grid.Children, 'Enable', 1)
                set(app.Band_Antenna_Grid.Children,  'Enable', 1)

                idx4 = find(cellfun(@(x) contains(app.Receiver_List.Value, x), app.switchList.Receiver), 1);
                app.AntennaSwitch_Name.Value = app.switchList.Switch{idx4};
                
                switch app.AntennaSwitch_Name.Value
                    case 'ETM'
                        app.AntennaName.Items  = app.switchList.Antennas{1};
                        set(app.Band_Target, 'Enable', 0, 'Items', {''})
                        app.Band_Antenna.Items = app.switchList.Antennas{1};
                    
                    case 'EMSAT'
                        app.AntennaName.Items  = app.switchList.Antennas{2};
                        set(app.Band_Target, 'Enable', 1, 'Items', [{''}; app.satelliteList.Satellite], 'Value', '')

                        if ismember(app.taskList{idx1}.Band(idx2).instrTarget, app.Band_Target.Items)
                            app.Band_Target.Value = app.taskList{idx1}.Band(idx2).instrTarget;
                        end
                        BandView_SatelliteList(app)
                end

                if ismember(app.taskList{idx1}.Band(idx2).instrAntenna, app.Band_Antenna.Items)
                    app.Band_Antenna.Value = app.taskList{idx1}.Band(idx2).instrAntenna;
                end

            else
                set(findobj('Parent', app.AntennaSwitch_Grid, '-not', 'Type', 'uicheckbox'), 'Enable', 0)
                set(app.Band_Target, 'Enable', 0, 'Items', {''})
                set(app.Band_Antenna,   'Enable', 0, 'Items', {''})

                app.AntennaSwitch_Name.Value = '';                
                set(app.AntennaName, 'Items', app.switchList.Antennas{3}, 'Value', '')
            end

            AntennaConfig_Name(app)
            
        end

        % Value changed function: AntennaName
        function AntennaConfig_Name(app, event)

            if ~isempty(app.AntennaName.Value)
                switch app.AntennaSwitch_Name.Value
                    case 'EMSAT'; app.Antenna_TrackingMode.Value = "Automático (Preset)";
                    otherwise;    app.Antenna_TrackingMode.Value = "Manual";
                end    
                app.AddAntenna_Image.Enable = 1;

            else
                app.Antenna_TrackingMode.Value = "Manual";
                app.AddAntenna_Image.Enable = 0;
            end

            AntennaConfig_TrackingMode(app)

        end

        % Value changed function: Antenna_TrackingMode
        function AntennaConfig_TrackingMode(app, event)

            if ~isempty(app.AntennaName.Value)
                switch app.Antenna_TrackingMode.Value
                    case 'Automático (Preset)'
                        AntennaConfig_Layout(app, 'Disable')
                        app.Antenna_TrackingMode.Enable = 1;
                    
                    case 'Automático (Livre)'
                        AntennaConfig_Layout(app, 'Enable')
                        app.AntennaHeight.Enable = 0;
                        set(app.AntennaAzimuthRef, 'Enable', 0, 'Value', 'NV')

                    case 'Manual'
                        switch app.AntennaSwitch_Name.Value
                            case 'EMSAT'
                                AntennaConfig_Layout(app, 'Enable')
                                app.AntennaHeight.Enable = 0;

                            case 'ETM'
                                AntennaConfig_Layout(app, 'Disable')
                                app.AntennaHeight.Enable = 1;

                            otherwise
                                if app.AntennaName.Value == "Unlisted (Directional)"
                                    AntennaConfig_Layout(app, 'Enable')
                                    app.Antenna_TrackingMode.Enable = 0;
                                else
                                    AntennaConfig_Layout(app, 'Disable')
                                    app.AntennaHeight.Enable = 1;
                                end
                        end
                end

            else
                AntennaConfig_Layout(app, 'Disable')
            end
            
        end

        % Image clicked function: AddAntenna_Image
        function AntennaConfig_Add(app, event)
            
            % new value
            AntennaValue = app.Antenna_TrackingMode.Value;

            if app.AntennaHeight.Enable
                AntennaValue = sprintf('%s; %.0f metros', AntennaValue, ...
                                                          app.AntennaHeight.Value);
            else
                AntennaValue = sprintf('%s; "NA"', AntennaValue);
            end

            if app.AntennaAzimuth.Enable
                AntennaValue = sprintf('%s; %.3fº %s; %.3fº; %.3fº', AntennaValue,                ...
                                                                     app.AntennaAzimuth.Value,    ...
                                                                     app.AntennaAzimuthRef.Value, ...
                                                                     app.AntennaElevation.Value,  ...
                                                                     app.AntennaPolarization.Value);
            else
                AntennaValue = sprintf('%s; "NA"; "NA"; "NA"', AntennaValue);
            end

            % old values
            AntennaList = {};
            for ii = 1:numel(app.AntennaList_Tree.Children)
                tempValue = strsplit(app.AntennaList_Tree.Children(ii).Text, ';');
                AntennaList{end+1} = tempValue{1};
            end

            % add new value
            idx = find(strcmp(AntennaList, app.AntennaName.Value), 1);
            if ~isempty(idx)
                app.AntennaList_Tree.Children(idx).Children.Text = AntennaValue;
            else
                tempValue = numel(app.AntennaList_Tree.Children)+1;
                tempNode  = uitreenode(app.AntennaList_Tree, 'Text', app.AntennaName.Value,  ...
                                                             'NodeData', tempValue,          ...
                                                             'ContextMenu', app.ContextMenu);
                uitreenode(tempNode, 'Text', AntennaValue,   ...
                                     'NodeData', tempValue,  ...
                                     'ContextMenu', app.ContextMenu);
            end

        end

        % Menu selected function: ExcluirMenu
        function AntennaConfig_Delete(app, event)
            
            if ~isempty(app.AntennaList_Tree.SelectedNodes)
                % old values
                AntennaList = {};
                for ii = 1:numel(app.AntennaList_Tree.Children)
                    AntennaList{end+1} = app.AntennaList_Tree.Children(ii).Text;
                end
    
                % delete selected value
                idx = app.AntennaList_Tree.SelectedNodes.NodeData;
                if ~isempty(idx)
                    delete(app.AntennaList_Tree.Children(idx))

                    for ii = 1:numel(app.AntennaList_Tree.Children)
                        app.AntennaList_Tree.Children(ii).NodeData          = ii;
                        app.AntennaList_Tree.Children(ii).Children.NodeData = ii;
                    end
                end
            end

        end

        % Button pushed function: Receiver_Connectivity
        function ConnectivityTest_Receiver(app, event)
            
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html', 'Message', '<font style="font-size:12;">Em andamento...</font>');
            ConnectivityTest_Receiver_Aux(app, 1);
            delete(d)

        end

        % Button pushed function: GPS_Connectivity
        function ConnectivityTest_GPS(app, event)
            
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html', 'Message', '<font style="font-size:12;">Em andamento...</font>');

            if (app.GPS_List.Value == "ID 0: Manual") & (app.GPS_manualLatitude.Value == -1) & (app.GPS_manualLongitude.Value == -1)
                MessageBox(app, 'error', 'Coordenadas geográficas inválidas.')
                return
            end

            gps = struct('Status',     0, ...
                         'Latitude',  -1, ...
                         'Longitude', -1, ...
                         'TimeStamp', []);

            try
                if strcmp(app.Receiver_List.Value, app.GPS_List.Value)
                    instrHandle = ConnectivityTest_Receiver_Aux(app, 0);
                    if ~isempty(instrHandle)
                        gps = task_gpsDecode(instrHandle);
                    end
    
                elseif strcmp(app.GPS_List.Value, 'ID 0: Manual')
                    gps = struct('Status',    1,                             ...
                                 'Latitude',  app.GPS_manualLatitude.Value,  ...
                                 'Longitude', app.GPS_manualLongitude.Value, ...
                                 'TimeStamp', []);
    
                else
                    instrHandle = ConnectivityTest_GPS_Aux(app);
                    if ~isempty(instrHandle)
                        gps = task_gpsReader(instrHandle, 1);
                    end
                end
    
                if gps.Status
                    [City, Distance] = geo_FindCity(gps);

                    if isempty(gps.TimeStamp); gps.TimeStamp = "NA";
                    end

                    msg = sprintf(['Status: %.0f\n'    ...
                                   'Latitude: %.6f\n'  ...
                                   'Longitude: %.6f\n' ...
                                   'Timestamp: %s\n\n' ...
                                   'Nota:\nCoordenadas geográficas distam <b>%.1f km</b> da sede do município <b>%s</b>.'], ...
                                   gps.Status, gps.Latitude, gps.Longitude, gps.TimeStamp, Distance, City);
                    
                    MessageBox(app, 'info', msg)
                    
                    app.GPS_manualLatitude.Value  = round(gps.Latitude,  6);
                    app.GPS_manualLongitude.Value = round(gps.Longitude, 6);
    
                else
                    error('<b>Não recebida informação válida do instrumento acerca das coordenadas geográficas do local de monitoração.</b>\n%s', jsonencode(gps))
                end

            catch ME
                fprintf('%s\n', jsonencode(ME))
                MessageBox(app, 'error', getReport(ME))
            end

            delete(d)

        end

        % Button pushed function: AntennaSwitch_Connectivity
        function ConnectivityTest_AntennaSwitch(app, event)
            
            % Em sendo "MSAT", procura na lista de instrumentos o comutador
            % de antenas e tenta se conectar.

            % Em sendo "ETM", comandos scpi pra saber informações das
            % antenas. Apresenta em mensagem.

            % Mensagens de erro, caso aplicáveis.

        end

        % Selection changed function: Band_Tree
        function BandView_TreeSelectionChanged(app, event)

            idx1 = find(strcmp(app.TaskName.Items, app.TaskName.Value), 1);
            idx2 = app.Band_Tree.SelectedNodes.NodeData;

            app.MetaData.HTMLSource = sprintf(['<font style="font-family: Helvetica; font-size: 10px;">'    ...
                                           '<b style="color: #808080;">DESCRIÇÃO: </b>%s<br><br>',      ...
                                           '<b style="color: #808080;">MODO RF: </b>%s<br>',            ...
                                           '<b style="color: #808080;">UNIDADE: </b>%s<br><br>',        ...
                                           '<b style="color: #808080;">PASSO: </b>%.3f kHz<br>',        ...
                                           '<b style="color: #808080;">RESOLUÇÃO: </b>%.3f kHz<br>',    ...
                                           '<b style="color: #808080;">DETECTOR: </b>%s<br><br>',       ...
                                           '<b style="color: #808080;">TRAÇO: </b>%s<br>',              ...
                                           '<b style="color: #808080;">INTEGRAÇÃO: </b>%.0f<br><br>',   ...
                                           '<b style="color: #808080;">REVISITA: </b>%.3f seg</font>'], ...
                                           app.taskList{idx1}.Band(idx2).Description,       ...
                                           app.taskList{idx1}.Band(idx2).RFMode,            ...
                                           app.taskList{idx1}.Band(idx2).LevelUnit,         ...
                                           app.taskList{idx1}.Band(idx2).StepWidth  / 1e+3, ...
                                           app.taskList{idx1}.Band(idx2).Resolution / 1e+3, ...
                                           app.taskList{idx1}.Band(idx2).Detector,          ...
                                           app.taskList{idx1}.Band(idx2).TraceMode,         ...
                                           app.taskList{idx1}.Band(idx2).IntegrationFactor, ...                                               
                                           app.taskList{idx1}.Band(idx2).RevisitTime);

            if ~isempty(app.Receiver_List.Items)
                BandView_EditablesParameters_ShowValues(app, idx1, idx2)
            end

        end

        % Image clicked function: Band_Refresh
        function BandView_Refresh(app, event)

            idx1 = find(strcmp(app.TaskName.Items, app.TaskName.Value), 1);
            idx2 = app.Band_Tree.SelectedNodes.NodeData;

            app.taskList{idx1}.Band(idx2).EditedFlag = 0;
            BandView_EditablesParameters_ShowValues(app, idx1, idx2)

        end

        % Value changed function: Band_Antenna, Band_DataPoints1, 
        % Band_Detector, Band_IntegrationTime, Band_Preamp, 
        % Band_Resolution, Band_Samples, Band_Selectivity, 
        % Band_StepWidth1, Band_StepWidth2, Band_Target, Band_attMode, 
        % Band_attValue
        function BandView_EditedParameters(app, event)

            idx1 = find(strcmp(app.TaskName.Items, app.TaskName.Value), 1);
            idx2 = app.Band_Tree.SelectedNodes.NodeData;

            switch event.Source
                % OPERATION COMPLEXITY: 1 OF 3
                case app.Band_Samples;         app.taskList{idx1}.Band(idx2).instrObservationSamples = app.Band_Samples.Value;
                case app.Band_attValue;        app.taskList{idx1}.Band(idx2).instrAttFactor          = app.Band_attValue.Value;
                case app.Band_Preamp;          app.taskList{idx1}.Band(idx2).instrPreamp             = app.Band_Preamp.Value;
                case app.Band_IntegrationTime; app.taskList{idx1}.Band(idx2).instrIntegrationTime    = app.Band_IntegrationTime.Value;
                case app.Band_Detector;        app.taskList{idx1}.Band(idx2).instrDetector           = app.Band_Detector.Value;
                case app.Band_Antenna;         app.taskList{idx1}.Band(idx2).instrAntenna            = app.Band_Antenna.Value;


                % OPERATION COMPLEXITY: 2 OF 3
                case app.Band_Target
                    app.taskList{idx1}.Band(idx2).instrTarget = app.Band_Target.Value;
                    BandView_SatelliteList(app)

                case app.Band_DataPoints1
                    if ~fix(diff(app.Band_DataPoints1.Limits))
                        return
                    end        
                    span = app.taskList{idx1}.Band(idx2).FreqStop - app.taskList{idx1}.Band(idx2).FreqStart;
                    app.Band_StepWidth1.Value = (span/(app.Band_DataPoints1.Value-1)) / 1000;
        
                    app.taskList{idx1}.Band(idx2).instrDataPoints = app.Band_DataPoints1.Value;
                    app.taskList{idx1}.Band(idx2).instrStepWidth  = app.Band_StepWidth1.Value*1000;

                case app.Band_Selectivity
                    app.Band_Resolution.Value = sprintf('%.3f kHz', app.EB500Mapping{app.Band_StepWidth2.Value, app.Band_Selectivity.Value}/1000);
        
                    app.taskList{idx1}.Band(idx2).instrSelectivity = app.Band_Selectivity.Value;
                    app.taskList{idx1}.Band(idx2).instrResolution  = app.Band_Resolution.Value;

                case app.Band_Resolution
                    app.taskList{idx1}.Band(idx2).instrResolution  = app.Band_Resolution.Value;
        
                    if contains(app.Receiver_List.Value, 'EB500')
                        if app.Band_Selectivity.Visible
                            ind3 = find(strcmp(app.Band_Resolution.Items, app.Band_Resolution.Value));
                            app.Band_Selectivity.Value = app.Band_Selectivity.Items{ind3};
                        end
                        app.taskList{idx1}.Band(idx2).instrSelectivity = app.Band_Selectivity.Value;
                    end

                case app.Band_attMode
                    app.taskList{idx1}.Band(idx2).instrAttMode = app.Band_attMode.Value;
        
                    if strcmp(app.Band_attMode.Value, 'Auto')
                        app.Band_attValueLabel.Visible = 0;
                        app.Band_attValue.Visible      = 0;
                    else
                        app.Band_attValueLabel.Visible = 1;
                        app.Band_attValue.Visible      = 1;
                    end


                % OPERATION COMPLEXITY: 3 OF 3
                case app.Band_StepWidth1
                    span = app.taskList{idx1}.Band(idx2).FreqStop - app.taskList{idx1}.Band(idx2).FreqStart;
                    DataPoints = round(span/(app.Band_StepWidth1.Value) + 1);
                    if DataPoints < app.Band_DataPoints1.Limits(1)
                        app.Band_DataPoints1.Value = app.Band_DataPoints1.Limits(1);
                    elseif DataPoints > app.Band_DataPoints1.Limits(2)
                        app.Band_DataPoints1.Value = fix(app.Band_DataPoints1.Limits(2));
                    else
                        app.Band_DataPoints1.Value = DataPoints;
                    end        
                    app.Band_StepWidth1.Value = (span/(app.Band_DataPoints1.Value-1)) / 1000;
        
                    app.taskList{idx1}.Band(idx2).instrStepWidth  = 1000*app.Band_StepWidth1.Value;
                    app.taskList{idx1}.Band(idx2).instrDataPoints = app.Band_DataPoints1.Value;

                case app.Band_StepWidth2
                    span = app.taskList{idx1}.Band(idx2).FreqStop - app.taskList{idx1}.Band(idx2).FreqStart;
                    stepValue = extractBefore(app.Band_StepWidth2.Value, ' kHz');
        
                    app.Band_DataPoints2.Value = span/(1000*str2double(stepValue)) + 1;
        
                    rbwValues = [];
                    rbwItems  = {};
                    for ii = 1:3
                        rbwValues = [rbwValues, app.EB500Mapping{app.Band_StepWidth2.Value,ii}];
                        rbwItems  = [rbwItems,  sprintf('%.3f kHz', rbwValues(ii)/1000)];
                    end
                    app.Band_Resolution.Items = rbwItems;
        
                    rbwIndex = find(abs(rbwValues - app.taskList{idx1}.Band(idx2).Resolution) == min(abs(rbwValues - app.taskList{idx1}.Band(idx2).Resolution)));
                    app.Band_Resolution.Value = app.Band_Resolution.Items{rbwIndex};
                    app.Band_Selectivity.Value = app.Band_Selectivity.Items{rbwIndex};
        
                    app.taskList{idx1}.Band(idx2).instrStepWidth   = app.Band_StepWidth2.Value;
                    app.taskList{idx1}.Band(idx2).instrDataPoints  = app.Band_DataPoints2.Value;
                    app.taskList{idx1}.Band(idx2).instrSelectivity = app.Band_Selectivity.Value;
        
                    app.taskList{idx1}.Band(idx2).instrResolution_Items = app.Band_Resolution.Items;
                    app.taskList{idx1}.Band(idx2).instrResolution       = app.Band_Resolution.Value;
            end

            app.taskList{idx1}.Band(idx2).EditedFlag = 1;
            app.Band_Refresh.Visible                 = 1;
            
        end

        % Button pushed function: MainButton
        function MainButtonPushed(app, event)
            
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html', 'Message', '<font style="font-size:12;">Em andamento...</font>');

            % (A) VALIDATIONS
            try
                MainButtonPushed_Validations(app)
            catch ME
                MessageBox(app, 'error', getReport(ME))
                return
            end


            % (B) GENERAL ASPECTS OF THE SELECTED TASK
            % BitsPerSample
            idx1 = find(strcmp(app.TaskName.Items, app.TaskName.Value), 1);
            idx2 = find(app.instrInfo.Name == string(extractBetween(app.Receiver_List.Value, ': ', ' -')), 1);

            app.taskList{idx1}.BitsPerSample = str2double(extractBefore(app.BitsPerPoint.Value, 'bits'));

            % Observation
            switch app.ObservationType.Value
                case 'Duração'
                    if app.Duration.Value == inf
                        Duration_sec     = inf;
                        Duration_EndTime = '';
                    else
                        switch app.DurationUnit.Value
                            case 'min'; Duration_sec = app.Duration.Value * 60;
                            case 'hr';  Duration_sec = app.Duration.Value * 3600;
                        end
                        Duration_EndTime = datestr(now+seconds(Duration_sec), 'dd/mm/yyyy HH:MM:ss');
                    end

                    app.taskList{idx1}.Observation = struct('Type',      'Duration',                          ...
                                                            'BeginTime', datestr(now, 'dd/mm/yyyy HH:MM:ss'), ...
                                                            'EndTime',   Duration_EndTime,                    ...
                                                            'Duration',  Duration_sec);
                case 'Período específico'
                    app.taskList{idx1}.Observation = struct('Type',      'Time',    ...
                                                            'BeginTime', datestr(BeginTime, 'dd/mm/yyyy HH:MM:ss'), ...
                                                            'EndTime',   datestr(EndTime,   'dd/mm/yyyy HH:MM:ss'), ...
                                                            'Duration',  []);
                case 'Quantidade específica de amostras'
                    app.taskList{idx1}.Observation = struct('Type',      'Samples',                           ...
                                                            'BeginTime', datestr(now, 'dd/mm/yyyy HH:MM:ss'), ...
                                                            'EndTime',   '',                                  ...
                                                            'Duration',  []);
            end

            % GPS
            switch app.GPS_List.Value
                case 'ID 0: Manual'
                    app.taskList{idx1}.GPS = struct('Type',        'manual',                      ...
                                                    'Latitude',    app.GPS_manualLatitude.Value,  ...
                                                    'Longitude',   app.GPS_manualLongitude.Value, ...
                                                    'RevisitTime', []);
                otherwise
                    app.taskList{idx1}.GPS = struct('Type',        'auto', ...
                                                    'Latitude',    [],     ...
                                                    'Longitude',   [],     ...
                                                    'RevisitTime', app.GPS_RevisitTime.Value);
            end


            % (C) FINAL OPERATIONS
            try
                % RECEIVER
                idx3 = str2double(char(extractBetween(app.Receiver_List.Value, 'ID', ':')));
                recSelection = app.scpiList(idx3,:);

                [recHandle, errorMsg] = ConnectivityTest_Receiver_Aux(app, 0);
                if ~isempty(errorMsg)
                    error(errorMsg)
                end

                % GPS
                gpsSelection = [];
                gpsHandle    = [];
                if ~ismember(app.GPS_List.Value, {'ID 0: Manual', app.Receiver_List.Value})
                    idx4 = str2double(char(extractBetween(app.GPS_List.Value, 'ID', ':')));
                    gpsSelection = app.scpiList(idx4,:);

                    [gpsHandle, errorMsg] = ConnectivityTest_GPS_Aux(app);
                    if ~isempty(errorMsg)
                        error(errorMsg)
                    end
                end

                % ANTENNA (SWITCH HANDLE & METADATA)
                switchHandle = [];
                if strcmp(app.AntennaName.Value, 'EMSAT')
                    switchHandle = -1;                                      % PENDENTE!
                    if isempty(switchHandle)
                        error('Favor verificar a conectividade com o comutador de antenas.')
                    end
                end
                
                AntennaMetaData = struct('Name', {}, 'Installation', {}, 'Height', {}, 'Azimuth', {}, 'Elevation', {}, 'Polarization', {});
                for ii = 1:numel(app.AntennaList_Tree.Children)
                    Parameters = replace(strsplit(app.AntennaList_Tree.Children(ii).Children.Text, '; '), '"', '');
    
                    AntennaMetaData(end+1) = struct('Name',         app.AntennaList_Tree.Children(ii).Text, ...
                                                    'Installation', Parameters{1}, ...
                                                    'Height',       Parameters{2}, ...
                                                    'Azimuth',      Parameters{3}, ...
                                                    'Elevation',    Parameters{4}, ...
                                                    'Polarization', Parameters{5});
                end

            catch ME
                MessageBox(app, 'error', getReport(ME))
                return
            end

            taskObj = struct('General',  struct('Type',      app.TaskType.Value,              ...
                                                'Task',      app.taskList{idx1},              ...
                                                'SCPI',      app.instrInfo(idx2,:),           ...
                                                'Maskfile',  app.MaskFile_Button.Tooltip{1}), ...
                             'Receiver', struct('Selection', recSelection,                    ...
                                                'Handle',    recHandle,                       ...
                                                'Reset',     app.Receiver_RstCommand.Value,   ...
                                                'Sync',      app.Receiver_SyncRef.Value),     ...
                             'GPS',      struct('Selection', gpsSelection,                    ...
                                                'Handle',    gpsHandle),                      ...
                             'Antenna',  struct('Switch',    struct('Name',   app.AntennaSwitch_Name.Value, ...
                                                                    'Handle', switchHandle),                ...
                                                'MetaData',  AntennaMetaData));

            app.CallingApp.winAux_AddOrEditTask(taskObj)

            delete(d)
            closeFcn(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.mainGrid = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.mainGrid);
            app.LeftPanel_Grid = uigridlayout(app.LeftPanel);
            app.Tab1_Grid = uigridlayout(app.LeftPanel_Grid);
            app.Tab1_Grid.Tag = 'COLORLOCKED';
            app.Tab1_Title = uilabel(app.Tab1_Grid);
            app.Tab1_Title.Text = 'ASPECTOS GERAIS';
            app.Tab1_Image = uiimage(app.Tab1_Grid);
            app.Tab1_Image.ImageClickedFcn = createCallbackFcn(app, @Layout_LeftPanelTab, true);
            app.Tab1_Image.ImageSource = 'LT_settings.png';
            app.Tab1_Panel = uigridlayout(app.LeftPanel_Grid);
            app.TaskName = uidropdown(app.Tab1_Panel);
            app.TaskName.Items = {};
            app.TaskName.ValueChangedFcn = createCallbackFcn(app, @General_Task, true);
            app.TaskName.Tag = 'task_Editable';
            app.TaskName.Value = {};
            app.TaskNameLabel = uilabel(app.Tab1_Panel);
            app.TaskNameLabel.Text = 'Nome:';
            app.TaskType_Grid = uigridlayout(app.Tab1_Panel);
            app.AddMaskFile_Button = uibutton(app.TaskType_Grid, 'push');
            app.AddMaskFile_Button.ButtonPushedFcn = createCallbackFcn(app, @General_SpectralMask_Add, true);
            app.AddMaskFile_Button.Tag = 'task_Editable';
            app.AddMaskFile_Button.Icon = 'LT_OpenFile.png';
            app.AddMaskFile_Button.Tooltip = {'Máscara espectral'};
            app.AddMaskFile_Button.Text = '';
            app.MaskFile_Button = uibutton(app.TaskType_Grid, 'push');
            app.MaskFile_Button.ButtonPushedFcn = createCallbackFcn(app, @General_SpectralMask_View, true);
            app.MaskFile_Button.Icon = 'LT_mask.png';
            app.MaskFile_Button.Enable = 'off';
            app.MaskFile_Button.Tooltip = {''};
            app.MaskFile_Button.Text = '';
            app.TaskType = uidropdown(app.TaskType_Grid);
            app.TaskType.Items = {'Monitoração ordinária', 'Drive-test', 'Rompimento de Máscara Espectral'};
            app.TaskType.ValueChangedFcn = createCallbackFcn(app, @General_TaskType, true);
            app.TaskType.Tag = 'task_Editable';
            app.TaskType.Value = 'Monitoração ordinária';
            app.TaskTypeLabel = uilabel(app.TaskType_Grid);
            app.TaskTypeLabel.Text = 'Tipo:';
            app.ObservationLabel = uilabel(app.Tab1_Panel);
            app.ObservationLabel.Text = 'Período de observação:';
            app.ObservationPanel = uipanel(app.Tab1_Panel);
            app.ObservationPanel_Grid = uigridlayout(app.ObservationPanel);
            app.SpecificTime_Grid = uigridlayout(app.ObservationPanel_Grid);
            app.SpecificTime_DatePicker1 = uidatepicker(app.SpecificTime_Grid);
            app.SpecificTime_DatePicker1.DisplayFormat = 'dd/MM/uuuu';
            app.SpecificTime_DatePicker1.Enable = 'off';
            app.SpecificTime_DatePicker1.Visible = 'off';
            app.SpecificTime_Spinner1 = uispinner(app.SpecificTime_Grid);
            app.SpecificTime_Spinner1.Limits = [0 23];
            app.SpecificTime_Spinner1.RoundFractionalValues = 'on';
            app.SpecificTime_Spinner1.ValueDisplayFormat = '%.0f';
            app.SpecificTime_Spinner1.Enable = 'off';
            app.SpecificTime_Spinner1.Visible = 'off';
            app.SpecificTime_Spinner2 = uispinner(app.SpecificTime_Grid);
            app.SpecificTime_Spinner2.Step = 10;
            app.SpecificTime_Spinner2.Limits = [0 59];
            app.SpecificTime_Spinner2.RoundFractionalValues = 'on';
            app.SpecificTime_Spinner2.ValueDisplayFormat = '%.0f';
            app.SpecificTime_Spinner2.Enable = 'off';
            app.SpecificTime_Spinner2.Visible = 'off';
            app.SpecificTime_DatePicker2 = uidatepicker(app.SpecificTime_Grid);
            app.SpecificTime_DatePicker2.DisplayFormat = 'dd/MM/uuuu';
            app.SpecificTime_DatePicker2.Enable = 'off';
            app.SpecificTime_DatePicker2.Visible = 'off';
            app.SpecificTime_Spinner3 = uispinner(app.SpecificTime_Grid);
            app.SpecificTime_Spinner3.Limits = [0 23];
            app.SpecificTime_Spinner3.RoundFractionalValues = 'on';
            app.SpecificTime_Spinner3.ValueDisplayFormat = '%.0f';
            app.SpecificTime_Spinner3.Enable = 'off';
            app.SpecificTime_Spinner3.Visible = 'off';
            app.SpecificTime_Spinner3.Value = 23;
            app.SpecificTime_Spinner4 = uispinner(app.SpecificTime_Grid);
            app.SpecificTime_Spinner4.Step = 10;
            app.SpecificTime_Spinner4.Limits = [0 59];
            app.SpecificTime_Spinner4.RoundFractionalValues = 'on';
            app.SpecificTime_Spinner4.ValueDisplayFormat = '%.0f';
            app.SpecificTime_Spinner4.Enable = 'off';
            app.SpecificTime_Spinner4.Visible = 'off';
            app.SpecificTime_Spinner4.Value = 59;
            app.SpecificTime_Mark1 = uilabel(app.SpecificTime_Grid);
            app.SpecificTime_Mark1.Enable = 'off';
            app.SpecificTime_Mark1.Visible = 'off';
            app.SpecificTime_Mark1.Text = ':';
            app.SpecificTime_Mark2 = uilabel(app.SpecificTime_Grid);
            app.SpecificTime_Mark2.Enable = 'off';
            app.SpecificTime_Mark2.Visible = 'off';
            app.SpecificTime_Mark2.Text = ':';
            app.ObservationType = uidropdown(app.ObservationPanel_Grid);
            app.ObservationType.Items = {'Duração', 'Período específico', 'Quantidade específica de amostras'};
            app.ObservationType.ValueChangedFcn = createCallbackFcn(app, @General_ObservationType, true);
            app.ObservationType.Tag = 'task_Editable';
            app.ObservationType.Value = 'Duração';
            app.ObservationTypeLabel = uilabel(app.ObservationPanel_Grid);
            app.ObservationTypeLabel.Text = 'Tipo:';
            app.Duration_Grid = uigridlayout(app.ObservationPanel_Grid);
            app.Duration = uieditfield(app.Duration_Grid, 'numeric');
            app.Duration.Limits = [1 Inf];
            app.Duration.ValueDisplayFormat = '%.3f';
            app.Duration.Tag = 'task_Editable';
            app.Duration.Value = 10;
            app.DurationUnit = uidropdown(app.Duration_Grid);
            app.DurationUnit.Items = {'min', 'hr'};
            app.DurationUnit.Tag = 'task_Editable';
            app.DurationUnit.Value = 'min';
            app.BitsPerPoint = uidropdown(app.Tab1_Panel);
            app.BitsPerPoint.Items = {'8 bits', '16 bits', '32 bits'};
            app.BitsPerPoint.Tag = 'task_Editable';
            app.BitsPerPoint.Value = '8 bits';
            app.BitsPerPointLabel = uilabel(app.Tab1_Panel);
            app.BitsPerPointLabel.Text = 'Codificação:';
            app.Tab2_Grid = uigridlayout(app.LeftPanel_Grid);
            app.Tab2_Grid.Tag = 'COLORLOCKED';
            app.Tab2_Title = uilabel(app.Tab2_Grid);
            app.Tab2_Title.Text = 'INSTRUMENTOS';
            app.Tab2_Image = uiimage(app.Tab2_Grid);
            app.Tab2_Image.ImageClickedFcn = createCallbackFcn(app, @Layout_LeftPanelTab, true);
            app.Tab2_Image.ImageSource = 'LT_playback.png';
            app.Tab2_Panel = uigridlayout(app.LeftPanel_Grid);
            app.Receiver_ListGrid = uigridlayout(app.Tab2_Panel);
            app.Receiver_Connectivity = uibutton(app.Receiver_ListGrid, 'push');
            app.Receiver_Connectivity.ButtonPushedFcn = createCallbackFcn(app, @ConnectivityTest_Receiver, true);
            app.Receiver_Connectivity.Tag = 'task_Editable';
            app.Receiver_Connectivity.Icon = 'LT_connectivity.png';
            app.Receiver_Connectivity.Tooltip = {'Teste de conectividade'};
            app.Receiver_Connectivity.Text = '';
            app.Receiver_List = uidropdown(app.Receiver_ListGrid);
            app.Receiver_List.Items = {};
            app.Receiver_List.ValueChangedFcn = createCallbackFcn(app, @Instruments_Receiver, true);
            app.Receiver_List.Tag = 'task_Editable';
            app.Receiver_List.Value = {};
            app.Receiver_ListLabel = uilabel(app.Receiver_ListGrid);
            app.Receiver_ListLabel.Text = 'Receptor:';
            app.Receiver_Panel = uipanel(app.Tab2_Panel);
            app.Receiver_Grid = uigridlayout(app.Receiver_Panel);
            app.Receiver_RstCommandLabel = uilabel(app.Receiver_Grid);
            app.Receiver_RstCommandLabel.Text = 'Reset:';
            app.Receiver_RstCommand = uidropdown(app.Receiver_Grid);
            app.Receiver_RstCommand.Items = {'On', 'Off'};
            app.Receiver_RstCommand.Tag = 'task_Editable';
            app.Receiver_RstCommand.Value = 'On';
            app.Receiver_SyncRefLabel = uilabel(app.Receiver_Grid);
            app.Receiver_SyncRefLabel.Text = 'Sincronismo:';
            app.Receiver_SyncRef = uidropdown(app.Receiver_Grid);
            app.Receiver_SyncRef.Items = {'Single Sweep', 'Continuous Sweep', 'Streaming'};
            app.Receiver_SyncRef.Tag = 'task_Editable';
            app.Receiver_SyncRef.Value = 'Single Sweep';
            app.GPS_ListGrid = uigridlayout(app.Tab2_Panel);
            app.GPS_ListLabel = uilabel(app.GPS_ListGrid);
            app.GPS_ListLabel.Text = 'GPS:';
            app.GPS_List = uidropdown(app.GPS_ListGrid);
            app.GPS_List.Items = {'ID 0: Manual'};
            app.GPS_List.ValueChangedFcn = createCallbackFcn(app, @Instruments_GPS, true);
            app.GPS_List.Tag = 'task_Editable';
            app.GPS_List.Value = 'ID 0: Manual';
            app.GPS_Connectivity = uibutton(app.GPS_ListGrid, 'push');
            app.GPS_Connectivity.ButtonPushedFcn = createCallbackFcn(app, @ConnectivityTest_GPS, true);
            app.GPS_Connectivity.Tag = 'task_Editable';
            app.GPS_Connectivity.Icon = 'LT_connectivity.png';
            app.GPS_Connectivity.Tooltip = {'Teste de conectividade'};
            app.GPS_Connectivity.Text = '';
            app.GPS_Panel = uipanel(app.Tab2_Panel);
            app.GPS_Grid = uigridlayout(app.GPS_Panel);
            app.GPS_RevisitTimeLabel = uilabel(app.GPS_Grid);
            app.GPS_RevisitTimeLabel.Text = {'Tempo revisita:'; '(segundos)'};
            app.GPS_RevisitTime = uieditfield(app.GPS_Grid, 'numeric');
            app.GPS_RevisitTime.Limits = [1 Inf];
            app.GPS_RevisitTime.RoundFractionalValues = 'on';
            app.GPS_RevisitTime.ValueDisplayFormat = '%.0f';
            app.GPS_RevisitTime.Tag = 'task_Editable';
            app.GPS_RevisitTime.Value = 60;
            app.GPS_manualLatitudeLabel = uilabel(app.GPS_Grid);
            app.GPS_manualLatitudeLabel.Text = {'Latitude:'; '(graus decimais)'};
            app.GPS_manualLatitude = uieditfield(app.GPS_Grid, 'numeric');
            app.GPS_manualLatitude.ValueDisplayFormat = '%.6f';
            app.GPS_manualLatitude.Tag = 'task_Editable';
            app.GPS_manualLatitude.Value = -1;
            app.GPS_manualLongitudeLabel = uilabel(app.GPS_Grid);
            app.GPS_manualLongitudeLabel.Text = {'Longitude:'; '(graus decimais)'};
            app.GPS_manualLongitude = uieditfield(app.GPS_Grid, 'numeric');
            app.GPS_manualLongitude.ValueDisplayFormat = '%.6f';
            app.GPS_manualLongitude.Tag = 'task_Editable';
            app.GPS_manualLongitude.Value = -1;
            app.Tab3_Grid = uigridlayout(app.LeftPanel_Grid);
            app.Tab3_Grid.Tag = 'COLORLOCKED';
            app.Tab3_Title = uilabel(app.Tab3_Grid);
            app.Tab3_Title.Text = 'ANTENAS';
            app.Tab3_Image = uiimage(app.Tab3_Grid);
            app.Tab3_Image.ImageClickedFcn = createCallbackFcn(app, @Layout_LeftPanelTab, true);
            app.Tab3_Image.ImageSource = 'LT_antenna.png';
            app.Tab3_Panel = uigridlayout(app.LeftPanel_Grid);
            app.AntennaSwitch_Grid = uigridlayout(app.Tab3_Panel);
            app.AntennaSwitch_Connectivity = uibutton(app.AntennaSwitch_Grid, 'push');
            app.AntennaSwitch_Connectivity.ButtonPushedFcn = createCallbackFcn(app, @ConnectivityTest_AntennaSwitch, true);
            app.AntennaSwitch_Connectivity.Tag = 'task_Editable';
            app.AntennaSwitch_Connectivity.Icon = 'LT_connectivity.png';
            app.AntennaSwitch_Connectivity.Enable = 'off';
            app.AntennaSwitch_Connectivity.Tooltip = {'Teste de conectividade'};
            app.AntennaSwitch_Connectivity.Text = '';
            app.AntennaSwitch_Mode = uicheckbox(app.AntennaSwitch_Grid);
            app.AntennaSwitch_Mode.ValueChangedFcn = createCallbackFcn(app, @AntennaConfig_SwitchMode, true);
            app.AntennaSwitch_Mode.Text = 'Comutador de antenas:';
            app.AntennaSwitch_Name = uieditfield(app.AntennaSwitch_Grid, 'text');
            app.AntennaSwitch_Name.Editable = 'off';
            app.AntennaSwitch_Name.Enable = 'off';
            app.Antenna_Panel = uipanel(app.Tab3_Panel);
            app.Antenna_Grid = uigridlayout(app.Antenna_Panel);
            app.AntennaHeight = uieditfield(app.Antenna_Grid, 'numeric');
            app.AntennaHeight.Limits = [0 127];
            app.AntennaHeight.RoundFractionalValues = 'on';
            app.AntennaHeight.ValueDisplayFormat = '%.0f';
            app.AntennaHeight.Tag = 'task_Editable';
            app.AntennaHeight.Enable = 'off';
            app.AntennaHeight.Value = 2;
            app.AntennaHeightLabel = uilabel(app.Antenna_Grid);
            app.AntennaHeightLabel.Text = {'Altura de instalação:'; '(metros)'};
            app.AntennaElevation = uieditfield(app.Antenna_Grid, 'numeric');
            app.AntennaElevation.Limits = [0 90];
            app.AntennaElevation.ValueDisplayFormat = '%.3f';
            app.AntennaElevation.Tag = 'task_Editable';
            app.AntennaElevation.Enable = 'off';
            app.AntennaElevationLabel = uilabel(app.Antenna_Grid);
            app.AntennaElevationLabel.Text = {'Elevação:'; '(graus)'};
            app.AntennaAzimuth_Grid = uigridlayout(app.Antenna_Grid);
            app.AntennaAzimuth = uieditfield(app.AntennaAzimuth_Grid, 'numeric');
            app.AntennaAzimuth.Limits = [0 359];
            app.AntennaAzimuth.ValueDisplayFormat = '%.3f';
            app.AntennaAzimuth.Tag = 'task_Editable';
            app.AntennaAzimuth.Enable = 'off';
            app.AntennaAzimuthLabel = uilabel(app.AntennaAzimuth_Grid);
            app.AntennaAzimuthLabel.Text = {'Azimute:'; '(graus)'};
            app.AntennaAzimuthRef = uidropdown(app.AntennaAzimuth_Grid);
            app.AntennaAzimuthRef.Items = {'NV', 'NM'};
            app.AntennaAzimuthRef.Enable = 'off';
            app.AntennaAzimuthRef.Value = 'NV';
            app.AntennaPolarization = uieditfield(app.Antenna_Grid, 'numeric');
            app.AntennaPolarization.Limits = [0 90];
            app.AntennaPolarization.ValueDisplayFormat = '%.3f';
            app.AntennaPolarization.Tag = 'task_Editable';
            app.AntennaPolarization.Enable = 'off';
            app.AntennaPolarizationLabel = uilabel(app.Antenna_Grid);
            app.AntennaPolarizationLabel.Text = {'Polarização:'; '(graus)'};
            app.Antenna_TrackingModeLabel = uilabel(app.Antenna_Grid);
            app.Antenna_TrackingModeLabel.Text = 'Apontamento:';
            app.Antenna_TrackingMode = uidropdown(app.Antenna_Grid);
            app.Antenna_TrackingMode.Items = {'Automático (Preset)', 'Automático (Livre)', 'Manual'};
            app.Antenna_TrackingMode.ValueChangedFcn = createCallbackFcn(app, @AntennaConfig_TrackingMode, true);
            app.Antenna_TrackingMode.Value = 'Manual';
            app.AntennaNameLabel = uilabel(app.Tab3_Panel);
            app.AntennaNameLabel.Text = 'Antena:';
            app.AntennaName = uidropdown(app.Tab3_Panel);
            app.AntennaName.Items = {'', 'CRFS Low Band (10 MHz - 1.2 GHz)', 'CRFS High Band (750 MHz - 6 GHz)', 'Rohde & Schwarz ADDx07 (EB500 GUI Auto)', 'Rohde & Schwarz ADD107 (20 MHz - 1.3 GHz)', 'Rohde & Schwarz ADD207 (600 MHz - 6 GHz)', 'Telescopic', 'Unlisted (Omni)', 'Unlisted (Directional)'};
            app.AntennaName.ValueChangedFcn = createCallbackFcn(app, @AntennaConfig_Name, true);
            app.AntennaName.Tag = 'task_Editable';
            app.AntennaName.Value = '';
            app.AddAntenna_Image = uiimage(app.Tab3_Panel);
            app.AddAntenna_Image.ImageClickedFcn = createCallbackFcn(app, @AntennaConfig_Add, true);
            app.AddAntenna_Image.ImageSource = 'LT_Add.png';
            app.AntennaList_Tree = uitree(app.Tab3_Panel);
            app.RightPanel = uipanel(app.mainGrid);
            app.RightPanel_Grid = uigridlayout(app.RightPanel);
            app.Band_TreeLabel = uilabel(app.RightPanel_Grid);
            app.Band_TreeLabel.Text = 'Faixa(s) de frequência relacionada(s) à tarefa selecionada:';
            app.Band_Tree = uitree(app.RightPanel_Grid);
            app.Band_Tree.SelectionChangedFcn = createCallbackFcn(app, @BandView_TreeSelectionChanged, true);
            app.MetaDataLabel = uilabel(app.RightPanel_Grid);
            app.MetaDataLabel.Text = 'Parâmetros de configuração da faixa:';
            app.MetaData_Panel = uipanel(app.RightPanel_Grid);
            app.MetaData_Grid = uigridlayout(app.MetaData_Panel);
            app.MetaData = uihtml(app.MetaData_Grid);
            app.Band_PanelLabel_Grid = uigridlayout(app.RightPanel_Grid);
            app.Band_PanelLabel = uilabel(app.Band_PanelLabel_Grid);
            app.Band_PanelLabel.Text = 'Parâmetros editáveis da faixa relacionados à tarefa e/ou ao receptor selecionados:';
            app.Band_Refresh = uiimage(app.Band_PanelLabel_Grid);
            app.Band_Refresh.ImageClickedFcn = createCallbackFcn(app, @BandView_Refresh, true);
            app.Band_Refresh.ImageSource = 'LT_refresh.png';
            app.Band_Panel = uipanel(app.RightPanel_Grid);
            app.Band_Grid = uigridlayout(app.Band_Panel);
            app.Band_SamplesLabel = uilabel(app.Band_Grid);
            app.Band_SamplesLabel.Text = 'Qtd. amostras:';
            app.Band_Samples = uieditfield(app.Band_Grid, 'numeric');
            app.Band_Samples.Limits = [-1 Inf];
            app.Band_Samples.RoundFractionalValues = 'on';
            app.Band_Samples.ValueDisplayFormat = '%.0f';
            app.Band_Samples.ValueChangedFcn = createCallbackFcn(app, @BandView_EditedParameters, true);
            app.Band_Samples.Value = -1;
            app.Band_Label = uilabel(app.Band_Grid);
            app.Band_Label.Text = 'Receptor:';
            app.Band_SubPanel = uipanel(app.Band_Grid);
            app.Band_SubGrid = uigridlayout(app.Band_SubPanel);
            app.Band_StepWidthLabel = uilabel(app.Band_SubGrid);
            app.Band_StepWidthLabel.Text = {'Passo varredura:'; '(kHz)'};
            app.Band_StepWidth1 = uieditfield(app.Band_SubGrid, 'numeric');
            app.Band_StepWidth1.Limits = [0 Inf];
            app.Band_StepWidth1.ValueDisplayFormat = '%.3f';
            app.Band_StepWidth1.ValueChangedFcn = createCallbackFcn(app, @BandView_EditedParameters, true);
            app.Band_StepWidth1.Tag = 'task_Set1';
            app.Band_StepWidth1.Editable = 'off';
            app.Band_StepWidth2 = uidropdown(app.Band_SubGrid);
            app.Band_StepWidth2.Items = {};
            app.Band_StepWidth2.ValueChangedFcn = createCallbackFcn(app, @BandView_EditedParameters, true);
            app.Band_StepWidth2.Tag = 'task_Set2';
            app.Band_StepWidth2.Enable = 'off';
            app.Band_StepWidth2.Value = {};
            app.Band_DataPointsLabel = uilabel(app.Band_SubGrid);
            app.Band_DataPointsLabel.Text = {'Pontos por '; 'traço:'};
            app.Band_DataPoints1 = uispinner(app.Band_SubGrid);
            app.Band_DataPoints1.Step = 100;
            app.Band_DataPoints1.Limits = [101 32001];
            app.Band_DataPoints1.RoundFractionalValues = 'on';
            app.Band_DataPoints1.ValueDisplayFormat = '%.0f';
            app.Band_DataPoints1.ValueChangedFcn = createCallbackFcn(app, @BandView_EditedParameters, true);
            app.Band_DataPoints1.Tag = 'task_Set1';
            app.Band_DataPoints1.Value = 101;
            app.Band_DataPoints2 = uieditfield(app.Band_SubGrid, 'numeric');
            app.Band_DataPoints2.ValueDisplayFormat = '%.0f';
            app.Band_DataPoints2.Tag = 'task_Set2';
            app.Band_DataPoints2.Editable = 'off';
            app.Band_DataPoints2.Enable = 'off';
            app.Band_SelectivityLabel = uilabel(app.Band_SubGrid);
            app.Band_SelectivityLabel.Text = 'Seletividade FFT:';
            app.Band_Selectivity = uidropdown(app.Band_SubGrid);
            app.Band_Selectivity.Items = {'Normal', 'Narrow', 'Sharp'};
            app.Band_Selectivity.ValueChangedFcn = createCallbackFcn(app, @BandView_EditedParameters, true);
            app.Band_Selectivity.Tag = 'task_Set2';
            app.Band_Selectivity.Value = 'Normal';
            app.Band_ResolutionLabel = uilabel(app.Band_SubGrid);
            app.Band_ResolutionLabel.Text = 'Resolução (kHz):';
            app.Band_Resolution = uidropdown(app.Band_SubGrid);
            app.Band_Resolution.Items = {};
            app.Band_Resolution.ValueChangedFcn = createCallbackFcn(app, @BandView_EditedParameters, true);
            app.Band_Resolution.Value = {};
            app.Band_attModeLabel = uilabel(app.Band_SubGrid);
            app.Band_attModeLabel.Text = 'Modo do atenuador:';
            app.Band_attMode = uidropdown(app.Band_SubGrid);
            app.Band_attMode.Items = {'Manual', 'Auto'};
            app.Band_attMode.ValueChangedFcn = createCallbackFcn(app, @BandView_EditedParameters, true);
            app.Band_attMode.Value = 'Manual';
            app.Band_attValueLabel = uilabel(app.Band_SubGrid);
            app.Band_attValueLabel.Text = 'Atenuador:';
            app.Band_attValue = uidropdown(app.Band_SubGrid);
            app.Band_attValue.Items = {};
            app.Band_attValue.ValueChangedFcn = createCallbackFcn(app, @BandView_EditedParameters, true);
            app.Band_attValue.Value = {};
            app.Band_PreampLabel = uilabel(app.Band_SubGrid);
            app.Band_PreampLabel.Text = 'Pré-amplificador:';
            app.Band_Preamp = uidropdown(app.Band_SubGrid);
            app.Band_Preamp.Items = {'On', 'Off'};
            app.Band_Preamp.ValueChangedFcn = createCallbackFcn(app, @BandView_EditedParameters, true);
            app.Band_Preamp.Value = 'Off';
            app.Band_IntegrationTimeLabel = uilabel(app.Band_SubGrid);
            app.Band_IntegrationTimeLabel.Text = 'Integração (ms):';
            app.Band_IntegrationTime = uieditfield(app.Band_SubGrid, 'numeric');
            app.Band_IntegrationTime.Limits = [-1 10000];
            app.Band_IntegrationTime.RoundFractionalValues = 'on';
            app.Band_IntegrationTime.ValueDisplayFormat = '%.0f';
            app.Band_IntegrationTime.ValueChangedFcn = createCallbackFcn(app, @BandView_EditedParameters, true);
            app.Band_IntegrationTime.Value = -1;
            app.Band_DetectorLabel = uilabel(app.Band_SubGrid);
            app.Band_DetectorLabel.Text = 'Detector:';
            app.Band_Detector = uidropdown(app.Band_SubGrid);
            app.Band_Detector.Items = {'Auto Peak', 'Average', 'Negative Peak', 'Positive Peak', 'Quasi Peak', 'RMS', 'Sample'};
            app.Band_Detector.ValueChangedFcn = createCallbackFcn(app, @BandView_EditedParameters, true);
            app.Band_Detector.Value = 'Positive Peak';
            app.Panel = uipanel(app.Band_Grid);
            app.Band_Antenna_Grid = uigridlayout(app.Panel);
            app.Band_AntennaLabel = uilabel(app.Band_Antenna_Grid);
            app.Band_AntennaLabel.Text = 'Antena:';
            app.Band_Antenna = uidropdown(app.Band_Antenna_Grid);
            app.Band_Antenna.Items = {};
            app.Band_Antenna.ValueChangedFcn = createCallbackFcn(app, @BandView_EditedParameters, true);
            app.Band_Antenna.Value = {};
            app.Band_TargetLabel = uilabel(app.Band_Antenna_Grid);
            app.Band_TargetLabel.Text = 'Estação alvo:';
            app.Band_Target = uidropdown(app.Band_Antenna_Grid);
            app.Band_Target.Items = {};
            app.Band_Target.ValueChangedFcn = createCallbackFcn(app, @BandView_EditedParameters, true);
            app.Band_Target.Value = {};
            app.AntenaLabel = uilabel(app.Band_Grid);
            app.AntenaLabel.Text = 'Antena:';
            app.MainButton = uibutton(app.RightPanel_Grid, 'push');
            app.MainButton.ButtonPushedFcn = createCallbackFcn(app, @MainButtonPushed, true);
            app.MainButton.Icon = 'LT_edit.png';
            app.MainButton.Text = '';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ExcluirMenu = uimenu(app.ContextMenu);
            app.ExcluirMenu.MenuSelectedFcn = createCallbackFcn(app, @AntennaConfig_Delete, true);
            app.ExcluirMenu.Text = 'Excluir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winTask(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end